classdef ME362_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Densitykgm3EditField            matlab.ui.control.NumericEditField
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        SOLVEButton_2                   matlab.ui.control.Button
        ColorMapsButtonGroup_2          matlab.ui.container.ButtonGroup
        Bone_2                          matlab.ui.control.ToggleButton
        Parula_2                        matlab.ui.control.ToggleButton
        Jet_2                           matlab.ui.control.ToggleButton
        DisplacementDirectionDropDown   matlab.ui.control.DropDown
        DisplacementDirectionDropDownLabel  matlab.ui.control.Label
        Am2EditField                    matlab.ui.control.NumericEditField
        Am2EditFieldLabel               matlab.ui.control.Label
        EPaEditField                    matlab.ui.control.NumericEditField
        EPaEditFieldLabel               matlab.ui.control.Label
        MagnifierSlider                 matlab.ui.control.Slider
        MagnifierSliderLabel            matlab.ui.control.Label
        ENLTable                        matlab.ui.control.Table
        POSTPROCESSLabel                matlab.ui.control.Label
        BoundaryConditionPanel          matlab.ui.container.Panel
        APPLYButton                     matlab.ui.control.Button
        MagnitudeEditField              matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel         matlab.ui.control.Label
        BCSwitch                        matlab.ui.control.Switch
        BCSwitchLabel                   matlab.ui.control.Label
        NodeEditField                   matlab.ui.control.NumericEditField
        NodeEditFieldLabel              matlab.ui.control.Label
        DirecitonSwitch                 matlab.ui.control.Switch
        BoundaryConditionDirecitonLabel  matlab.ui.control.Label
        PREPROCESSLabel                 matlab.ui.control.Label
        RemoveAllButton                 matlab.ui.control.Button
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        NumberofNodesEditField          matlab.ui.control.NumericEditField
        NumberofNodesEditFieldLabel     matlab.ui.control.Label
        ElementNumberSizeSlider         matlab.ui.control.Slider
        ElementNumberSizeSliderLabel    matlab.ui.control.Label
        ElementLabelSwitch              matlab.ui.control.Switch
        ElementLabelSwitchLabel         matlab.ui.control.Label
        PROCESSLabel                    matlab.ui.control.Label
        ReadNodeandElementListButton    matlab.ui.control.StateButton
        NodeLabelSwitch                 matlab.ui.control.Switch
        NodeLabelSwitchLabel            matlab.ui.control.Label
        NodeNumberSizeSlider            matlab.ui.control.Slider
        NodeNumberSizeSliderLabel       matlab.ui.control.Label
        GenerateElementButton           matlab.ui.control.Button
        ElementLabel                    matlab.ui.control.Label
        Node2                           matlab.ui.control.NumericEditField
        Node2EditFieldLabel             matlab.ui.control.Label
        Node1                           matlab.ui.control.NumericEditField
        Node1EditFieldLabel             matlab.ui.control.Label
        GenerateNodeButton              matlab.ui.control.Button
        NodeLabel                       matlab.ui.control.Label
        Y_coor                          matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        X_coor                          matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        UIAxes_stress                   matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_displacement             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Pre-process Properties
        NL = [] 
        EL = []
        ENL = []
        NoN = 0
        NoE = 0
        DoFs = 0
        DoCs = 0
        NodeSize = 10
        ElementSize = 10
        NodeSwitch = "On"
        ElementSwitch = "On"
        colorMap = jet
        ar = 1
        mag = 1

        % Process parameters
        BC_node = 1             
        BC_direction = [1 0]
        BC_type = 1
        BC_magnitude = 0

        % Element properties
        E = 0
        E_default = 210*10^9
        A = 0
        A_default = 10^(-4) % 10^-4 m^2 = 1 cm^2
        density = 0
        density_default = 8000 %kg/m^3

        disp_direction = "U_x"
    end
    
    methods (Access = private)
            
        function results = updateAxes(app)
    
            cla(app.UIAxes);
            hold(app.UIAxes,"on");
            colormap(app.UIAxes, app.colorMap)
            colorbar(app.UIAxes);

            for i = 1 : app.NoE
                if app.ElementSize == 0
                    uialert(app.UIFigure, "Element Size must be larger than zero!", "Input Error")
                else
                    H = plot(app.UIAxes, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'LineWidth' ,app.ElementSize,'Color', 'k');
                    H.Color(4)=0.2;
                    app.NumberofElementsEditField.Value=app.NoE;
                end

            end

 
            switch app.NodeSwitch
                case "On"
                    for i = 1:app.NoN
                        if app.NodeSize == 0
                            uialert(app.UIFigure, "Node Size must be larger than zero!", "Input Error");
                        else
                            if app.ENL(i,3) == -1 && app.ENL(i,4) == -1
                                plot(app.UIAxes, app.NL(i,1), app.NL(i,2), 's', 'MarkerSize', app.NodeSize, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            elseif app.ENL(i,3) == -1
                                plot(app.UIAxes, app.NL(i,1), app.NL(i,2), '>', 'MarkerSize', app.NodeSize, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            elseif app.ENL(i,4) == -1
                                plot(app.UIAxes, app.NL(i,1), app.NL(i,2), '^', 'MarkerSize', app.NodeSize, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            else
                                plot(app.UIAxes, app.NL(i,1), app.NL(i,2), 'o', 'MarkerSize', app.NodeSize, 'MarkerEdgeColor','k','MarkerFaceColor','r')    
                            end
                            
                            text(app.UIAxes ,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                        end
                       
                        if app.ENL(i,3) == -1
                            if app.ENL(i,9) == 0
                                %do nothing
                            else
                                text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append("\fontsize{15}", num2str(app.ENL(i, 9)), "m"), "\fontsize{25}\rightarrow"}, 'Color','blue','VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            end
                        end
                        if app.ENL(i, 4) == -1
                            if app.ENL(i,10) == 0
                                %do nothing
                            else
                                text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append('\fontsize{15}', num2str(app.ENL(i,10)),'m'),'\fontsize{25}\uparrow'}, 'Color', 'blue', 'VerticalAlignment','bottom', 'HorizontalAlignment','Center')
                            end
                        end

                        if (app.ENL(i,11) ~= 0) && (app.ENL(i,3) == 1)
                            
                            text(app.UIAxes,app.NL(i,1),app.NL(i,2), append('\fontsize{25}\rightarrow', '{\fontsize{15}', num2str(app.ENL(i,11)), ' N}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                          
                        end
                        
                        if (app.ENL(i,12) ~= 0) && (app.ENL(i,4) == 1)
                            
                            text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append('\fontsize{14}', num2str(app.ENL(i,12)),' N'),'\fontsize{35}\uparrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            
                        end
                    end
            end

            switch app.ElementSwitch
                case "On"
                    for i=1:app.NoE 
                        text(app.UIAxes,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2,(app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,num2str(i),'Color','k','FontSize',16,'HorizontalAlignment','center')
                    end
            end

            hold(app.UIAxes, "off");

        end

        function results_2 = process(app)

            format long;

            K= assemble_stiffness(app.ENL,app.EL,app.NL,app.E,app.A);
            Fp=assemble_forces(app.ENL, app.NL);
            Up=assemble_displacement(app.ENL,app.NL); 

            %assemble forces and displacements 
            Kpu= K(1:app.DoFs,1:app.DoFs); %DOFxDOF                                                                        
            Kpp= K(1:app.DoFs, app.DoFs+1: app.DoFs+app.DoCs); %DOFxDOC
            Kuu= K(app.DoFs+1:app.DoCs+app.DoFs,1:app.DoFs);% DOCxDOF
            Kup= K(DOFs+1:app.DoCs+app.DoFs,app.DoFs+1:app.DoCs+DOFs);%DOCxDOC
            
            F = Fp - Kpp * Up;
            
            Uu=inv(Kpu)*F;  
            
            Fu=Kuu*Uu+Kup*Up;
            
            app.ENL= update_nodes(app.ENL,Uu,app.NL,Fu);
            
            app.ENLTable.Data = string([app.NoN(:) app.ENL]);
        end
        
        function results3 = post_process(app)
            cla(app.UIAxes_stress);
            cla(app.UIAxes_displacement);
            colormap(app.UIAxes_stress, app.colorMap); 
            colormap(app.UIAxes_displacement, app.colorMap);
            hold(app.UIAxes_stress, "on");
            hold(app.UIAxes_displacement, "on");
            grid(app.UIAxes_stress, "on");
            grid(app.UIAxes_displacement, "on");

            %%%plot elements%%%
            for i=1:app.NoE
                H_stress = plot(app.UIAxes_stress, [app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)] , [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth', 5, 'Color','k');
                %plot([first node x, second node x], [first node y, second node y])
                H_stress.Color(4)=0.2; %transparency
                H_displacement = plot(app.UIAxes_displacement, [app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)] , [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth', 5, 'Color','k');
                %plot([first node x, second node x], [first node y, second node y])
                H_displacement.Color(4)=0.2; %transparency
            end

            %%%plot nodes%%%
            for i = 1:app.NoN
                plot(app.UIAxes_stress, app.NL(i,1),app.NL(i,2),'o','Markersize', 10,'MarkerEdgeColor','k', 'MarkerFaceColor','r')
                %x and y coordinates of the nodes
                plot(app.UIAxes_displacement, app.NL(i,1),app.NL(i,2),'o','Markersize', 10,'MarkerEdgeColor','k', 'MarkerFaceColor','r')
                %x and y coordinates of the nodes
            end 

            %%% plot elements with stress distrubution%%%
            for i=1:app.NoE
                x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];  
                    %x starting coord+displacement added with magnification factor, x ending coord
                y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                    %y starting coord, y ending coord
                z = zeros(size(x)); %z value is needed to plot surface 
                sigma = app.E * (sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2 + (app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)/(sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2 + (app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                % (length change over original length) 
                %E* sqrt((xdisp1-xdisp2)^2+(ydisp1-ydisp2)^2) / ((xoriginal1-xoriginal2)^2 + (yoriginal1-yoriginal2)^2)
            
                %Stress Plot
                col_stress = [sigma sigma]; %stress does not vary inside the element 
                surface(app.UIAxes_stress, [x;x],[y;y],[z;z], [col_stress;col_stress], 'facecol','no','edgecol','interp','linew',4);
                %surface function to plot

                if app.disp_direction == "U_x"
                    dispx1 = x(1) - app.ENL(app.EL(i,1),1);
                    dispx2 = x(2) - app.ENL(app.EL(i,2),1);
                    col_dispx = [dispx1 dispx2];
                    surface([x;x],[y;y],[z;z], [col_dispx;col_dispx], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction == "U_y"
                    dispy1 = y(1) - app.ENL(app.EL(i,1),2);
                    dispy2 = y(2) - app.ENL(app.EL(i,2),2);
                    col_dispy = [dispy1 dispy2];
                    surface([x;x],[y;y],[z;z], [col_dispy;col_dispy], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction == "U"
                    alldisp1 = sqrt(dispx1^2+dispy1^2);
                    alldisp2 = sqrt(dispx2^2+dispy2^2);
                    col_alldisp = [alldisp1 alldisp2];
                    surface([x;x],[y;y],[z;z], [col_alldisp;col_alldisp], 'facecol','no','edgecol','interp','linew',4);
                end
                
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
                nl = [app.X_coor.Value, app.Y_coor.Value];
                enl = [nl zeros(1,10)];

                if (app.NoN > 0) && (sum(ismember(app.NL, nl, "rows")) == 1)
                    uialert(app.UIFigure, "Node is alreadey input!", "Input Error");
                else 
                    app.NL = [app.NL; nl];
                    app.NoN = size(app.NL,1);
                    %app.NLTable.Data = app.NL;
                    app.ENL = [app.ENL; enl];
                    app.ENLTable.Data = app.ENL;
                end

                updateAxes(app);
        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
                el = [app.Node1.Value, app.Node2.Value];

                if (app.NoE > 0) && (sum(ismember(app.EL, el, "rows")) == 1)
                    uialert(app.UIFigure, "Element is alreadey input!", "Input Error");
                else
                    app.EL = [app.EL; el];
                    app.NoE = size(app.EL, 1);
                    %app.ElementTable.Data = app.EL;
                end
                updateAxes(app);

        end

        % Value changed function: NodeNumberSizeSlider
        function NodeNumberSizeSliderValueChanged(app, event)
                app.NodeSize = app.NodeNumberSizeSlider.Value;
                updateAxes(app);
        end

        % Value changed function: NodeLabelSwitch
        function NodeLabelSwitchValueChanged(app, event)
                app.NodeSwitch = app.NodeLabelSwitch.Value;
                updateAxes(app);
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
%deleted
        end

        % Callback function
        function ColorMapsButtonGroupSelectionChanged2(app, event)
            selectedButton = app.ColorMapsButtonGroup.SelectedObject;

            switch selectedButton

                case app.Jet
                    app.colorMap = jet ;
                    colormap(app.UIAxes_displacemet, app.colorMap)
                    colormap(app.UIAxes_stress, app.colorMap)

                case app.Parula
                    app.colorMap = parula ;
                    colormap(app.UIAxes_displacemet, app.colorMap)
                    colormap(app.UIAxes_stress, app.colorMap)

                case app.Bone
                    app.colorMap = bone ;
                    colormap(app.UIAxes_displacemet, app.colorMap)
                    colormap(app.UIAxes_stress, app.colorMap)
            end

            post_process(app)


        end

        % Value changed function: ReadNodeandElementListButton
        function ReadNodeandElementListButtonValueChanged(app, event)
            
            [inputfile,~] = uigetfile({'*.xlsx'},'Select file to open');
            if ~isequal(inputfile,0)
                app.NL = [];
                app.EL = [];

                nl = readmatrix(inputfile,'Range', 'A:B');
                el = readmatrix(inputfile,'Range', 'C:D');
            end

            for i = 1 : size(nl,1)
                if ~isnan(nl(i,:))
                    app.NL = [app.NL; nl(i,:)];
                end
            end

            for i = 1 : size(el,1)
                if ~isnan(el(i,:))
                    app.EL = [app.EL; el(i,:)];
                end
            end
            app.NLTable.Data = app.NL;
            app.ElementTable.Data = app.EL;


        end

        % Callback function
        function ElementLabelSwitchValueChanged(app, event)
%deleted
        end

        % Value changed function: ElementLabelSwitch
        function ElementLabelSwitchValueChanged2(app, event)
            app.ElementSwitch = app.ElementLabelSwitch.Value;
            updateAxes(app);
        end

        % Value changed function: ElementNumberSizeSlider
        function ElementNumberSizeSliderValueChanged(app, event)
            app.ElementSize = app.ElementNumberSizeSlider.Value;
            updateAxes(app);
        end

        % Value changed function: NumberofNodesEditField
        function NumberofNodesEditFieldValueChanged(app, event)
            app.NoN = app.NumberofNodesEditField.Value;
            updateAxes(app);
        end

        % Value changed function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanged(app, event)
            app.NoE = app.NumberofElementsEditField.Value;
            updateAxes(app);
        end

        % Button pushed function: RemoveAllButton
        function RemoveAllButtonPushed(app, event)
%couldnt make it work
        end

        % Callback function
        function NodeNumberEditFieldValueChanged(app, event)
 %deleted
 %app.NodeBC = app.NodeNumberEditField.Value;
        end

        % Value changed function: DirecitonSwitch
        function DirecitonSwitchValueChanged(app, event)
            if app.DirecitonSwitch.Value == 'X'
                app.BC_direction = [1,0];
            else 
                app.BC_direction = [0,1];
            end
        end

        % Value changed function: NodeEditField
        function NodeEditFieldValueChanged(app, event)
            app.BC_node = app.NodeEditField.Value;
        end

        % Value changed function: BCSwitch
        function BCSwitchValueChanged(app, event)
            if app.BCSwitch.Value == "Displacement"
                app.BC_type = -1;
            else
                app.BC_type = 1;
            end
        end

        % Value changed function: MagnitudeEditField
        function MagnitudeEditFieldValueChanged(app, event)
            app.BC_magnitude = app.MagnitudeEditField.Value;
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)

            if app.BC_type == 1
                if app.BC_direction == [0,1]
                    app.ENL(app.BC_node,4) = 1;
                    app.ENL(app.BC_node, 12) = app.BC_magnitude;
                else
                    app.ENL(app.BC_node,3) = 1;
                    app.ENL(app.BC_node, 11) = app.BC_magnitude;
                end
            else
                 if app.BC_direction == [0,1]
                    app.ENL(app.BC_node,4) = -1;
                    app.ENL(app.BC_node,10) = app.BC_magnitude;
                 else
                    app.ENL(app.BC_node,3) = -1;
                    app.ENL(app.BC_node,9) = app.BC_magnitude;
                 end

            end
            app.ENLTable.Data = app.ENL;
            updateAxes(app);
                              

        end

        % Value changing function: MagnifierSlider
        function MagnifierSliderValueChanging(app, event)
            changingValue = event.Value;
            app.mag = changingValue;
            post_process(app);
        end

        % Value changed function: EPaEditField
        function EPaEditFieldValueChanged(app, event)
            value = app.EPaEditField.Value;
            app.E = value;
        end

        % Value changed function: Am2EditField
        function Am2EditFieldValueChanged(app, event)
            value = app.Am2EditField.Value;
            app.A = app.value;
        end

        % Value changed function: DisplacementDirectionDropDown
        function DisplacementDirectionDropDownValueChanged(app, event)
            value = app.DisplacementDirectionDropDown.Value;
            app.disp_direction = value;
        end

        % Callback function
        function SOLVEButtonPushed(app, event)
            post_process(app);
        end

        % Value changed function: Densitykgm3EditField
        function Densitykgm3EditFieldValueChanged(app, event)
            value = app.Densitykgm3EditField.Value;
            app.density = value;
        end

        % Button pushed function: SOLVEButton_2
        function SOLVEButton_2Pushed(app, event)
            [app.ENL, app.DoFs, app.DoCs] =assign_BCs(app.NL)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_displacement = uiaxes(app.GridLayout);
            title(app.UIAxes_displacement, 'Displacement Plot')
            xlabel(app.UIAxes_displacement, 'X')
            ylabel(app.UIAxes_displacement, 'Y')
            zlabel(app.UIAxes_displacement, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_stress = uiaxes(app.GridLayout);
            title(app.UIAxes_stress, 'Stress Plot')
            xlabel(app.UIAxes_stress, 'X')
            ylabel(app.UIAxes_stress, 'Y')
            zlabel(app.UIAxes_stress, 'Z')
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.X_coor = uieditfield(app.GridLayout, 'numeric');
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.Y_coor = uieditfield(app.GridLayout, 'numeric');
            app.NodeLabel = uilabel(app.GridLayout);
            app.NodeLabel.Text = 'Node';
            app.GenerateNodeButton = uibutton(app.GridLayout, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = {'Generate '; 'Node'};
            app.Node1EditFieldLabel = uilabel(app.GridLayout);
            app.Node1EditFieldLabel.Text = 'Node 1';
            app.Node1 = uieditfield(app.GridLayout, 'numeric');
            app.Node2EditFieldLabel = uilabel(app.GridLayout);
            app.Node2EditFieldLabel.Text = 'Node 2';
            app.Node2 = uieditfield(app.GridLayout, 'numeric');
            app.ElementLabel = uilabel(app.GridLayout);
            app.ElementLabel.Text = 'Element';
            app.GenerateElementButton = uibutton(app.GridLayout, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = {'Generate '; 'Element'};
            app.NodeNumberSizeSliderLabel = uilabel(app.GridLayout);
            app.NodeNumberSizeSliderLabel.Text = 'Node Number Size';
            app.NodeNumberSizeSlider = uislider(app.GridLayout);
            app.NodeNumberSizeSlider.ValueChangedFcn = createCallbackFcn(app, @NodeNumberSizeSliderValueChanged, true);
            app.NodeNumberSizeSlider.Value = 10;
            app.NodeLabelSwitchLabel = uilabel(app.GridLayout);
            app.NodeLabelSwitchLabel.Text = 'Node Label';
            app.NodeLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.NodeLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitchValueChanged, true);
            app.NodeLabelSwitch.Value = 'On';
            app.ReadNodeandElementListButton = uibutton(app.GridLayout, 'state');
            app.ReadNodeandElementListButton.ValueChangedFcn = createCallbackFcn(app, @ReadNodeandElementListButtonValueChanged, true);
            app.ReadNodeandElementListButton.Text = {'Read '; 'Node and Element List'};
            app.PROCESSLabel = uilabel(app.GridLayout);
            app.PROCESSLabel.Text = 'PROCESS';
            app.ElementLabelSwitchLabel = uilabel(app.GridLayout);
            app.ElementLabelSwitchLabel.Text = 'Element Label';
            app.ElementLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.ElementLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementLabelSwitchValueChanged2, true);
            app.ElementLabelSwitch.Value = 'On';
            app.ElementNumberSizeSliderLabel = uilabel(app.GridLayout);
            app.ElementNumberSizeSliderLabel.Text = 'Element Number Size';
            app.ElementNumberSizeSlider = uislider(app.GridLayout);
            app.ElementNumberSizeSlider.ValueChangedFcn = createCallbackFcn(app, @ElementNumberSizeSliderValueChanged, true);
            app.ElementNumberSizeSlider.Value = 10;
            app.NumberofNodesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofNodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNodesEditFieldValueChanged, true);
            app.NumberofElementsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofElementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanged, true);
            app.RemoveAllButton = uibutton(app.GridLayout, 'push');
            app.RemoveAllButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllButtonPushed, true);
            app.RemoveAllButton.Text = 'Remove All';
            app.PREPROCESSLabel = uilabel(app.GridLayout);
            app.PREPROCESSLabel.Text = 'PRE-PROCESS';
            app.BoundaryConditionPanel = uipanel(app.GridLayout);
            app.BoundaryConditionPanel.Title = 'Boundary Condition';
            app.BoundaryConditionDirecitonLabel = uilabel(app.BoundaryConditionPanel);
            app.BoundaryConditionDirecitonLabel.Text = ' Direciton';
            app.DirecitonSwitch = uiswitch(app.BoundaryConditionPanel, 'slider');
            app.DirecitonSwitch.Items = {'X', 'Y'};
            app.DirecitonSwitch.ValueChangedFcn = createCallbackFcn(app, @DirecitonSwitchValueChanged, true);
            app.DirecitonSwitch.Value = 'X';
            app.NodeEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.NodeEditFieldLabel.Text = 'Node';
            app.NodeEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.NodeEditField.ValueChangedFcn = createCallbackFcn(app, @NodeEditFieldValueChanged, true);
            app.NodeEditField.Value = 1;
            app.BCSwitchLabel = uilabel(app.BoundaryConditionPanel);
            app.BCSwitchLabel.Text = 'BC';
            app.BCSwitch = uiswitch(app.BoundaryConditionPanel, 'slider');
            app.BCSwitch.Items = {'Force', 'Displacement'};
            app.BCSwitch.ValueChangedFcn = createCallbackFcn(app, @BCSwitchValueChanged, true);
            app.BCSwitch.Value = 'Force';
            app.MagnitudeEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.MagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MagnitudeEditFieldValueChanged, true);
            app.APPLYButton = uibutton(app.BoundaryConditionPanel, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.POSTPROCESSLabel = uilabel(app.GridLayout);
            app.POSTPROCESSLabel.Text = 'POST-PROCESS';
            app.ENLTable = uitable(app.GridLayout);
            app.ENLTable.ColumnName = {'NL(x)'; 'NL(y)'; 'BC(x)'; 'BC(y)'; 'TD(x)'; 'TD(y)'; 'GD(x)'; 'GD(y)'; 'Disp.(x)'; 'Disp.(y)'; 'Force(x)'; 'Force(y)'};
            app.ENLTable.RowName = {};
            app.MagnifierSliderLabel = uilabel(app.GridLayout);
            app.MagnifierSliderLabel.Text = {'Magnifier'; ''};
            app.MagnifierSlider = uislider(app.GridLayout);
            app.MagnifierSlider.ValueChangingFcn = createCallbackFcn(app, @MagnifierSliderValueChanging, true);
            app.MagnifierSlider.Value = 1;
            app.EPaEditFieldLabel = uilabel(app.GridLayout);
            app.EPaEditFieldLabel.Text = 'E (Pa)';
            app.EPaEditField = uieditfield(app.GridLayout, 'numeric');
            app.EPaEditField.ValueChangedFcn = createCallbackFcn(app, @EPaEditFieldValueChanged, true);
            app.Am2EditFieldLabel = uilabel(app.GridLayout);
            app.Am2EditFieldLabel.Text = 'A (m^2)';
            app.Am2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Am2EditField.ValueChangedFcn = createCallbackFcn(app, @Am2EditFieldValueChanged, true);
            app.DisplacementDirectionDropDownLabel = uilabel(app.GridLayout);
            app.DisplacementDirectionDropDownLabel.Text = 'Displacement Direction';
            app.DisplacementDirectionDropDown = uidropdown(app.GridLayout);
            app.DisplacementDirectionDropDown.Items = {'U_x', 'U_y', 'U', ''};
            app.DisplacementDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplacementDirectionDropDownValueChanged, true);
            app.DisplacementDirectionDropDown.Value = 'U_x';
            app.ColorMapsButtonGroup_2 = uibuttongroup(app.GridLayout);
            app.ColorMapsButtonGroup_2.Title = 'Color Maps';
            app.Jet_2 = uitogglebutton(app.ColorMapsButtonGroup_2);
            app.Jet_2.Text = 'Rainbow';
            app.Parula_2 = uitogglebutton(app.ColorMapsButtonGroup_2);
            app.Parula_2.Text = 'Parula';
            app.Bone_2 = uitogglebutton(app.ColorMapsButtonGroup_2);
            app.Bone_2.Text = 'Bone';
            app.Bone_2.Value = true;
            app.SOLVEButton_2 = uibutton(app.GridLayout, 'push');
            app.SOLVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButton_2Pushed, true);
            app.SOLVEButton_2.Text = 'SOLVE';
            app.Densitykgm3EditFieldLabel = uilabel(app.GridLayout);
            app.Densitykgm3EditFieldLabel.Text = 'Density (kg/m^3)';
            app.Densitykgm3EditField = uieditfield(app.GridLayout, 'numeric');
            app.Densitykgm3EditField.ValueChangedFcn = createCallbackFcn(app, @Densitykgm3EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ME362_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end