classdef app_gui_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ProblemDeifinitionTab           matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        MassoftheStructurekgEditField   matlab.ui.control.NumericEditField
        MassoftheStructurekgLabel       matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        RemoveElementNumber             matlab.ui.control.NumericEditField
        ElementNumberEditFieldLabel     matlab.ui.control.Label
        RemoveNodeNumber                matlab.ui.control.NumericEditField
        NodeNumberEditField_2Label      matlab.ui.control.Label
        RemoveElemetButton              matlab.ui.control.Button
        RemoveNodeButton                matlab.ui.control.Button
        SecondNode                      matlab.ui.control.NumericEditField
        SecondNodeLabel                 matlab.ui.control.Label
        FirstNode                       matlab.ui.control.NumericEditField
        FirstNodeLabel                  matlab.ui.control.Label
        GenerateElementButton           matlab.ui.control.Button
        ElemetLabel                     matlab.ui.control.Label
        Y_coor                          matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        X_coor                          matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        GenerateNodeButton              matlab.ui.control.Button
        NodeLabel                       matlab.ui.control.Label
        NumberofNodesEditField          matlab.ui.control.NumericEditField
        NumberofNodesEditFieldLabel     matlab.ui.control.Label
        ReadNLandELButton               matlab.ui.control.Button
        REMOVEALLButton                 matlab.ui.control.Button
        SOLVEButton                     matlab.ui.control.Button
        SOLVELamp                       matlab.ui.control.Lamp
        ExtendedNodeListPanel           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ENLTable                        matlab.ui.control.Table
        MaterialPropertiesPanel         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ApplyMaterialPropertiesButton   matlab.ui.control.Button
        Densitykgm3EditField            matlab.ui.control.NumericEditField
        Densitykgm3Label                matlab.ui.control.Label
        Am2EditField                    matlab.ui.control.NumericEditField
        Am2EditFieldLabel               matlab.ui.control.Label
        EPaEditField                    matlab.ui.control.NumericEditField
        EPaEditFieldLabel               matlab.ui.control.Label
        BoundryConditionPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        NodeNumberEditField             matlab.ui.control.NumericEditField
        NodeNumberEditFieldLabel        matlab.ui.control.Label
        RemoveAllBoundryConditionsButton  matlab.ui.control.Button
        ApplyBoundryConditionButton     matlab.ui.control.Button
        MagnitudeNormEditField          matlab.ui.control.NumericEditField
        MagnitudeNormEditFieldLabel     matlab.ui.control.Label
        BCDirectionSwitch               matlab.ui.control.Switch
        BCDirectionSwitchLabel          matlab.ui.control.Label
        BCTypeSwitch                    matlab.ui.control.Switch
        BCTypeSwitchLabel               matlab.ui.control.Label
        ElementSizeSlider               matlab.ui.control.Slider
        ElementSizeSliderLabel          matlab.ui.control.Label
        NodeSizeSlider                  matlab.ui.control.Slider
        NodeSizeLabel                   matlab.ui.control.Label
        ElementLabelSwitch              matlab.ui.control.ToggleSwitch
        ElementLabelSwitchLabel         matlab.ui.control.Label
        NodeLabelSwitch                 matlab.ui.control.ToggleSwitch
        NodeLabelSwitchLabel            matlab.ui.control.Label
        RemoveAllNodesandElementsButton  matlab.ui.control.Button
        PROCESSLabel                    matlab.ui.control.Label
        PREPROCESSLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AnalysesTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        REMOVERESULTSButton             matlab.ui.control.Button
        POSTPROCESSLabel                matlab.ui.control.Label
        DispacementDropDown             matlab.ui.control.DropDown
        DispacementDropDownLabel        matlab.ui.control.Label
        WeightandDesplacementValuesPanel  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        MxkgmmaxcenterEditField         matlab.ui.control.NumericEditField
        MxkgmmaxcenterEditFieldLabel    matlab.ui.control.Label
        TotalMasskgEditField            matlab.ui.control.NumericEditField
        TotalMasskgEditFieldLabel       matlab.ui.control.Label
        MagnifierSlider                 matlab.ui.control.Slider
        MagnifierSliderLabel            matlab.ui.control.Label
        ShowUndeformedStructureSwitch_2  matlab.ui.control.Switch
        ShowUndeformedStructureLabel_2  matlab.ui.control.Label
        ShowUndeformedStructureSwitch   matlab.ui.control.Switch
        ShowUndeformedStructureLabel    matlab.ui.control.Label
        ColorMapButtonGroup             matlab.ui.container.ButtonGroup
        Parula                          matlab.ui.control.ToggleButton
        Bone                            matlab.ui.control.ToggleButton
        Jet                             matlab.ui.control.ToggleButton
        UIAxes_displacement             matlab.ui.control.UIAxes
        UIAxes_stress                   matlab.ui.control.UIAxes
        VoidStructuresTab               matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        DisplacementUndeformedStructureSwitch  matlab.ui.control.Switch
        DisplacementUndeformedStructureLabel  matlab.ui.control.Label
        StressUndeformedStructureSwitch  matlab.ui.control.Switch
        StressUndeformedStructureLabel  matlab.ui.control.Label
        DispacementDropDown_2           matlab.ui.control.DropDown
        DispacementDropDown_2Label      matlab.ui.control.Label
        MagnifierSlider_2               matlab.ui.control.Slider
        MagnifierSlider_2Label          matlab.ui.control.Label
        ColorMapButtonGroup_2           matlab.ui.container.ButtonGroup
        Parula_2                        matlab.ui.control.ToggleButton
        Bone_2                          matlab.ui.control.ToggleButton
        Jet_2                           matlab.ui.control.ToggleButton
        REMOVEALLButton_2               matlab.ui.control.Button
        SOLVEButton_2                   matlab.ui.control.Button
        ReadELNLBCsPanel                matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ReadDesiredSystemsButton        matlab.ui.control.Button
        BoundryConditionDropDown        matlab.ui.control.DropDown
        BoundryConditionDropDownLabel   matlab.ui.control.Label
        MeshDensityDropDown             matlab.ui.control.DropDown
        MeshDensityDropDownLabel        matlab.ui.control.Label
        HoleStructureDropDown           matlab.ui.control.DropDown
        HoleStructureDropDownLabel      matlab.ui.control.Label
        MassoftheStructurekgEditField_2  matlab.ui.control.NumericEditField
        MassoftheStructurekgEditField_2Label  matlab.ui.control.Label
        BoundryConditionsPanel          matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        BCDirectionSwitch_2             matlab.ui.control.Switch
        BCDirectionSwitch_2Label        matlab.ui.control.Label
        BCTypeSwitch_2                  matlab.ui.control.Switch
        BCTypeSwitch_2Label             matlab.ui.control.Label
        NodeNumberEditField_2           matlab.ui.control.NumericEditField
        NodeNumberEditField_2Label_2    matlab.ui.control.Label
        MagnitudeNormEditField_2        matlab.ui.control.NumericEditField
        MagnitudeNormEditField_2Label   matlab.ui.control.Label
        RemoveAllBoundryConditionsButton_2  matlab.ui.control.Button
        ApplyBoundryConditionButton_2   matlab.ui.control.Button
        MaterialPropertiesPanel_2       matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        Densitykgm3EditField_2          matlab.ui.control.NumericEditField
        Densitykgm3EditField_2Label     matlab.ui.control.Label
        Am2EditField_2                  matlab.ui.control.NumericEditField
        Am2EditField_2Label             matlab.ui.control.Label
        EPaEditField_2                  matlab.ui.control.NumericEditField
        EPaEditField_2Label             matlab.ui.control.Label
        ApplyMaterialPropertiesButton_2  matlab.ui.control.Button
        CreatingtheStructurePanel       matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        NumberofNodesEditField_2        matlab.ui.control.NumericEditField
        NumberofNodesLabel              matlab.ui.control.Label
        NumberofElementsEditField_2     matlab.ui.control.NumericEditField
        NumberofElementsLabel           matlab.ui.control.Label
        ElementSizeSlider_2             matlab.ui.control.Slider
        ElementSizeSlider_2Label        matlab.ui.control.Label
        NodeSizeSlider_2                matlab.ui.control.Slider
        NodeSizeSlider_2Label           matlab.ui.control.Label
        ElementLabelSwitch_2            matlab.ui.control.ToggleSwitch
        ElementLabelSwitch_2Label       matlab.ui.control.Label
        NodeLabelSwitch_2               matlab.ui.control.ToggleSwitch
        NodeLabelSwitch_2Label          matlab.ui.control.Label
        RemoveStructureButton           matlab.ui.control.Button
        Analysisof2DSquaresBasedonTrussStructuresLabel  matlab.ui.control.Label
        UIAxes_displacement_2           matlab.ui.control.UIAxes
        UIAxes_stress_2                 matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Pre-process Properties
        NL = [] 
        EL = []
        ENL = []
        BCs = []
        NoN = 0
        NoE = 0
        DOFs = 0
        DOCs = 0
        NodeSize = 15
        ElementSize = 15
        NodeSwitchLabel = "On"
        ElementSwitchLabel = "On"
        colorMap = jet
        %ar = 1
        mag = 1
        mass = 0
        %cont_def_prop = "Yes" %continue with default properties

        % Process parameters
        BC_node = 1             
        BC_direction = [1 0]
        BC_type = 1
        BC_magnitude = 0
        %Fp = []
        %Up = []

        % Element properties
        E = 0
        E_default = 210*10^9
        A = 0
        A_default = 10^(-4) % 10^-4 m^2 = 1 cm^2
        density = 0
        density_default = 8000 %kg/m^3
        %material_properties = "default"

        disp_direction = "U_x"
        solve = "off"
        undeformed_s = "On";
        undeformed_d = "On";

        NL_2 = [] 
        EL_2 = []
        ENL_2 = []
        BCs_2 = []
        NoN_2 = 0
        NoE_2 = 0
        DOFs_2 = 0
        DOCs_2 = 0
        NodeSize_2 = 15
        ElementSize_2 = 15
        NodeSwitchLabel_2 = "On"
        ElementSwitchLabel_2 = "On"
        colorMap_2 = jet
        %ar = 1
        mag_2 = 1
        mass_2 = 0
        %cont_def_prop = "Yes" %continue with default properties
        inputfile

        % Process parameters
        BC_node_2 = 1             
        BC_direction_2 = [1 0]
        BC_type_2 = 1
        BC_magnitude_2 = 0
        %Fp = []
        %Up = []

        % Element properties
        E_2 = 0
        E_default_2 = 210*10^9
        A_2 = 0
        A_default_2 = 10^(-4) % 10^-4 m^2 = 1 cm^2
        density_2 = 0
        density_default_2 = 8000 %kg/m^3
        %material_properties = "default"

        disp_direction_2 = "U_x"
        solve_2 = "off"
        undeformed_s_2 = "On";
        undeformed_d_2 = "On";
    end
    
    methods (Access = private)
            
        function analysis_1 = updateAxes(app)
    
            cla(app.UIAxes);
            hold(app.UIAxes,"on");
            
            %colormap(app.UIAxes, app.colorMap);
            %colorbar(app.UIAxes);

            for i = 1 : app.NoE
                if app.ElementSize == 0
                    uialert(app.UIFigure, "Element Size must be larger than zero!", "Input Error");
                    app.ElementSize = 15;
                else
                    H = plot(app.UIAxes, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'LineWidth' ,app.ElementSize,'Color', 'k');
                    H.Color(4)=0.2;
                    app.NumberofElementsEditField.Value = app.NoE;
                end

            end

 
            switch app.NodeSwitchLabel
                case "On"
                    for i = 1:app.NoN
                        if app.NodeSize == 0
                            uialert(app.UIFigure, "Node Size must be larger than zero!", "Input Error");
                            app.NodeSize = 15;
                        else
                            if app.ENL(i,3) == -1 && app.ENL(i,4) == -1
                                plot(app.UIAxes, app.NL(i,1), app.NL(i,2), 's', 'MarkerSize', app.NodeSize, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            elseif app.ENL(i,3) == -1
                                plot(app.UIAxes, app.NL(i,1), app.NL(i,2), '>', 'MarkerSize', app.NodeSize, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            elseif app.ENL(i,4) == -1
                                plot(app.UIAxes, app.NL(i,1), app.NL(i,2), '^', 'MarkerSize', app.NodeSize, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            else
                                plot(app.UIAxes, app.NL(i,1), app.NL(i,2), 'o', 'MarkerSize', app.NodeSize, 'MarkerEdgeColor','k','MarkerFaceColor','r')    
                            end
                            
                            text(app.UIAxes ,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                        end

                        app.NumberofNodesEditField.Value = app.NoN;

                        if app.ENL(i,3) == -1
                            if app.ENL(i,9) == 0
                                %do nothing
                            else
                                text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append("\fontsize{20}", num2str(app.ENL(i, 9)), "m"), "\fontsize{30}\rightarrow"}, 'Color','blue','VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            end
                        end

                        if app.ENL(i, 4) == -1
                            if app.ENL(i,10) == 0
                                %do nothing
                            else
                                text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append('\fontsize{20}', num2str(app.ENL(i,10)),'m'),'\fontsize{30}\uparrow'}, 'Color', 'blue', 'VerticalAlignment','bottom', 'HorizontalAlignment','Center')
                            end
                        end

                        if (app.ENL(i,11) ~= 0) && (app.ENL(i,3) == 1)
                            
                            text(app.UIAxes,app.NL(i,1),app.NL(i,2), append('\fontsize{30}\rightarrow', '{\fontsize{20}', num2str(app.ENL(i,11)), 'N}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                          
                        end
                        
                        if (app.ENL(i,12) ~= 0) && (app.ENL(i,4) == 1)
                            
                            text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append('\fontsize{20}', num2str(app.ENL(i,12)),'N'),'\fontsize{30}\uparrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            
                        end
                    end
            end

            switch app.ElementSwitchLabel
                case "On"
                    for i=1:app.NoE 
                        text(app.UIAxes,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2,(app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,num2str(i),'Color','k','FontSize',16,'HorizontalAlignment','center')
                    end
            end

            hold(app.UIAxes, "off");

        end


        function analysis_2 = process(app)

            format long;

            K  = assemble_stiffness(app.ENL,app.EL,app.NL,app.E,app.A);
            Fp = assemble_forces(app.ENL, app.NL);
            Up = assemble_displacement(app.ENL,app.NL); 

            %assemble forces and displacements 
            Kpu= K(1:app.DOFs,1:app.DOFs); %DOFxDOF                                                                        
            Kpp= K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs); %DOFxDOC
            Kuu= K(app.DOFs+1:app.DOCs+app.DOFs,1:app.DOFs);% DOCxDOF
            Kup= K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);%DOCxDOC
            
            F = Fp - Kpp * Up;
            
            Uu= inv(Kpu)*F;  
            
            Fu=Kuu*Uu+Kup*Up;
            
            app.ENL = update_nodes(app.ENL,Uu,app.NL,Fu);
                       
            app.ENLTable.Data = app.ENL;
        end
        
        function analysis_3 = post_process(app)

            cla(app.UIAxes_stress);
            cla(app.UIAxes_displacement);
            colormap(app.UIAxes_stress, app.colorMap); 
            colormap(app.UIAxes_displacement, app.colorMap);
            hold(app.UIAxes_stress, "on");
            hold(app.UIAxes_displacement, "on");
            grid(app.UIAxes_stress, "on");
            grid(app.UIAxes_displacement, "on");

            switch app.undeformed_s
                case "On"
                    %%% plot elements %%%
                    for i=1:app.NoE
                        H_stress = plot(app.UIAxes_stress, [app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)] , [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth', 5, 'Color','k');
                        %plot([first node x, second node x], [first node y, second node y])
                        H_stress.Color(4)=0.2; %transparency  
                    end
        
                    %%%plot nodes%%%
                    for i = 1:app.NoN
                        plot(app.UIAxes_stress, app.NL(i,1),app.NL(i,2),'o','Markersize', 20,'MarkerEdgeColor','k', 'MarkerFaceColor','r');
                        %x and y coordinates of the nodes
                        
                    end 
            end

            switch app.undeformed_d
                case "On"
                    for i=1:app.NoE
                        H_displacement = plot(app.UIAxes_displacement, [app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)] , [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth', 5, 'Color','k');
                        %plot([first node x, second node x], [first node y, second node y])
                        H_displacement.Color(4)=0.2; %transparency
                    end
    
                    for i = 1:app.NoN
                        plot(app.UIAxes_displacement, app.NL(i,1),app.NL(i,2),'o','Markersize', 20,'MarkerEdgeColor','k', 'MarkerFaceColor','r');
                        %x and y coordinates of the nodes
                    end
            end


            %%% plot elements with stress distrubution%%%
            for i=1:app.NoE
                x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];  
                    %x starting coord+displacement added with magnification factor, x ending coord
                y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                    %y starting coord, y ending coord
                z = zeros(size(x)); %z value is needed to plot surface 
                sigma = app.E * (sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2 + (app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)/(sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2 + (app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                % (length change over original length) 
                %E* sqrt((xdisp1-xdisp2)^2+(ydisp1-ydisp2)^2) / ((xoriginal1-xoriginal2)^2 + (yoriginal1-yoriginal2)^2)
            
                %Stress Plot
                col_stress = [sigma sigma]; %stress does not vary inside the element 
                surface(app.UIAxes_stress, [x;x],[y;y],[z;z], [col_stress;col_stress], 'facecol','no','edgecol','interp','linew',4);
                %surface function to plot

                if app.disp_direction == "U_x"
                    dispx1 = abs(app.ENL(app.EL(i,1),9));
                    dispx2 = abs(app.ENL(app.EL(i,2),9));
                    col_dispx = [dispx1 dispx2];
                    surface(app.UIAxes_displacement, [x;x],[y;y],[z;z], [col_dispx;col_dispx], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction == "U_y"
                    dispy1 = abs(app.ENL(app.EL(i,1),10));
                    dispy2 = abs(app.ENL(app.EL(i,2),10));
                    col_dispy = [dispy1 dispy2];
                    surface(app.UIAxes_displacement, [x;x],[y;y],[z;z], [col_dispy;col_dispy], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction == "U"
                    alldisp1 = sqrt(app.ENL(app.EL(i,1),9)^2 + app.ENL(app.EL(i,1),10)^2);
                    alldisp2 = sqrt(app.ENL(app.EL(i,2),9)^2 + app.ENL(app.EL(i,2),10)^2);
                    col_alldisp = [alldisp1 alldisp2];
                    surface(app.UIAxes_displacement, [x;x],[y;y],[z;z], [col_alldisp;col_alldisp], 'facecol','no','edgecol','interp','linew',4);
                end
                
            end
            colorbar(app.UIAxes_displacement);
            colorbar(app.UIAxes_stress);
            hold(app.UIAxes_displacement, "off");
            hold(app.UIAxes_stress, "off");
        end

    %%% REST OF THE FUCTIONS ARE FOR VOID ANALYSES %%%

    function voids = updateAxes_2(app)
            cla(app.UIAxes_2);
            hold(app.UIAxes_2,"on");
            
            %colormap(app.UIAxes, app.colorMap);
            %colorbar(app.UIAxes);

            for i = 1 : app.NoE_2
                if app.ElementSize_2 == 0
                    uialert(app.UIFigure, "Element Size must be larger than zero!", "Input Error");
                    app.ElementSize_2 = 15;
                else
                    H = plot(app.UIAxes_2, [app.NL_2(app.EL_2(i,1),1), app.NL_2(app.EL_2(i,2),1)], [app.NL_2(app.EL_2(i,1),2), app.NL_2(app.EL_2(i,2),2)],'LineWidth' ,app.ElementSize_2,'Color', 'k');
                    H.Color(4)=0.2;
                    app.NumberofElementsEditField_2.Value = app.NoE_2;
                end

            end

 
            switch app.NodeSwitchLabel_2
                case "On"
                    for i = 1:app.NoN_2
                        if app.NodeSize_2 == 0
                            uialert(app.UIFigure, "Node Size must be larger than zero!", "Input Error");
                            app.NodeSize_2 = 15;
                        else
                            if app.ENL_2(i,3) == -1 && app.ENL_2(i,4) == -1
                                plot(app.UIAxes_2, app.NL_2(i,1), app.NL_2(i,2), 's', 'MarkerSize', app.NodeSize_2, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            elseif app.ENL_2(i,3) == -1
                                plot(app.UIAxes_2, app.NL_2(i,1), app.NL_2(i,2), '>', 'MarkerSize', app.NodeSize_2, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            elseif app.ENL_2(i,4) == -1
                                plot(app.UIAxes_2, app.NL_2(i,1), app.NL_2(i,2), '^', 'MarkerSize', app.NodeSize_2, 'MarkerEdgeColor','k','MarkerFaceColor','k')
                            
                            else
                                plot(app.UIAxes_2, app.NL_2(i,1), app.NL_2(i,2), 'o', 'MarkerSize', app.NodeSize_2, 'MarkerEdgeColor','k','MarkerFaceColor','r')    
                            end
                            
                            text(app.UIAxes_2 ,app.NL_2(i,1),app.NL_2(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                        end

                        app.NumberofNodesEditField_2.Value = app.NoN_2;

                        if app.ENL_2(i,3) == -1
                            if app.ENL_2(i,9) == 0
                                %do nothing
                            else
                                text(app.UIAxes_2,app.NL_2(i,1),app.NL_2(i,2), {append("\fontsize{20}", num2str(app.ENL_2(i, 9)), "m"), "\fontsize{30}\rightarrow"}, 'Color','blue','VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            end
                        end

                        if app.ENL_2(i, 4) == -1
                            if app.ENL_2(i,10) == 0
                                %do nothing
                            else
                                text(app.UIAxes_2,app.NL_2(i,1),app.NL_2(i,2), {append('\fontsize{20}', num2str(app.ENL_2(i,10)),'m'),'\fontsize{30}\uparrow'}, 'Color', 'blue', 'VerticalAlignment','bottom', 'HorizontalAlignment','Center')
                            end
                        end

                        if (app.ENL_2(i,11) ~= 0) && (app.ENL_2(i,3) == 1)
                            
                            text(app.UIAxes_2,app.NL_2(i,1),app.NL_2(i,2), append('\fontsize{30}\rightarrow', '{\fontsize{20}', num2str(app.ENL_2(i,11)), 'N}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                          
                        end
                        
                        if (app.ENL_2(i,12) ~= 0) && (app.ENL_2(i,4) == 1)
                            
                            text(app.UIAxes_2,app.NL_2(i,1),app.NL_2(i,2), {append('\fontsize{20}', num2str(app.ENL_2(i,12)),'N'),'\fontsize{30}\uparrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            
                        end
                    end
            end

            switch app.ElementSwitchLabel_2
                case "On"
                    for i=1:app.NoE_2
                        text(app.UIAxes_2,(app.NL_2(app.EL_2(i,1),1)+app.NL_2(app.EL_2(i,2),1))/2,(app.NL_2(app.EL_2(i,1),2)+app.NL_2(app.EL_2(i,2),2))/2,num2str(i),'Color','k','FontSize',16,'HorizontalAlignment','center')
                    end
            end

            hold(app.UIAxes_2, "off");
        end

    function voids_2 = process_2(app)

            format long;

            K_2  = assemble_stiffness(app.ENL_2,app.EL_2,app.NL_2,app.E_2,app.A_2);
            Fp_2 = assemble_forces(app.ENL_2, app.NL_2);
            Up_2 = assemble_displacement(app.ENL_2,app.NL_2); 

            %assemble forces and displacements 
            Kpu_2= K_2(1:app.DOFs_2,1:app.DOFs_2); %DOFxDOF                                                                        
            Kpp_2= K_2(1:app.DOFs_2, app.DOFs_2+1:app.DOFs_2+app.DOCs_2); %DOFxDOC
            Kuu_2= K_2(app.DOFs_2+1:app.DOCs_2+app.DOFs_2,1:app.DOFs_2);% DOCxDOF
            Kup_2= K_2(app.DOFs_2+1:app.DOCs_2+app.DOFs_2,app.DOFs_2+1:app.DOCs_2+app.DOFs_2);%DOCxDOC
            
            F_2 = Fp_2 - Kpp_2 * Up_2;
            
            Uu_2= inv(Kpu_2)*F_2;  
            
            Fu_2=Kuu_2*Uu_2+Kup_2*Up_2;
            
            app.ENL_2 = update_nodes(app.ENL_2,Uu_2,app.NL_2,Fu_2);
                       
            %app.ENLTable.Data = app.ENL;
        end
        
        function voids_3 = post_process_2(app)

            cla(app.UIAxes_stress_2);
            cla(app.UIAxes_displacement_2);
            colormap(app.UIAxes_stress_2, app.colorMap_2); 
            colormap(app.UIAxes_displacement_2, app.colorMap_2);
            hold(app.UIAxes_stress_2, "on");
            hold(app.UIAxes_displacement_2, "on");
            grid(app.UIAxes_stress_2, "on");
            grid(app.UIAxes_displacement_2, "on");

            switch app.undeformed_s_2
                case "On"
                    %%% plot elements %%%
                    for i=1:app.NoE_2
                        H_stress_2 = plot(app.UIAxes_stress_2, [app.NL_2(app.EL_2(i,1),1),app.NL_2(app.EL_2(i,2),1)] , [app.NL_2(app.EL_2(i,1),2),app.NL_2(app.EL_2(i,2),2)], 'LineWidth', 5, 'Color','k');
                        %plot([first node x, second node x], [first node y, second node y])
                        H_stress_2.Color(4)=0.2; %transparency  
                    end
        
                    %%%plot nodes%%%
                    for i = 1:app.NoN_2
                        plot(app.UIAxes_stress_2, app.NL_2(i,1),app.NL_2(i,2),'o','Markersize', 20,'MarkerEdgeColor','k', 'MarkerFaceColor','r');
                        %x and y coordinates of the nodes
                        
                    end 
            end

            switch app.undeformed_d_2
                case "On"
                    for i=1:app.NoE_2
                        H_displacement_2 = plot(app.UIAxes_displacement_2, [app.NL_2(app.EL_2(i,1),1),app.NL_2(app.EL_2(i,2),1)] , [app.NL_2(app.EL_2(i,1),2),app.NL_2(app.EL_2(i,2),2)], 'LineWidth', 5, 'Color','k');
                        %plot([first node x, second node x], [first node y, second node y])
                        H_displacement_2.Color(4)=0.2; %transparency
                    end
    
                    for i = 1:app.NoN_2
                        plot(app.UIAxes_displacement_2, app.NL_2(i,1),app.NL_2(i,2),'o','Markersize', 20,'MarkerEdgeColor','k', 'MarkerFaceColor','r');
                        %x and y coordinates of the nodes
                    end
            end


            %%% plot elements with stress distrubution%%%
            for i=1:app.NoE_2
                x = [app.ENL_2(app.EL_2(i,1),1)+app.mag_2*app.ENL_2(app.EL_2(i,1),9) app.ENL_2(app.EL_2(i,2),1)+app.mag_2*app.ENL_2(app.EL_2(i,2),9)];  
                    %x starting coord+displacement added with magnification factor, x ending coord
                y = [app.ENL_2(app.EL_2(i,1),2)+app.mag_2*app.ENL_2(app.EL_2(i,1),10) app.ENL_2(app.EL_2(i,2),2)+app.mag_2*app.ENL_2(app.EL_2(i,2),10)];
                    %y starting coord, y ending coord
                z = zeros(size(x)); %z value is needed to plot surface 
                sigma_2 = app.E_2 * (sqrt((app.ENL_2(app.EL_2(i,1),9)-app.ENL_2(app.EL_2(i,2),9))^2 + (app.ENL_2(app.EL_2(i,1),10)-app.ENL_2(app.EL_2(i,2),10))^2)/(sqrt((app.ENL_2(app.EL_2(i,1),1)-app.ENL_2(app.EL_2(i,2),1))^2 + (app.ENL_2(app.EL_2(i,1),2)-app.ENL_2(app.EL_2(i,2),2))^2)));
                % (length change over original length) 
                %E* sqrt((xdisp1-xdisp2)^2+(ydisp1-ydisp2)^2) / ((xoriginal1-xoriginal2)^2 + (yoriginal1-yoriginal2)^2)
            
                %Stress Plot
                col_stress_2 = [sigma_2 sigma_2]; %stress does not vary inside the element 
                surface(app.UIAxes_stress_2, [x;x],[y;y],[z;z], [col_stress_2;col_stress_2], 'facecol','no','edgecol','interp','linew',4);
                %surface function to plot

                if app.disp_direction_2 == "U_x"
                    dispx1 = abs(app.ENL_2(app.EL_2(i,1),9));
                    dispx2 = abs(app.ENL_2(app.EL_2(i,2),9));
                    col_dispx = [dispx1 dispx2];
                    surface(app.UIAxes_displacement_2, [x;x],[y;y],[z;z], [col_dispx;col_dispx], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction_2 == "U_y"
                    dispy1 = abs(app.ENL_2(app.EL_2(i,1),10));
                    dispy2 = abs(app.ENL_2(app.EL_2(i,2),10));
                    col_dispy = [dispy1 dispy2];
                    surface(app.UIAxes_displacement_2, [x;x],[y;y],[z;z], [col_dispy;col_dispy], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction_2 == "U"
                    alldisp1 = sqrt(app.ENL_2(app.EL_2(i,1),9)^2 + app.ENL_2(app.EL_2(i,1),10)^2);
                    alldisp2 = sqrt(app.ENL_2(app.EL_2(i,2),9)^2 + app.ENL_2(app.EL_2(i,2),10)^2);
                    col_alldisp = [alldisp1 alldisp2];
                    surface(app.UIAxes_displacement_2, [x;x],[y;y],[z;z], [col_alldisp;col_alldisp], 'facecol','no','edgecol','interp','linew',4);
                end
                
            end
            colorbar(app.UIAxes_displacement_2);
            colorbar(app.UIAxes_stress_2);
            hold(app.UIAxes_displacement_2, "off");
            hold(app.UIAxes_stress_2, "off");
        end

        function reading = apply_selected_file(app)
            [app.inputfile,~] = uigetfile({'*.xlsx'},'Select file to open');
            try
                if ~isequal(app.inputfile,0)
                    app.NL_2 = [];
                    app.EL_2  = [];
                    app.BCs_2  = [];
                    nl_2  = readmatrix(app.inputfile,'Range', 'A:B');
                    el_2  = readmatrix(app.inputfile,'Range', 'C:D');
                    bcs_2  = readmatrix(app.inputfile,'Range', 'E:J');
                end
    
                for i = 1 : size(nl_2 ,1)
                    if ~isnan(nl_2 (i,:))
                        app.NL_2  = [app.NL_2 ; nl_2(i,:)];
                        app.BCs_2  = [app.BCs_2 ; bcs_2(i,:)];
                    end
                end

                for i = 1 : size(el_2,1)
                    if ~isnan(el_2(i,:))
                        app.EL_2  = [app.EL_2; el_2(i,:)];
                        app.E_2  = app.EPaEditField_2.Value;
                        app.A_2  = app.Am2EditField_2.Value;
                        app.density_2  = app.Densitykgm3EditField_2.Value;
                        el_length_2 = sqrt((app.NL_2(el_2(i,2), 1) - (app.NL_2(el_2(i,1), 1)))^2 + (app.NL_2(el_2(i,2), 2) - (app.NL_2(el_2(i,1), 2)))^2);
                        app.MassoftheStructurekgEditField_2.Value = app.MassoftheStructurekgEditField_2.Value + app.A_2*app.density_2*el_length_2;
                    end
                end
                [app.ENL_2, app.DOFs_2, app.DOCs_2] = assign_BCs(app.NL_2, app.BCs_2);
                app.NoE_2 = size(app.EL_2,1);
                app.NoN_2 = size(app.NL_2,1);
                updateAxes_2(app)
            catch
                 uialert(app.UIFigure, "You did not choose any file!", "Message");
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
                nl = [app.X_coor.Value, app.Y_coor.Value];
                enl = [nl zeros(1,10)];

%                 if app.solve == "on" && sum((find(app.ENL(:,[3 4])==1)) + (find(app.ENL(:,[3 4])==-1))) == 0
%                     uialert(app.UIFigure, "There are no enough boundry conditions!", "Inconclusive Analysis");
%                     app.solve = "off";
%                     app.SOLVELamp.Color = [1 0 0];
%                 else
%                     app.SOLVELamp.Color = [1 0 0];
%                 end
                
                if (app.NoN > 0) && (sum(ismember(app.NL, nl, "rows")) == 1)
                    uialert(app.UIFigure, "Node is alreadey input!", "Input Error");
                else 
                    %x = 1:app.NoN;
                    app.NL = [app.NL; nl];
                    app.NoN = size(app.NL,1);
                    app.ENL = [app.ENL; enl];
                    %app.ENLTable.Data = string([x(:) zeros(app.NoN,12)]);
                    app.BCs = [app.BCs; 1 1 0 0 0 0];
                    app.ENLTable.Data = app.ENL;
                    updateAxes(app);
                end

                
        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
                
%                 if app.solve == "on" && sum((find(app.ENL(:,[3 4])==1)) + (find(app.ENL(:,[3 4])==-1))) == 0
%                     uialert(app.UIFigure, "There are no enough boundry conditions!", "Inconclusive Analysis");
%                     app.solve = "off";
%                     app.SOLVELamp.Color = [1 0 0];
%                 else
%                     app.SOLVELamp.Color = [1 0 0];
%                 end

                if app.NoN < 2
                    uialert(app.UIFigure, "There should be at least 2 nodes to generate an element!", "Input Error");
                elseif app.FirstNode.Value == 0 || app.SecondNode.Value == 0 %|| (ismember(app.FirstNode.Value, app.NL) == 0 && ismember(app.SecondNode.Value, app.NL) == 0)
                    uialert(app.UIFigure, "Supplied nodes are not in the node list!", "Input Error");
                else 
                    el = [app.FirstNode.Value, app.SecondNode.Value];
                    n1 = app.ENL(app.FirstNode.Value,:);
                    n2 = app.ENL(app.SecondNode.Value,:);
                    x1 = n1(1);
                    x2 = n2(1);
                    y1 = n1(2);
                    y2 = n2(2);
                    el_length = sqrt((x1-x2)^2+(y1-y2)^2);
                    if (app.NoE > 0) && (sum(ismember(app.EL, el, "rows")) == 1)
                        uialert(app.UIFigure, "Element is alreadey input!", "Input Error");
                    elseif el_length > 3
                        uialert(app.UIFigure, "Element length cannot exceed 3 meters!", "Input Error");
                    elseif app.E == 0 && app.A == 0 && app.density == 0
                        msg = "Any specific Materail Property has not been added. Do you want to proceed with regular steel bar's properties. 'No' will remove all nodes and elements! (E=210 GPa, A=1 cm^2; Density = 8000 kg/m^3) ?";
                        title = 'Confirm Element';
                        selection = uiconfirm(app.UIFigure,msg,title,'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
                        if selection == "Yes"
                            app.EL = [app.EL; el];
                            app.NoE = size(app.EL, 1);
                            app.E = app.E_default;
                            app.A = app.A_default;
                            app.density = app.density_default;
                            app.EPaEditField.Value = app.E;
                            app.Am2EditField.Value = app.A;
                            app.Densitykgm3EditField.Value = app.density;
                            app.MassoftheStructurekgEditField.Value  = app.MassoftheStructurekgEditField.Value  + app.density*app.A*el_length;
                        else
                            uialert(app.UIFigure, "Then, you need to add specific properties beforehand!", "Warning");
                            RemoveAllNodesandElementsButtonPushed(app)
                        end
                    else
                        app.EL = [app.EL; el];
                        app.NoE = size(app.EL, 1);
                        app.MassoftheStructurekgEditField.Value = app.MassoftheStructurekgEditField.Value  + app.density*app.A*el_length;
                        app.TotalMasskgEditField.Value = app.TotalMasskgEditField.Value + app.A*app.density*el_length;
                    end
                    
                end
                updateAxes(app);

        end

        % Button pushed function: ReadNLandELButton
        function ReadNLandELButtonPushed(app, event)
            [inputfile,~] = uigetfile({'*.xlsx'},'Select file to open');
            try
                if ~isequal(inputfile,0)
                    %REMOVEALLButtonPushed(app)
                    app.NL = [];
                    app.EL = [];
                    app.BCs = [];
                    nl = readmatrix(inputfile,'Range', 'A:B');
                    el = readmatrix(inputfile,'Range', 'C:D');
                    bcs = readmatrix(inputfile,'Range', 'E:J');
                end
    
                for i = 1 : size(nl,1)
                    if ~isnan(nl(i,:))
                        app.NL = [app.NL; nl(i,:)];
                        app.BCs = [app.BCs; bcs(i,:)];
                    end
                end
    
                %if app.E == 210*10^9 && app.A == 10^-4 && app.density == 8000
                %    msg = "Material properties are default values, plotting and calculating will proceed with them!";
                %    title = 'Warning!';
                %    selection = uiconfirm(app.UIFigure,msg,title,'Options',{'Continue with Default Values','Cancel'},'DefaultOption',2,'CancelOption',2);
                %    if selection == "Continue with Default Values"
                for i = 1 : size(el,1)
                    if ~isnan(el(i,:))
                        app.EL = [app.EL; el(i,:)];
                        app.E = app.EPaEditField.Value;
                        app.A = app.Am2EditField.Value;
                        app.density = app.Densitykgm3EditField.Value;
                        el_length = sqrt((app.NL(el(i,2), 1) - (app.NL(el(i,1), 1)))^2 + (app.NL(el(i,2), 2) - (app.NL(el(i,1), 2)))^2);
                        app.MassoftheStructurekgEditField.Value = app.MassoftheStructurekgEditField.Value + app.A*app.density*el_length;
                        app.TotalMasskgEditField.Value = app.TotalMasskgEditField.Value + app.A*app.density*el_length;
                    end
                end
                [app.ENL, app.DOFs, app.DOCs] = assign_BCs(app.NL, app.BCs);
                app.NoE = size(app.EL,1);
                app.NoN = size(app.NL,1);
    
%                 app.ENL(:, [1 2]) = app.NL;
%                 app.ENL(:, [3 4]) = ones(app.NoN, 2);
%                 app.ENL(:, [5 6 7 8 9 10 11 12]) = zeros(app.NoN,8);
                %app.BCs(:, [3 4 5 6]) = zeros(app.NoN,4);
                %app.BCs(:, [1 2]) = ones(app.NoN, 2);
                updateAxes(app)
                app.ENLTable.Data = app.ENL;
            catch
                 uialert(app.UIFigure, "You did not choose any file or selected file has no permission to open!", "Message");
            end

            %app.NLTable.Data = app.NL;
            %app.ElementTable.Data = app.EL;
        end

        % Value changed function: ElementLabelSwitch
        function ElementLabelSwitchValueChanged(app, event)
            value = app.ElementLabelSwitch.Value;
            app.ElementSwitchLabel = value;
            updateAxes(app);
        end

        % Value changed function: NodeLabelSwitch
        function NodeLabelSwitchValueChanged(app, event)
            value = app.NodeLabelSwitch.Value;
            app.NodeSwitchLabel = value;
            updateAxes(app);
        end

        % Value changed function: ElementSizeSlider
        function ElementSizeSliderValueChanged(app, event)
            value = app.ElementSizeSlider.Value;

            if value == 0
                uialert(app.UIFigure, "Element Size must be larger than zero!", "Input Error");
                app.ElementSize = 15;
                app.ElementSizeSlider.Value = 15;
            else 
                app.ElementSize = value;
            end

            updateAxes(app);
        end

        % Value changed function: NodeSizeSlider
        function NodeSizeSliderValueChanged(app, event)
            value = app.NodeSizeSlider.Value;

            if value == 0
                uialert(app.UIFigure, "Node Size must be larger than zero!", "Input Error");
                app.NodeSize = 15;
                app.NodeSizeSlider.Value = 15;
            else 
                app.NodeSize = value;
            end

            updateAxes(app);
        end

        % Value changed function: NodeNumberEditField
        function NodeNumberEditFieldValueChanged(app, event)
            value = app.NodeNumberEditField.Value;
            app.BC_node = value;
        end

        % Value changed function: BCDirectionSwitch
        function BCDirectionSwitchValueChanged(app, event)
            value = app.BCDirectionSwitch.Value;
            if value == 'X'
                app.BC_direction = [1,0];
            else 
                app.BC_direction = [0,1];
            end
        end

        % Value changed function: BCTypeSwitch
        function BCTypeSwitchValueChanged(app, event)
            value = app.BCTypeSwitch.Value;
            if value == "Displacement"
                app.BC_type = -1;
            else
                app.BC_type = 1;
            end
        end

        % Value changed function: MagnitudeNormEditField
        function MagnitudeNormEditFieldValueChanged(app, event)
            value = app.MagnitudeNormEditField.Value;
            app.BC_magnitude = value;
        end

        % Button pushed function: ApplyBoundryConditionButton
        function ApplyBoundryConditionButtonPushed(app, event)

%             if app.solve == "on"
%                 app.ENL(:, [9 10 11 12]) = zeros(app.NoN,4);
%                 app.BCs(:, [3 4 5 6]) = ones(app.NoN,4);
%                 app.solve = "off";
%                 app.SOLVELamp.Color = [1 0 0];
%             end

            if app.NoN < app.BC_node
                uialert(app.UIFigure," Supplied node is not in the node list!", "Imput Error")
            else
                if app.BC_type == 1
                    if app.BC_direction == [0,1]
                        app.BCs(app.BC_node,2) = 1;
                        app.BCs(app.BC_node,6) = app.BC_magnitude;
                    else
                        app.BCs(app.BC_node,1) = 1;
                        app.BCs(app.BC_node,5) = app.BC_magnitude;
                    end
                else
                     if app.BC_direction == [0,1]
                        app.BCs(app.BC_node,2) = -1;
                        app.BCs(app.BC_node,4) = app.BC_magnitude;
                     else
                        app.BCs(app.BC_node,1) = -1;
                        app.BCs(app.BC_node,3) = app.BC_magnitude;
                     end       
                end
            [app.ENL, app.DOFs, app.DOCs] = assign_BCs(app.NL, app.BCs);
            end
            app.ENLTable.Data = app.ENL;
            updateAxes(app);
        end

        % Value changed function: EPaEditField
        function EPaEditFieldValueChanged(app, event)
            value = app.EPaEditField.Value;
            app.E = value;
            updateAxes(app)
        end

        % Value changed function: Am2EditField
        function Am2EditFieldValueChanged(app, event)
            value = app.Am2EditField.Value;
            app.A = value;
            updateAxes(app)
        end

        % Value changed function: Densitykgm3EditField
        function Densitykgm3EditFieldValueChanged(app, event)
            value = app.Densitykgm3EditField.Value;
            app.density = value;
            updateAxes(app)
        end

        % Value changed function: DispacementDropDown
        function DispacementDropDownValueChanged(app, event)
            value = app.DispacementDropDown.Value;
            app.disp_direction = value;
            if app.solve == "on"
                post_process(app);
            end
        end

        % Value changed function: MagnifierSlider
        function MagnifierSliderValueChanged(app, event)
%deleted
        end

        % Value changing function: MagnifierSlider
        function MagnifierSliderValueChanging(app, event)
            changingValue = event.Value;
            app.mag = changingValue;
            post_process(app);
        end

        % Value changed function: NumberofNodesEditField
        function NumberofNodesEditFieldValueChanged(app, event)
            value = app.NumberofNodesEditField.Value;
            app.NoN = value;
            updateAxes(app);
        end

        % Value changed function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanged(app, event)
            value = app.NumberofElementsEditField.Value;
            app.NoE = value;
            updateAxes(app);
        end

        % Selection changed function: ColorMapButtonGroup
        function ColorMapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorMapButtonGroup.SelectedObject;

            switch selectedButton

                case app.Jet
                    app.colorMap = jet;
                    colormap(app.UIAxes_displacement, app.colorMap)
                    colormap(app.UIAxes_stress, app.colorMap)

                case app.Parula
                    app.colorMap = parula ;
                    colormap(app.UIAxes_displacement, app.colorMap)
                    colormap(app.UIAxes_stress, app.colorMap)

                case app.Bone
                    app.colorMap = bone ;
                    colormap(app.UIAxes_displacement, app.colorMap)
                    colormap(app.UIAxes_stress, app.colorMap)
            end
            if app.solve == "on"
                post_process(app)
            end

        end

        % Button pushed function: RemoveAllNodesandElementsButton
        function RemoveAllNodesandElementsButtonPushed(app, event)
             app.X_coor.Value=0;
             app.Y_coor.Value=0;
             app.FirstNode.Value=0;
             app.SecondNode.Value=0;
             app.NumberofElementsEditField.Value=0;
             app.NumberofNodesEditField.Value=0;
             app.NL = []; 
             app.EL = [];
             app.ENL = [];
             app.BCs = [];
             app.NoN = 0;
             app.NoE = 0;
             app.DOFs = 0;
             app.DOCs = 0;
             app.MassoftheStructurekgEditField.Value = 0;
             app.E = 0;
             app.A = 0;
             app.density = 0;
             app.EPaEditField.Value = 210*10^9;
             app.Densitykgm3EditField.Value = 8000;
             app.Am2EditField.Value = 0.0001;
             app.ENLTable.Data = app.ENL;
             app.solve = "off";
             app.NodeSize = 15;
             app.ElementSize = 15;
             app.mag = 1;
             updateAxes(app); 
        end

        % Button pushed function: RemoveAllBoundryConditionsButton
        function RemoveAllBoundryConditionsButtonPushed(app, event)
             app.BCs = [];
             %app.ENL(:, [3 4 9 10 11 12]) = zeros(app.NoN,6);
            
            for i = 1:app.NoN 
                 app.ENL(i, [3 4 9 10 11 12]) = [0 0 0 0 0 0];
                 app.BCs(i, :) = [1 1 0 0 0 0];
             end
             if app.solve == "on"
                 app.solve = "off";
                 app.SOLVELamp.Color = [1 0 0];
             end
             %x = 1:app.NoN;
             %app.ENLTable.Data = string([x(:) app.ENL]);
             app.ENLTable.Data = app.ENL;
             updateAxes(app);
        end

        % Button pushed function: ApplyMaterialPropertiesButton
        function ApplyMaterialPropertiesButtonPushed(app, event)
%             if app.solve == "on"
%                 app.ENL(:, [9 10 11 12]) = zeros(app.NoN,4);
%                 app.BCs(:, [3 4 5 6]) = ones(app.Non,4);
%                 app.solve = "off";
%                 app.SOLVELamp.Color = [1 0 0];
%                 x = 1:app.NoN;
%                 app.ENLTable.Data = string([x(:) app.ENL]);
%                 updateAxes(app)
%             end
            
            if app.NoE == 0 
                if app.EPaEditField.Value == app.E_default && app.Am2EditField.Value == app.A_default && app.Densitykgm3EditField.Value == app.density_default
                    app.E = app.E_default;
                    app.A = app.A_default;
                    app.density = app.density_default;
                    uialert(app.UIFigure, "Default properties are applied (E=210 GPa, A=1 cm^2; Density = 8000 kg/m^3)", "Message");
                    %app.mass = app.MassoftheStructurekgEditField.Value;
                    %app.mass = app.TotalMasskgEditFieldValueChanged(app)
                else
                    EPaEditFieldValueChanged(app);
                    Am2EditFieldValueChanged(app);
                    Densitykgm3EditFieldValueChanged(app);
                    uialert(app.UIFigure, "Properties that you enterred are applied (E=" + app.E+"GPa, A=" +app.A +" m^2, Density ="+ app.density +" kg/m^3)", "Message");
                    %MassoftheStructurekgEditFieldValueChanged(app)
                    %TotalMasskgEditFieldValueChanged(app)
                end
            else
                msg = "You have elements with default properties!" +  ...
                " - To change the materail properties without any discrepancy among truss elements, push 'Remove All Nodes and Elements' button" + ...
                " - To continue with default properties, push the 'Continue with Default Values' button";
                title = 'Discrepancy among Material Properties';
                selection = uiconfirm(app.UIFigure,msg,title,'Options',{'Remove All Nodes and Elements','Continue with Default Values'},'DefaultOption',2,'CancelOption',2);
                    if strcmp(selection, 'Remove All Nodes and Elements') == 1
                        RemoveAllNodesandElementsButtonPushed(app);
                        %app.EPaEditFieldValueChanged(app)
                        %app.Am2EditFieldValueChanged(app)
                        %app.Densitykgm3EditFieldValueChanged(app)
                        uialert(app.UIFigure, "Structure is removed and default values are applied as properties. To change them enter properties before creating the structure!", "Message");
                        %MassoftheStructurekgEditFieldValueChanged(app)
                        %TotalMasskgEditFieldValueChanged(app)
                    else
                        app.E = app.E_default;
                        app.A = app.A_default;
                        app.density = app.density_default;
                        app.EPaEditField.Value = app.E_default;
                        app.Am2EditField.Value = app.A_default;
                        app.Densitykgm3EditField.Value = app.density_default;
                        uialert(app.UIFigure, "Default properties are continued to be applied (E=210 GPa, A=1 cm^2; Density = 8000 kg/m^3)", "Message");
                        MassoftheStructurekgEditFieldValueChanged(app)
                        TotalMasskgEditFieldValueChanged(app)
                    end
            end
            
        end

        % Button pushed function: REMOVEALLButton
        function REMOVEALLButtonPushed(app, event)
            RemoveAllBoundryConditionsButtonPushed(app)
            RemoveAllNodesandElementsButtonPushed(app)
            REMOVERESULTSButtonPushed(app)
        end

        % Button pushed function: SOLVEButton
        function SOLVEButtonPushed(app, event)
            [app.ENL, app.DOFs, app.DOCs] = assign_BCs(app.NL, app.BCs);

            if ismember(0,app.E) || ismember(0,app.A) || ismember(0,app.density)
                uialert(app.UIFigure,"Some elements do not have proper material properties!", "Input Error")
            else
                try
                    app.MagnifierSlider.Value = 1;
                    process(app);
                    app.SOLVELamp.Color = [0 1 0];
                    app.solve = "on";
                    post_process(app);
                    uialert(app.UIFigure, "Done! check the Analyses page.", "Result");
                catch
                    uialert(app.UIFigure, "Ups! Something went wrong, be sure that your system is not inderminant or misses any BCs.", "Detrimental Error");

                end
            end
           
        end

        % Value changed function: MassoftheStructurekgEditField
        function MassoftheStructurekgEditFieldValueChanged(app, event)
            value = app.MassoftheStructurekgEditField.Value;
            app.mass = value;
            updateAxes(app);
        end

        % Value changed function: ShowUndeformedStructureSwitch
        function ShowUndeformedStructureSwitchValueChanged(app, event)
            app.undeformed_s = app.ShowUndeformedStructureSwitch.Value;
            if app.solve == "on"
                post_process(app);
            end
        end

        % Value changed function: ShowUndeformedStructureSwitch_2
        function ShowUndeformedStructureSwitch_2ValueChanged(app, event)
            app.undeformed_d = app.ShowUndeformedStructureSwitch_2.Value;
            if app.solve == "on"
                post_process(app);
            end
        end

        % Value changed function: TotalMasskgEditField
        function TotalMasskgEditFieldValueChanged(app, event)
            value = app.TotalMasskgEditField.Value;
            app.MassoftheStructurekgEditField.Value = value;
        end

        % Button pushed function: REMOVERESULTSButton
        function REMOVERESULTSButtonPushed(app, event)
            msg = "Remove Results operation clears all boundry conditions. Do you want to continue?";
            title = 'Confirm Remove Results';
            selection = uiconfirm(app.UIFigure,msg,title,'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
                if selection == "Yes"
                    app.MagnifierSlider.Value = 1;
                    RemoveAllBoundryConditionsButtonPushed(app)
                    cla(app.UIAxes_stress);
                    cla(app.UIAxes_displacement);
                end
        end

        % Button pushed function: RemoveNodeButton
        function RemoveNodeButtonPushed(app, event)
            selected_node = app.RemoveNodeNumber.Value;
%             if app.solve == "on"
%                 app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
%                 app.BCs(:,[3 4 5 6]) = zeros(app.NoN,4);
%                 app.solve = "off";
%                 app.SOLVELamp.Color = [1 0 0];
%             end
            
            if selected_node == 0 || selected_node > app.NoN 
                uialert(app.UIFigure,"Node does not exist!","Input Error")
            else
                if app.NoE > 0
                    x_ind = find(app.EL(:,1)==selected_node);
                    y_ind = find(app.EL(:,2)==selected_node);
                    indices = [x_ind;y_ind];  
                    %if ismember(indices, app.EL) == 1
                    for i = 1:length(indices) %to find how many elements do involve selected_node
                        el_length = sqrt((app.NL(app.EL(indices(i),2), 1) - (app.NL(app.EL(indices(i),1), 1)))^2 + (app.NL(app.EL(indices(i),2), 2) - (app.NL(app.EL(indices(i),1), 2)))^2);
                        app.MassoftheStructurekgEditField.Value = app.MassoftheStructurekgEditField.Value - app.A*app.density*el_length; 
                    end
                
                    app.EL(indices,:) = [];
                    app.NoE = size(app.EL,1);
                    %app.ElementsCreated.Value = app.NoE;
                    %app.ElementsCreated_2.Value = app.NoE;
                    %app.E(node_coor,:) = [];
                    %app.A(node_coor,:) = [];
                    %app.density(node_coor,:) = [];
                    app.EL(app.EL>selected_node) = app.EL(app.EL>selected_node) - 1; 
                end
                app.NL(selected_node,:) = [];
                app.NoN = size(app.NL,1);
                app.BCs(selected_node,:) = [];

                %x = 1:app.NoN;
                %app.ENLTable.Data = string([x(:) zeros(app.NoN, 12)]); 
                [app.ENL, app.DOFs , app.DOCs] = assign_BCs(app.NL, app.BCs);
                %app.ENL = cat(2, app.NL, app.BCs(:, logical(app.BCs(end, [1 2]))), zeros(app.NoN,8));
                app.ENLTable.Data = app.ENL;
                updateAxes(app);
            end
        end

        % Value changed function: RemoveElementNumber
        function RemoveElementNumberValueChanged(app, event)
%deleted            
        end

        % Button pushed function: RemoveElemetButton
        function RemoveElemetButtonPushed(app, event)
            selected_element = app.RemoveElementNumber.Value;
%             if app.solve == "on"
%                 app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
%                 app.BCs(:,[3 4 5 6]) = zeros(app.NoN,4);
%                 app.solve = "off";
%                 app.SOLVELamp.Color = [1 0 0];
%             end
            
            if selected_element == 0 || selected_element > app.NoE 
                uialert(app.UIFigure,"Element does not exist!","Input Error")
            else
                el_length = sqrt((app.NL(app.EL(selected_element,2), 1) - (app.NL(app.EL(selected_element,1), 1)))^2 + (app.NL(app.EL(selected_element,2), 2) - (app.NL(app.EL(selected_element,1), 2)))^2);
                app.MassoftheStructurekgEditField.Value = app.MassoftheStructurekgEditField.Value - app.A*app.density*el_length; 
                
                app.EL(selected_element,:) = [];
                app.NoE = size(app.EL,1);
                updateAxes(app);
            end
        end

        % Value changed function: NumberofNodesEditField_2
        function NumberofNodesEditField_2ValueChanged(app, event)
            value = app.NumberofNodesEditField_2.Value;
            app.NoN_2 = value;
            updateAxes_2(app);
        end

        % Value changed function: NumberofElementsEditField_2
        function NumberofElementsEditField_2ValueChanged(app, event)
            value = app.NumberofElementsEditField_2.Value;
            app.NoE_2 = value;
            updateAxes_2(app);
        end

        % Button pushed function: RemoveStructureButton
        function RemoveStructureButtonPushed(app, event)
             %app.X_coor_2.Value=0;
             %app.Y_coor_2.Value=0;
             %app.FirstNode_2.Value=0;
             %app.SecondNode_2.Value=0;
             app.NumberofElementsEditField_2.Value=0;
             app.NumberofNodesEditField_2.Value=0;
             app.NL_2 = []; 
             app.EL_2 = [];
             app.ENL_2 = [];
             app.BCs_2 = [];
             app.NoN_2 = 0;
             app.NoE_2 = 0;
             app.DOFs_2 = 0;
             app.DOCs_2 = 0;
             app.MassoftheStructurekgEditField_2.Value = 0;
             app.E_2 = 0;
             app.A_2 = 0;
             app.density_2 = 0;
             app.EPaEditField_2.Value = 210*10^9;
             app.Densitykgm3EditField_2.Value = 8000;
             app.Am2EditField_2.Value = 0.0001;
             %app.ENLTable_2.Data = app.ENL_2;
             app.solve_2 = "off";
             app.NodeSize_2 = 15;
             app.ElementSize_2 = 15;
             app.mag_2 = 1;
             updateAxes_2(app); 
        end

        % Value changed function: NodeLabelSwitch_2
        function NodeLabelSwitch_2ValueChanged(app, event)
            value = app.NodeLabelSwitch_2.Value;
            app.NodeSwitchLabel_2 = value;
            updateAxes_2(app);
        end

        % Value changed function: ElementLabelSwitch_2
        function ElementLabelSwitch_2ValueChanged(app, event)
            value = app.ElementLabelSwitch_2.Value;
            app.ElementSwitchLabel_2 = value;
            updateAxes_2(app);
        end

        % Value changed function: NodeSizeSlider_2
        function NodeSizeSlider_2ValueChanged(app, event)
            value = app.NodeSizeSlider_2.Value;

            if value == 0
                uialert(app.UIFigure, "Node Size must be larger than zero!", "Input Error");
                app.NodeSize_2 = 15;
                app.NodeSizeSlider_2.Value = 15;
            else 
                app.NodeSize_2 = value;
            end

            updateAxes_2(app);
        end

        % Value changed function: ElementSizeSlider_2
        function ElementSizeSlider_2ValueChanged(app, event)
            value = app.ElementSizeSlider_2.Value;

            if value == 0
                uialert(app.UIFigure, "Element Size must be larger than zero!", "Input Error");
                app.ElementSize_2 = 15;
                app.ElementSizeSlider_2.Value = 15;
            else 
                app.ElementSize_2 = value;
            end

            updateAxes_2(app);
        end

        % Value changed function: EPaEditField_2
        function EPaEditField_2ValueChanged(app, event)
            value = app.EPaEditField_2.Value;
            app.E_2 = value;
            updateAxes_2(app)
        end

        % Value changed function: Am2EditField_2
        function Am2EditField_2ValueChanged(app, event)
            value = app.Am2EditField_2.Value;
            app.A_2 = value;
            updateAxes_2(app)
        end

        % Value changed function: Densitykgm3EditField_2
        function Densitykgm3EditField_2ValueChanged(app, event)
            value = app.Densitykgm3EditField_2.Value;
            app.density_2 = value;
            updateAxes_2(app)
        end

        % Button pushed function: ApplyMaterialPropertiesButton_2
        function ApplyMaterialPropertiesButton_2Pushed(app, event)
%             if app.solve_2 == "on"
%                 app.ENL_2(:, [9 10 11 12]) = zeros(app.NoN_2,4);
%                 app.BCs_2(:, [3 4 5 6]) = ones(app.NoN_2,4);
%                 app.solve_2 = "off";
%                 app.SOLVELamp.Color = [1 0 0];
%                 x = 1:app.NoN;
%                 app.ENLTable.Data = string([x(:) app.ENL]);
%                 updateAxes(app)
%             end
            
            if app.NoE_2 == 0 
                if app.EPaEditField_2.Value == app.E_default_2 && app.Am2EditField_2.Value == app.A_default_2 && app.Densitykgm3EditField_2.Value == app.density_default_2
                    app.E_2 = app.E_default_2;
                    app.A_2 = app.A_default_2;
                    app.density_2 = app.density_default_2;
                    uialert(app.UIFigure, "Default properties are applied (E=210 GPa, A=1 cm^2; Density = 8000 kg/m^3)", "Message");
                    %app.mass = app.MassoftheStructurekgEditField.Value;
                    %app.mass = app.TotalMasskgEditFieldValueChanged(app)
                else
                    EPaEditField_2ValueChanged(app);
                    Am2EditField_2ValueChanged(app);
                    Densitykgm3EditField_2ValueChanged(app);
                    uialert(app.UIFigure, "Properties that you enterred are applied (E=" + app.E_2+"GPa, A=" +app.A_2 +" m^2, Density ="+ app.density_2 +" kg/m^3)", "Message");
                    %MassoftheStructurekgEditFieldValueChanged(app)
                    %TotalMasskgEditFieldValueChanged(app)
                end
            else
                msg = "You have elements with default properties!" +  ...
                " - To change the materail properties without any discrepancy among truss elements, push 'Remove All Nodes and Elements' button" + ...
                " - To continue with default properties, push the 'Continue with Default Values' button";
                title = 'Discrepancy among Material Properties';
                selection = uiconfirm(app.UIFigure,msg,title,'Options',{'Remove All Nodes and Elements','Continue with Default Values'},'DefaultOption',2,'CancelOption',2);
                    if strcmp(selection, 'Remove All Nodes and Elements') == 1
                        RemoveStructureButtonPushed(app);
                        %app.EPaEditFieldValueChanged(app)
                        %app.Am2EditFieldValueChanged(app)
                        %app.Densitykgm3EditFieldValueChanged(app)
                        uialert(app.UIFigure, "Structure is removed and default values are applied as properties. To change them enter properties before creating the structure!", "Message");
                        %MassoftheStructurekgEditFieldValueChanged(app)
                        %TotalMasskgEditFieldValueChanged(app)
                    else
                        app.E_2 = app.E_default_2;
                        app.A_2 = app.A_default_2;
                        app.density_2 = app.density_default_2;
                        app.EPaEditField_2.Value = app.E_default_2;
                        app.Am2EditField_2.Value = app.A_default_2;
                        app.Densitykgm3EditField_2.Value = app.density_default_2;
                        uialert(app.UIFigure, "Default properties are continued to be applied (E=210 GPa, A=1 cm^2; Density = 8000 kg/m^3)", "Message");
                        MassoftheStructurekgEditField_2ValueChanged(app)
                        %TotalMasskgEditField_2ValueChanged(app)
                    end
            end
            
        end

        % Value changed function: BCTypeSwitch_2
        function BCTypeSwitch_2ValueChanged(app, event)
            value = app.BCTypeSwitch_2.Value;
            if value == "Displacement"
                app.BC_type_2 = -1;
            else
                app.BC_type_2 = 1;
            end
        end

        % Value changed function: BCDirectionSwitch_2
        function BCDirectionSwitch_2ValueChanged(app, event)
            value = app.BCDirectionSwitch_2.Value;
            if value == 'X'
                app.BC_direction_2 = [1,0];
            else 
                app.BC_direction_2 = [0,1];
            end
        end

        % Value changed function: MagnitudeNormEditField_2
        function MagnitudeNormEditField_2ValueChanged(app, event)
            value = app.MagnitudeNormEditField_2.Value;
            app.BC_magnitude_2 = value;
        end

        % Value changed function: NodeNumberEditField_2
        function NodeNumberEditField_2ValueChanged(app, event)
            value = app.NodeNumberEditField_2.Value;
            app.BC_node_2 = value;
        end

        % Button pushed function: ApplyBoundryConditionButton_2
        function ApplyBoundryConditionButton_2Pushed(app, event)
            
%             if app.solve == "on"
%                 app.ENL(:, [9 10 11 12]) = zeros(app.NoN,4);
%                 app.BCs(:, [3 4 5 6]) = ones(app.NoN,4);
%                 app.solve = "off";
%                 app.SOLVELamp.Color = [1 0 0];
%             end

            if app.NoN_2 < app.BC_node_2 
                uialert(app.UIFigure," Supplied node is not in the node list!", "Imput Error")
            else
                if app.BC_type_2  == 1
                    if app.BC_direction_2  == [0,1]
                        app.BCs_2(app.BC_node_2 ,2) = 1;
                        app.BCs_2(app.BC_node_2 ,6) = app.BC_magnitude_2 ;
                    else
                        app.BCs_2(app.BC_node_2 ,1) = 1;
                        app.BCs_2(app.BC_node_2 ,5) = app.BC_magnitude_2 ;
                    end
                else
                     if app.BC_direction_2  == [0,1]
                        app.BCs_2(app.BC_node_2 ,2) = -1;
                        app.BCs_2(app.BC_node_2 ,4) = app.BC_magnitude_2 ;
                     else
                        app.BCs_2(app.BC_node_2 ,1) = -1;
                        app.BCs_2(app.BC_node_2 ,3) = app.BC_magnitude_2 ;
                     end       
                end
            [app.ENL_2 , app.DOFs_2 , app.DOCs_2 ] = assign_BCs(app.NL_2 , app.BCs_2 );
            end
            %app.ENLTable.Data = app.ENL;
            updateAxes_2 (app);
        end

        % Button pushed function: RemoveAllBoundryConditionsButton_2
        function RemoveAllBoundryConditionsButton_2Pushed(app, event)
            app.BCs_2 = [];
             %app.ENL(:, [3 4 9 10 11 12]) = zeros(app.NoN,6);
            
            for i = 1:app.NoN_2 
                 app.ENL_2(i, [3 4 9 10 11 12]) = [0 0 0 0 0 0];
                 app.BCs_2(i, :) = [1 1 0 0 0 0];
             end
             if app.solve_2 == "on"
                 app.solve_2 = "off";
                 %app.SOLVELamp_2.Color = [1 0 0];
             end
             %x = 1:app.NoN;
             %app.ENLTable.Data = string([x(:) app.ENL]);
             %app.ENLTable.Data = app.ENL;
             updateAxes_2(app);
        end

        % Value changed function: MassoftheStructurekgEditField_2
        function MassoftheStructurekgEditField_2ValueChanged(app, event)
            value = app.MassoftheStructurekgEditField_2.Value;
            app.mass_2 = value;
            updateAxes_2(app);
        end

        % Button pushed function: SOLVEButton_2
        function SOLVEButton_2Pushed(app, event)
            [app.ENL_2, app.DOFs_2, app.DOCs_2] = assign_BCs(app.NL_2, app.BCs_2);

            if ismember(0,app.E_2) || ismember(0,app.A_2) || ismember(0,app.density_2)
                uialert(app.UIFigure,"Some elements do not have proper material properties!", "Input Error")
            else
                try
                    app.MagnifierSlider_2.Value = 1;
                    process_2(app);
                    %app.SOLVELamp.Color = [0 1 0];
                    app.solve_2 = "on";
                    post_process_2(app);
                    uialert(app.UIFigure, "Done! check the Analyses page.", "Result");
                catch
                    uialert(app.UIFigure, "Ups! Something went wrong, be sure that your system is not inderminant or misses any BCs.", "Detrimental Error");

                end
            end
           
        end

        % Selection changed function: ColorMapButtonGroup_2
        function ColorMapButtonGroup_2SelectionChanged(app, event)
            selectedButton_2 = app.ColorMapButtonGroup_2.SelectedObject;
            switch selectedButton_2

                case app.Jet
                    app.colorMap_2 = jet;
                    colormap(app.UIAxes_displacement_2, app.colorMap_2)
                    colormap(app.UIAxes_stress_2, app.colorMap_2)

                case app.Parula
                    app.colorMap_2  = parula ;
                    colormap(app.UIAxes_displacement_2 , app.colorMap_2 )
                    colormap(app.UIAxes_stress_2 , app.colorMap_2 )

                case app.Bone
                    app.colorMap_2  = bone ;
                    colormap(app.UIAxes_displacement_2 , app.colorMap_2 )
                    colormap(app.UIAxes_stress_2 , app.colorMap_2 )
            end
        
            post_process_2(app)


        end

        % Button pushed function: REMOVEALLButton_2
        function REMOVEALLButton_2Pushed(app, event)
            RemoveAllBoundryConditionsButton_2Pushed(app)
            RemoveStructureButtonPushed(app)
        end

        % Value changed function: StressUndeformedStructureSwitch
        function StressUndeformedStructureSwitchValueChanged(app, event)
            value = app.StressUndeformedStructureSwitch.Value;
            app.undeformed_s_2 = value;
            if app.solve_2 == "on"
                post_process_2(app);
            end
        end

        % Value changed function: DisplacementUndeformedStructureSwitch
        function DisplacementUndeformedStructureSwitchValueChanged(app, event)
            value = app.DisplacementUndeformedStructureSwitch.Value;
            app.undeformed_d_2 = value;
            if app.solve_2 == "on"
                post_process_2(app);
            end
        end

        % Drop down opening function: DispacementDropDown_2
        function DispacementDropDown_2Opening(app, event)
            value = app.DispacementDropDown_2.Value;
            app.disp_direction_2 = value;
            if app.solve_2 == "on"
                post_process_2(app);
            end
        end

        % Value changing function: MagnifierSlider_2
        function MagnifierSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.mag_2 = changingValue;
            post_process_2(app);
        end

        % Button pushed function: ReadDesiredSystemsButton
        function ReadDesiredSystemsButtonPushed(app, event)
            hole = app.HoleStructureDropDownOpening.Value;
            mesh = app.MeshDensityDropDown.Value;
            bcs = app.BoundryConditionDropDown.Value;
            if hole == "No Hole"
                if mesh == "3x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "nohole_3x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "nohole_3x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "nohole_3x4_pureexpansion";
                    end
                elseif mesh == "5x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "nohole_5x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "nohole_5x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "nohole_5x4_pureexpansion";
                    end
                elseif mesh == "10x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "nohole_10x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "nohole_10x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "nohole_10x4_pureexpansion";
                    end
                end
            elseif hole == "Square Hole"
                if mesh == "3x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "squarehole_3x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "squarehole_3x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "squarehole_3x4_pureexpansion";
                    end
                elseif mesh == "5x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "squarehole_5x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "squarehole_5x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "squarehole_5x4_pureexpansion";
                    end
                elseif mesh == "10x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "squarehole_10x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "squarehole_10x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "squarehole_10x4_pureexpansion";
                    end
                end
            elseif hole == "Circle Hole"
                if mesh == "3x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "circlehole_3x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "circlehole_3x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "circlehole_3x4_pureexpansion";
                    end
                elseif mesh == "5x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "circlehole_5x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "circlehole_5x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "circlehole_5x4_pureexpansion";
                    end
                elseif mesh == "10x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "circlehole_10x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "circlehole_10x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "circlehole_10x4_pureexpansion";
                    end
                end
            elseif hole == "Rhombus Hole"
                if mesh == "3x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "rhombushole_3x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "rhombushole_3x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "rhombushole_3x4_pureexpansion";
                    end
                elseif mesh == "5x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "rhombushole_5x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "rhombushole_5x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "rhombushole_5x4_pureexpansion";
                    end
                elseif mesh == "10x4"
                    if bcs == "Uniaxial Extension"
                        app.inputfile = "rhombushole_10x4_uniaxialextension";
                    elseif bcs == "Shear"
                        app.inputfile = "rhombushole_10x4_shear";
                    elseif bcs == "Pure Expansion"
                        app.inputfile = "rhombushole_10x4_pureexpansion";
                    end
                end
            end


%             [inputfile,~] = uigetfile({'*.xlsx'},'Select file to open');
%             try
%                 if ~isequal(inputfile,0)
%                     app.NL_2 = [];
%                     app.EL_2  = [];
%                     app.BCs_2  = [];
%                     nl_2  = readmatrix(inputfile,'Range', 'A:B');
%                     el_2  = readmatrix(inputfile,'Range', 'C:D');
%                     bcs_2  = readmatrix(inputfile,'Range', 'E:J');
%                 end
%     
%                 for i = 1 : size(nl_2 ,1)
%                     if ~isnan(nl_2 (i,:))
%                         app.NL_2  = [app.NL_2 ; nl_2(i,:)];
%                         app.BCs_2  = [app.BCs_2 ; bcs_2(i,:)];
%                     end
%                 end
% 
%                 for i = 1 : size(el_2,1)
%                     if ~isnan(el_2(i,:))
%                         app.EL_2  = [app.EL_2; el_2(i,:)];
%                         app.E_2  = app.EPaEditField_2.Value;
%                         app.A_2  = app.Am2EditField_2.Value;
%                         app.density_2  = app.Densitykgm3EditField_2.Value;
%                         el_length_2 = sqrt((app.NL_2(el_2(i,2), 1) - (app.NL_2(el_2(i,1), 1)))^2 + (app.NL_2(el_2(i,2), 2) - (app.NL_2(el_2(i,1), 2)))^2);
%                         app.MassoftheStructurekgEditField_2.Value = app.MassoftheStructurekgEditField_2.Value + app.A_2*app.density_2*el_length_2;
%                     end
%                 end
%                 [app.ENL_2, app.DOFs_2, app.DOCs_2] = assign_BCs(app.NL_2, app.BCs_2);
%                 app.NoE_2 = size(app.EL_2,1);
%                 app.NoN_2 = size(app.NL_2,1);
%                 updateAxes_2(app)
%             catch
%                  uialert(app.UIFigure, "You did not choose any file!", "Message");
%             end

        end

        % Value changed function: MagnifierSlider_2
        function MagnifierSlider_2ValueChanged(app, event)
%deleted
        end

        % Drop down opening function: HoleStructureDropDown
        function HoleStructureDropDownOpening(app, event)
            value = app.HoleStructureDropDownOpening.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProblemDeifinitionTab = uitab(app.TabGroup);
            app.ProblemDeifinitionTab.Title = 'Problem Deifinition';
            app.GridLayout = uigridlayout(app.ProblemDeifinitionTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PREPROCESSLabel = uilabel(app.GridLayout);
            app.PREPROCESSLabel.Text = 'PRE - PROCESS';
            app.PROCESSLabel = uilabel(app.GridLayout);
            app.PROCESSLabel.Text = 'PROCESS';
            app.RemoveAllNodesandElementsButton = uibutton(app.GridLayout, 'push');
            app.RemoveAllNodesandElementsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllNodesandElementsButtonPushed, true);
            app.RemoveAllNodesandElementsButton.Text = {'Remove All Nodes '; 'and Elements'};
            app.NodeLabelSwitchLabel = uilabel(app.GridLayout);
            app.NodeLabelSwitchLabel.Text = 'Node Label';
            app.NodeLabelSwitch = uiswitch(app.GridLayout, 'toggle');
            app.NodeLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitchValueChanged, true);
            app.NodeLabelSwitch.Value = 'On';
            app.ElementLabelSwitchLabel = uilabel(app.GridLayout);
            app.ElementLabelSwitchLabel.Text = 'Element Label';
            app.ElementLabelSwitch = uiswitch(app.GridLayout, 'toggle');
            app.ElementLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementLabelSwitchValueChanged, true);
            app.ElementLabelSwitch.Value = 'On';
            app.NodeSizeLabel = uilabel(app.GridLayout);
            app.NodeSizeLabel.Text = 'Node Size';
            app.NodeSizeSlider = uislider(app.GridLayout);
            app.NodeSizeSlider.ValueChangedFcn = createCallbackFcn(app, @NodeSizeSliderValueChanged, true);
            app.NodeSizeSlider.Value = 15;
            app.ElementSizeSliderLabel = uilabel(app.GridLayout);
            app.ElementSizeSliderLabel.Text = 'Element Size';
            app.ElementSizeSlider = uislider(app.GridLayout);
            app.ElementSizeSlider.ValueChangedFcn = createCallbackFcn(app, @ElementSizeSliderValueChanged, true);
            app.ElementSizeSlider.Value = 15;
            app.BoundryConditionPanel = uipanel(app.GridLayout);
            app.BoundryConditionPanel.Title = 'Boundry Condition';
            app.GridLayout3 = uigridlayout(app.BoundryConditionPanel);
            app.BCTypeSwitchLabel = uilabel(app.GridLayout3);
            app.BCTypeSwitchLabel.Text = 'BC Type';
            app.BCTypeSwitch = uiswitch(app.GridLayout3, 'slider');
            app.BCTypeSwitch.Items = {'Force', 'Displacement'};
            app.BCTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @BCTypeSwitchValueChanged, true);
            app.BCTypeSwitch.Value = 'Force';
            app.BCDirectionSwitchLabel = uilabel(app.GridLayout3);
            app.BCDirectionSwitchLabel.Text = 'BC Direction';
            app.BCDirectionSwitch = uiswitch(app.GridLayout3, 'slider');
            app.BCDirectionSwitch.Items = {'X', 'Y'};
            app.BCDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @BCDirectionSwitchValueChanged, true);
            app.BCDirectionSwitch.Value = 'X';
            app.MagnitudeNormEditFieldLabel = uilabel(app.GridLayout3);
            app.MagnitudeNormEditFieldLabel.Text = 'Magnitude (N or m)';
            app.MagnitudeNormEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MagnitudeNormEditField.ValueChangedFcn = createCallbackFcn(app, @MagnitudeNormEditFieldValueChanged, true);
            app.ApplyBoundryConditionButton = uibutton(app.GridLayout3, 'push');
            app.ApplyBoundryConditionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyBoundryConditionButtonPushed, true);
            app.ApplyBoundryConditionButton.Text = {'Apply Boundry '; 'Condition'};
            app.RemoveAllBoundryConditionsButton = uibutton(app.GridLayout3, 'push');
            app.RemoveAllBoundryConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllBoundryConditionsButtonPushed, true);
            app.RemoveAllBoundryConditionsButton.Text = {'Remove All '; 'Boundry Conditions'};
            app.NodeNumberEditFieldLabel = uilabel(app.GridLayout3);
            app.NodeNumberEditFieldLabel.Text = 'Node Number';
            app.NodeNumberEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NodeNumberEditField.ValueChangedFcn = createCallbackFcn(app, @NodeNumberEditFieldValueChanged, true);
            app.MaterialPropertiesPanel = uipanel(app.GridLayout);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.GridLayout4 = uigridlayout(app.MaterialPropertiesPanel);
            app.EPaEditFieldLabel = uilabel(app.GridLayout4);
            app.EPaEditFieldLabel.Text = 'E (Pa)';
            app.EPaEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EPaEditField.ValueChangedFcn = createCallbackFcn(app, @EPaEditFieldValueChanged, true);
            app.EPaEditField.Value = 210000000000;
            app.Am2EditFieldLabel = uilabel(app.GridLayout4);
            app.Am2EditFieldLabel.Text = 'A (m^2)';
            app.Am2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Am2EditField.ValueChangedFcn = createCallbackFcn(app, @Am2EditFieldValueChanged, true);
            app.Am2EditField.Value = 0.0001;
            app.Densitykgm3Label = uilabel(app.GridLayout4);
            app.Densitykgm3Label.Text = 'Density (kg/m^3)';
            app.Densitykgm3EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Densitykgm3EditField.ValueChangedFcn = createCallbackFcn(app, @Densitykgm3EditFieldValueChanged, true);
            app.Densitykgm3EditField.Value = 8000;
            app.ApplyMaterialPropertiesButton = uibutton(app.GridLayout4, 'push');
            app.ApplyMaterialPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMaterialPropertiesButtonPushed, true);
            app.ApplyMaterialPropertiesButton.Text = {'Apply Material'; 'Properties'};
            app.ExtendedNodeListPanel = uipanel(app.GridLayout);
            app.ExtendedNodeListPanel.Title = 'Extended Node List';
            app.GridLayout5 = uigridlayout(app.ExtendedNodeListPanel);
            app.ENLTable = uitable(app.GridLayout5);
            app.ENLTable.ColumnName = {'NL(x)'; 'NL(y)'; 'BC(x)'; 'BC(y)'; 'TD(x)'; 'TD(y)'; 'GD(x)'; 'GD(y)'; 'Disp.(x)'; 'Disp.(y)'; 'Force(x)'; 'Force(y)'};
            app.ENLTable.RowName = {};
            app.SOLVELamp = uilamp(app.GridLayout);
            app.SOLVEButton = uibutton(app.GridLayout, 'push');
            app.SOLVEButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButtonPushed, true);
            app.SOLVEButton.Text = 'SOLVE';
            app.REMOVEALLButton = uibutton(app.GridLayout, 'push');
            app.REMOVEALLButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEALLButtonPushed, true);
            app.REMOVEALLButton.Text = 'REMOVE ALL';
            app.ReadNLandELButton = uibutton(app.GridLayout, 'push');
            app.ReadNLandELButton.ButtonPushedFcn = createCallbackFcn(app, @ReadNLandELButtonPushed, true);
            app.ReadNLandELButton.Text = {'Read NL '; 'and EL'};
            app.NumberofNodesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofNodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNodesEditFieldValueChanged, true);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.Panel);
            app.NodeLabel = uilabel(app.GridLayout7);
            app.NodeLabel.Text = 'Node';
            app.GenerateNodeButton = uibutton(app.GridLayout7, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = {'Generate'; 'Node'};
            app.XEditFieldLabel = uilabel(app.GridLayout7);
            app.XEditFieldLabel.Text = 'X';
            app.X_coor = uieditfield(app.GridLayout7, 'numeric');
            app.YEditFieldLabel = uilabel(app.GridLayout7);
            app.YEditFieldLabel.Text = 'Y';
            app.Y_coor = uieditfield(app.GridLayout7, 'numeric');
            app.ElemetLabel = uilabel(app.GridLayout7);
            app.ElemetLabel.Text = 'Elemet';
            app.GenerateElementButton = uibutton(app.GridLayout7, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = {'Generate'; 'Element'};
            app.FirstNodeLabel = uilabel(app.GridLayout7);
            app.FirstNodeLabel.Text = {'First '; 'Node'};
            app.FirstNode = uieditfield(app.GridLayout7, 'numeric');
            app.SecondNodeLabel = uilabel(app.GridLayout7);
            app.SecondNodeLabel.Text = {'Second'; 'Node'};
            app.SecondNode = uieditfield(app.GridLayout7, 'numeric');
            app.RemoveNodeButton = uibutton(app.GridLayout7, 'push');
            app.RemoveNodeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNodeButtonPushed, true);
            app.RemoveNodeButton.Text = 'Remove Node';
            app.RemoveElemetButton = uibutton(app.GridLayout7, 'push');
            app.RemoveElemetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveElemetButtonPushed, true);
            app.RemoveElemetButton.Text = 'Remove Elemet';
            app.NodeNumberEditField_2Label = uilabel(app.GridLayout7);
            app.NodeNumberEditField_2Label.Text = {'Node'; 'Number'};
            app.RemoveNodeNumber = uieditfield(app.GridLayout7, 'numeric');
            app.ElementNumberEditFieldLabel = uilabel(app.GridLayout7);
            app.ElementNumberEditFieldLabel.Text = {'Element'; 'Number'};
            app.RemoveElementNumber = uieditfield(app.GridLayout7, 'numeric');
            app.RemoveElementNumber.ValueChangedFcn = createCallbackFcn(app, @RemoveElementNumberValueChanged, true);
            app.NumberofElementsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofElementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanged, true);
            app.MassoftheStructurekgLabel = uilabel(app.GridLayout);
            app.MassoftheStructurekgLabel.Text = {'Mass of the Structure '; '(kg)'};
            app.MassoftheStructurekgEditField = uieditfield(app.GridLayout, 'numeric');
            app.MassoftheStructurekgEditField.ValueChangedFcn = createCallbackFcn(app, @MassoftheStructurekgEditFieldValueChanged, true);
            app.AnalysesTab = uitab(app.TabGroup);
            app.AnalysesTab.Title = 'Analyses';
            app.GridLayout2 = uigridlayout(app.AnalysesTab);
            app.UIAxes_stress = uiaxes(app.GridLayout2);
            title(app.UIAxes_stress, 'Stress')
            xlabel(app.UIAxes_stress, 'X')
            ylabel(app.UIAxes_stress, 'Y')
            zlabel(app.UIAxes_stress, 'Z')
            app.UIAxes_displacement = uiaxes(app.GridLayout2);
            title(app.UIAxes_displacement, 'Displacement')
            xlabel(app.UIAxes_displacement, 'X')
            ylabel(app.UIAxes_displacement, 'Y')
            zlabel(app.UIAxes_displacement, 'Z')
            app.ColorMapButtonGroup = uibuttongroup(app.GridLayout2);
            app.ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroupSelectionChanged, true);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.Jet = uitogglebutton(app.ColorMapButtonGroup);
            app.Jet.Icon = 'jet.png';
            app.Jet.Text = 'Jet';
            app.Jet.Value = true;
            app.Bone = uitogglebutton(app.ColorMapButtonGroup);
            app.Bone.Icon = 'bone.png';
            app.Bone.Text = 'Bone';
            app.Parula = uitogglebutton(app.ColorMapButtonGroup);
            app.Parula.Icon = 'rainbow.png';
            app.Parula.Text = 'Parula';
            app.ShowUndeformedStructureLabel = uilabel(app.GridLayout2);
            app.ShowUndeformedStructureLabel.Text = {'Show Undeformed'; 'Structure'};
            app.ShowUndeformedStructureSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ShowUndeformedStructureSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowUndeformedStructureSwitchValueChanged, true);
            app.ShowUndeformedStructureSwitch.Value = 'On';
            app.ShowUndeformedStructureLabel_2 = uilabel(app.GridLayout2);
            app.ShowUndeformedStructureLabel_2.Text = {'Show Undeformed'; 'Structure'};
            app.ShowUndeformedStructureSwitch_2 = uiswitch(app.GridLayout2, 'slider');
            app.ShowUndeformedStructureSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ShowUndeformedStructureSwitch_2ValueChanged, true);
            app.ShowUndeformedStructureSwitch_2.Value = 'On';
            app.MagnifierSliderLabel = uilabel(app.GridLayout2);
            app.MagnifierSliderLabel.Text = 'Magnifier';
            app.MagnifierSlider = uislider(app.GridLayout2);
            app.MagnifierSlider.Limits = [1e-05 100];
            app.MagnifierSlider.ValueChangedFcn = createCallbackFcn(app, @MagnifierSliderValueChanged, true);
            app.MagnifierSlider.ValueChangingFcn = createCallbackFcn(app, @MagnifierSliderValueChanging, true);
            app.MagnifierSlider.Value = 1;
            app.WeightandDesplacementValuesPanel = uipanel(app.GridLayout2);
            app.WeightandDesplacementValuesPanel.Title = 'Weight and Desplacement Values';
            app.GridLayout6 = uigridlayout(app.WeightandDesplacementValuesPanel);
            app.TotalMasskgEditFieldLabel = uilabel(app.GridLayout6);
            app.TotalMasskgEditFieldLabel.Text = 'Total Mass [kg]';
            app.TotalMasskgEditField = uieditfield(app.GridLayout6, 'numeric');
            app.TotalMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @TotalMasskgEditFieldValueChanged, true);
            app.MxkgmmaxcenterEditFieldLabel = uilabel(app.GridLayout6);
            app.MxkgmmaxcenterEditFieldLabel.Text = {'Mx𝛿 [kg.m]'; '(max @ center)'};
            app.MxkgmmaxcenterEditField = uieditfield(app.GridLayout6, 'numeric');
            app.DispacementDropDownLabel = uilabel(app.GridLayout2);
            app.DispacementDropDownLabel.Text = 'Dispacement';
            app.DispacementDropDown = uidropdown(app.GridLayout2);
            app.DispacementDropDown.Items = {'U_x', 'U_y', 'U'};
            app.DispacementDropDown.ValueChangedFcn = createCallbackFcn(app, @DispacementDropDownValueChanged, true);
            app.DispacementDropDown.Value = 'U_x';
            app.POSTPROCESSLabel = uilabel(app.GridLayout2);
            app.POSTPROCESSLabel.Text = 'POST - PROCESS';
            app.REMOVERESULTSButton = uibutton(app.GridLayout2, 'push');
            app.REMOVERESULTSButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVERESULTSButtonPushed, true);
            app.REMOVERESULTSButton.Text = 'REMOVE RESULTS';
            app.VoidStructuresTab = uitab(app.TabGroup);
            app.VoidStructuresTab.Title = 'Void Structures';
            app.GridLayout8 = uigridlayout(app.VoidStructuresTab);
            app.UIAxes_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes_2, 'Structure')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_stress_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes_stress_2, 'Stress')
            xlabel(app.UIAxes_stress_2, 'X')
            ylabel(app.UIAxes_stress_2, 'Y')
            zlabel(app.UIAxes_stress_2, 'Z')
            app.UIAxes_displacement_2 = uiaxes(app.GridLayout8);
            title(app.UIAxes_displacement_2, 'Displacement')
            xlabel(app.UIAxes_displacement_2, 'X')
            ylabel(app.UIAxes_displacement_2, 'Y')
            zlabel(app.UIAxes_displacement_2, 'Z')
            app.Analysisof2DSquaresBasedonTrussStructuresLabel = uilabel(app.GridLayout8);
            app.Analysisof2DSquaresBasedonTrussStructuresLabel.Text = 'Analysis of 2D Squares Based on Truss Structures';
            app.CreatingtheStructurePanel = uipanel(app.GridLayout8);
            app.CreatingtheStructurePanel.Title = 'Creating the Structure';
            app.GridLayout9 = uigridlayout(app.CreatingtheStructurePanel);
            app.RemoveStructureButton = uibutton(app.GridLayout9, 'push');
            app.RemoveStructureButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveStructureButtonPushed, true);
            app.RemoveStructureButton.Text = 'Remove Structure';
            app.NodeLabelSwitch_2Label = uilabel(app.GridLayout9);
            app.NodeLabelSwitch_2Label.Text = {'Node'; 'Label'};
            app.NodeLabelSwitch_2 = uiswitch(app.GridLayout9, 'toggle');
            app.NodeLabelSwitch_2.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitch_2ValueChanged, true);
            app.NodeLabelSwitch_2.Value = 'On';
            app.ElementLabelSwitch_2Label = uilabel(app.GridLayout9);
            app.ElementLabelSwitch_2Label.Text = {'Element'; 'Label'};
            app.ElementLabelSwitch_2 = uiswitch(app.GridLayout9, 'toggle');
            app.ElementLabelSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ElementLabelSwitch_2ValueChanged, true);
            app.ElementLabelSwitch_2.Value = 'On';
            app.NodeSizeSlider_2Label = uilabel(app.GridLayout9);
            app.NodeSizeSlider_2Label.Text = 'Node Size';
            app.NodeSizeSlider_2 = uislider(app.GridLayout9);
            app.NodeSizeSlider_2.ValueChangedFcn = createCallbackFcn(app, @NodeSizeSlider_2ValueChanged, true);
            app.NodeSizeSlider_2.Value = 15;
            app.ElementSizeSlider_2Label = uilabel(app.GridLayout9);
            app.ElementSizeSlider_2Label.Text = 'Element Size';
            app.ElementSizeSlider_2 = uislider(app.GridLayout9);
            app.ElementSizeSlider_2.ValueChangedFcn = createCallbackFcn(app, @ElementSizeSlider_2ValueChanged, true);
            app.ElementSizeSlider_2.Value = 15;
            app.NumberofElementsLabel = uilabel(app.GridLayout9);
            app.NumberofElementsLabel.Text = {'Number of'; 'Elements'};
            app.NumberofElementsEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.NumberofElementsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditField_2ValueChanged, true);
            app.NumberofNodesLabel = uilabel(app.GridLayout9);
            app.NumberofNodesLabel.Text = {'Number of'; 'Nodes'};
            app.NumberofNodesEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.NumberofNodesEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofNodesEditField_2ValueChanged, true);
            app.MaterialPropertiesPanel_2 = uipanel(app.GridLayout8);
            app.MaterialPropertiesPanel_2.Title = 'Material Properties';
            app.GridLayout10 = uigridlayout(app.MaterialPropertiesPanel_2);
            app.ApplyMaterialPropertiesButton_2 = uibutton(app.GridLayout10, 'push');
            app.ApplyMaterialPropertiesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyMaterialPropertiesButton_2Pushed, true);
            app.ApplyMaterialPropertiesButton_2.Text = {'Apply Material'; 'Properties'};
            app.EPaEditField_2Label = uilabel(app.GridLayout10);
            app.EPaEditField_2Label.Text = 'E (Pa)';
            app.EPaEditField_2 = uieditfield(app.GridLayout10, 'numeric');
            app.EPaEditField_2.ValueChangedFcn = createCallbackFcn(app, @EPaEditField_2ValueChanged, true);
            app.EPaEditField_2.Value = 210000000000;
            app.Am2EditField_2Label = uilabel(app.GridLayout10);
            app.Am2EditField_2Label.Text = 'A (m^2)';
            app.Am2EditField_2 = uieditfield(app.GridLayout10, 'numeric');
            app.Am2EditField_2.ValueChangedFcn = createCallbackFcn(app, @Am2EditField_2ValueChanged, true);
            app.Am2EditField_2.Value = 0.0001;
            app.Densitykgm3EditField_2Label = uilabel(app.GridLayout10);
            app.Densitykgm3EditField_2Label.Text = 'Density (kg/m^3)';
            app.Densitykgm3EditField_2 = uieditfield(app.GridLayout10, 'numeric');
            app.Densitykgm3EditField_2.ValueChangedFcn = createCallbackFcn(app, @Densitykgm3EditField_2ValueChanged, true);
            app.Densitykgm3EditField_2.Value = 8000;
            app.BoundryConditionsPanel = uipanel(app.GridLayout8);
            app.BoundryConditionsPanel.Title = 'Boundry Conditions';
            app.GridLayout11 = uigridlayout(app.BoundryConditionsPanel);
            app.ApplyBoundryConditionButton_2 = uibutton(app.GridLayout11, 'push');
            app.ApplyBoundryConditionButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyBoundryConditionButton_2Pushed, true);
            app.ApplyBoundryConditionButton_2.Text = {'Apply Boundry '; 'Condition'};
            app.RemoveAllBoundryConditionsButton_2 = uibutton(app.GridLayout11, 'push');
            app.RemoveAllBoundryConditionsButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllBoundryConditionsButton_2Pushed, true);
            app.RemoveAllBoundryConditionsButton_2.Text = {'Remove All '; 'Boundry Conditions'};
            app.MagnitudeNormEditField_2Label = uilabel(app.GridLayout11);
            app.MagnitudeNormEditField_2Label.Text = 'Magnitude (N or m)';
            app.MagnitudeNormEditField_2 = uieditfield(app.GridLayout11, 'numeric');
            app.MagnitudeNormEditField_2.ValueChangedFcn = createCallbackFcn(app, @MagnitudeNormEditField_2ValueChanged, true);
            app.NodeNumberEditField_2Label_2 = uilabel(app.GridLayout11);
            app.NodeNumberEditField_2Label_2.Text = 'Node Number';
            app.NodeNumberEditField_2 = uieditfield(app.GridLayout11, 'numeric');
            app.NodeNumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @NodeNumberEditField_2ValueChanged, true);
            app.BCTypeSwitch_2Label = uilabel(app.GridLayout11);
            app.BCTypeSwitch_2Label.Text = 'BC Type';
            app.BCTypeSwitch_2 = uiswitch(app.GridLayout11, 'slider');
            app.BCTypeSwitch_2.Items = {'Force', 'Disp.'};
            app.BCTypeSwitch_2.ValueChangedFcn = createCallbackFcn(app, @BCTypeSwitch_2ValueChanged, true);
            app.BCTypeSwitch_2.Value = 'Force';
            app.BCDirectionSwitch_2Label = uilabel(app.GridLayout11);
            app.BCDirectionSwitch_2Label.Text = 'BC Direction';
            app.BCDirectionSwitch_2 = uiswitch(app.GridLayout11, 'slider');
            app.BCDirectionSwitch_2.Items = {'      X', '      Y'};
            app.BCDirectionSwitch_2.ValueChangedFcn = createCallbackFcn(app, @BCDirectionSwitch_2ValueChanged, true);
            app.BCDirectionSwitch_2.Value = '      X';
            app.MassoftheStructurekgEditField_2Label = uilabel(app.GridLayout8);
            app.MassoftheStructurekgEditField_2Label.Text = {'Mass of the Structure '; '(kg)'};
            app.MassoftheStructurekgEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.MassoftheStructurekgEditField_2.ValueChangedFcn = createCallbackFcn(app, @MassoftheStructurekgEditField_2ValueChanged, true);
            app.ReadELNLBCsPanel = uipanel(app.GridLayout8);
            app.ReadELNLBCsPanel.Title = 'Read EL & NL & BCs';
            app.GridLayout12 = uigridlayout(app.ReadELNLBCsPanel);
            app.HoleStructureDropDownLabel = uilabel(app.GridLayout12);
            app.HoleStructureDropDownLabel.Text = 'Hole Structure';
            app.HoleStructureDropDown = uidropdown(app.GridLayout12);
            app.HoleStructureDropDown.Items = {'Select', 'No Hole', 'Square Hole', 'Circle Hole', 'Rhombus Hole'};
            app.HoleStructureDropDown.DropDownOpeningFcn = createCallbackFcn(app, @HoleStructureDropDownOpening, true);
            app.HoleStructureDropDown.Value = 'Select';
            app.MeshDensityDropDownLabel = uilabel(app.GridLayout12);
            app.MeshDensityDropDownLabel.Text = '~Mesh Density';
            app.MeshDensityDropDown = uidropdown(app.GridLayout12);
            app.MeshDensityDropDown.Items = {'Select', '3x4', '5x4', '10x4'};
            app.MeshDensityDropDown.Value = 'Select';
            app.BoundryConditionDropDownLabel = uilabel(app.GridLayout12);
            app.BoundryConditionDropDownLabel.Text = 'Boundry Condition';
            app.BoundryConditionDropDown = uidropdown(app.GridLayout12);
            app.BoundryConditionDropDown.Items = {'Select', 'Uniaxial Extension', 'Shear', 'Pure Expansion'};
            app.BoundryConditionDropDown.Value = 'Select';
            app.ReadDesiredSystemsButton = uibutton(app.GridLayout12, 'push');
            app.ReadDesiredSystemsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDesiredSystemsButtonPushed, true);
            app.ReadDesiredSystemsButton.Text = {'Read Desired'; 'Systems'};
            app.SOLVEButton_2 = uibutton(app.GridLayout8, 'push');
            app.SOLVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButton_2Pushed, true);
            app.SOLVEButton_2.Text = 'SOLVE';
            app.REMOVEALLButton_2 = uibutton(app.GridLayout8, 'push');
            app.REMOVEALLButton_2.ButtonPushedFcn = createCallbackFcn(app, @REMOVEALLButton_2Pushed, true);
            app.REMOVEALLButton_2.Text = 'REMOVE ALL';
            app.ColorMapButtonGroup_2 = uibuttongroup(app.GridLayout8);
            app.ColorMapButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup_2SelectionChanged, true);
            app.ColorMapButtonGroup_2.Title = 'Color Map';
            app.Jet_2 = uitogglebutton(app.ColorMapButtonGroup_2);
            app.Jet_2.Icon = 'jet.png';
            app.Jet_2.Text = 'Jet';
            app.Jet_2.Value = true;
            app.Bone_2 = uitogglebutton(app.ColorMapButtonGroup_2);
            app.Bone_2.Icon = 'bone.png';
            app.Bone_2.Text = 'Bone';
            app.Parula_2 = uitogglebutton(app.ColorMapButtonGroup_2);
            app.Parula_2.Icon = 'rainbow.png';
            app.Parula_2.Text = 'Parula';
            app.MagnifierSlider_2Label = uilabel(app.GridLayout8);
            app.MagnifierSlider_2Label.Text = 'Magnifier';
            app.MagnifierSlider_2 = uislider(app.GridLayout8);
            app.MagnifierSlider_2.Limits = [1e-05 100];
            app.MagnifierSlider_2.ValueChangedFcn = createCallbackFcn(app, @MagnifierSlider_2ValueChanged, true);
            app.MagnifierSlider_2.ValueChangingFcn = createCallbackFcn(app, @MagnifierSlider_2ValueChanging, true);
            app.MagnifierSlider_2.Value = 1;
            app.DispacementDropDown_2Label = uilabel(app.GridLayout8);
            app.DispacementDropDown_2Label.Text = 'Dispacement';
            app.DispacementDropDown_2 = uidropdown(app.GridLayout8);
            app.DispacementDropDown_2.Items = {'U_x', 'U_y', 'U'};
            app.DispacementDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DispacementDropDown_2Opening, true);
            app.DispacementDropDown_2.Value = 'U_x';
            app.StressUndeformedStructureLabel = uilabel(app.GridLayout8);
            app.StressUndeformedStructureLabel.Text = {'Stress'; 'Undeformed'; 'Structure'};
            app.StressUndeformedStructureSwitch = uiswitch(app.GridLayout8, 'slider');
            app.StressUndeformedStructureSwitch.ValueChangedFcn = createCallbackFcn(app, @StressUndeformedStructureSwitchValueChanged, true);
            app.StressUndeformedStructureSwitch.Value = 'On';
            app.DisplacementUndeformedStructureLabel = uilabel(app.GridLayout8);
            app.DisplacementUndeformedStructureLabel.Text = {'Displacement'; 'Undeformed'; 'Structure'};
            app.DisplacementUndeformedStructureSwitch = uiswitch(app.GridLayout8, 'slider');
            app.DisplacementUndeformedStructureSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplacementUndeformedStructureSwitchValueChanged, true);
            app.DisplacementUndeformedStructureSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_gui_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end