classdef GUI_2d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        DFEMforVoidedIncludedSquaresTab  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        RUNButton                      matlab.ui.control.Button
        VoidSwitch                     matlab.ui.control.Switch
        Image4                         matlab.ui.control.Image
        ResultsDropDown                matlab.ui.control.DropDown
        ResultsDropDownLabel           matlab.ui.control.Label
        ShowMeshSwitch                 matlab.ui.control.Switch
        ShowMeshSwitchLabel            matlab.ui.control.Label
        MagnifierEditField             matlab.ui.control.NumericEditField
        MagnifierEditFieldLabel        matlab.ui.control.Label
        ColorMapButtonGroup            matlab.ui.container.ButtonGroup
        BONEButton                     matlab.ui.control.ToggleButton
        RAINBOWButton                  matlab.ui.control.ToggleButton
        JETButton                      matlab.ui.control.ToggleButton
        MaterialPropertiesPanel        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        InclusionLabel                 matlab.ui.control.Label
        NuInclusionEditField           matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        NuMatrixEditField              matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        EInclusionEditField            matlab.ui.control.NumericEditField
        EEditField_2Label              matlab.ui.control.Label
        EMatrixEditField               matlab.ui.control.NumericEditField
        EEditFieldLabel                matlab.ui.control.Label
        MatrixLabel                    matlab.ui.control.Label
        ElementLabelSwitch             matlab.ui.control.ToggleSwitch
        ElementLabelSwitchLabel        matlab.ui.control.Label
        RUNLamp                        matlab.ui.control.Lamp
        NodeLabelSwitch                matlab.ui.control.ToggleSwitch
        NodeLabelSwitchLabel           matlab.ui.control.Label
        PREPROCESSLabel                matlab.ui.control.Label
        PROCESSLabel                   matlab.ui.control.Label
        DeformationMagnitudeEditField  matlab.ui.control.NumericEditField
        DeformationMagnitudeLabel      matlab.ui.control.Label
        POSTPROCESSLabel               matlab.ui.control.Label
        DeformationTypeButtonGroup     matlab.ui.container.ButtonGroup
        Image2_2                       matlab.ui.control.Image
        ShearButton                    matlab.ui.control.RadioButton
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ExpansionButton                matlab.ui.control.RadioButton
        ExtensionButton                matlab.ui.control.RadioButton
        ElementsEditField              matlab.ui.control.NumericEditField
        ElementsEditFieldLabel         matlab.ui.control.Label
        NodesEditField                 matlab.ui.control.NumericEditField
        NodesEditFieldLabel            matlab.ui.control.Label
        ElementTypeDropDown            matlab.ui.control.DropDown
        ElementTypeDropDownLabel       matlab.ui.control.Label
        GenerateMeshButton             matlab.ui.control.Button
        REMOVEMESHButton               matlab.ui.control.Button
        eValue                         matlab.ui.control.NumericEditField
        eEditFieldLabel                matlab.ui.control.Label
        dValue                         matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        cValue                         matlab.ui.control.NumericEditField
        cEditFieldLabel                matlab.ui.control.Label
        bValue                         matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        aEditFieldLabel                matlab.ui.control.Label
        aValue                         matlab.ui.control.NumericEditField
        InclusionTypeDropDown          matlab.ui.control.DropDown
        InclusionTypeDropDownLabel     matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxesSmooth                   matlab.ui.control.UIAxes
        UIAxesPrecise                  matlab.ui.control.UIAxes
        D2DFEMforSquaresTab            matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        ShowMeshSwitch_2Label          matlab.ui.control.Label
        ShowMeshSwitch_2               matlab.ui.control.Switch
        DropDown_2                     matlab.ui.control.DropDown
        DropDown                       matlab.ui.control.DropDown
        PLOTButton                     matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        EPaEditField_2                 matlab.ui.control.NumericEditField
        EPaEditField_2Label            matlab.ui.control.Label
        Am2EditField                   matlab.ui.control.NumericEditField
        Am2EditFieldLabel              matlab.ui.control.Label
        EPaEditField                   matlab.ui.control.NumericEditField
        EPaEditFieldLabel              matlab.ui.control.Label
        BuildingtheStructurePanel      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        VoidType_2                     matlab.ui.control.DropDown
        eEditField                     matlab.ui.control.NumericEditField
        eEditFieldLabel_2              matlab.ui.control.Label
        cEditField                     matlab.ui.control.NumericEditField
        cEditFieldLabel_2              matlab.ui.control.Label
        APPLYButton                    matlab.ui.control.Button
        DeformaitonMagnitudeEditField  matlab.ui.control.NumericEditField
        DeformaitonMagnitudeEditFieldLabel  matlab.ui.control.Label
        DeformationTypeDropDown        matlab.ui.control.DropDown
        ShearLabel                     matlab.ui.control.Label
        ExtensionLabel                 matlab.ui.control.Label
        ExpansionLabel                 matlab.ui.control.Label
        Image8                         matlab.ui.control.Image
        Image7                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        d4Label                        matlab.ui.control.Label
        ab1Label                       matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        DFEANALYSISLabel_2             matlab.ui.control.Label
        DFEANALYSISLabel               matlab.ui.control.Label
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes_displacement_2          matlab.ui.control.UIAxes
        UIAxes8                        matlab.ui.control.UIAxes
        D2DFEMforBeamTab               matlab.ui.container.Tab
        GridLayout4_2                  matlab.ui.container.GridLayout
        ShowMeshSwitch_3Label          matlab.ui.control.Label
        ShowMeshSwitch_3               matlab.ui.control.Switch
        DropDown_4                     matlab.ui.control.DropDown
        DropDown_3                     matlab.ui.control.DropDown
        PLOTButton_2                   matlab.ui.control.Button
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EPaEditField_4                 matlab.ui.control.NumericEditField
        EPaEditField_4Label            matlab.ui.control.Label
        Am2EditField_2                 matlab.ui.control.NumericEditField
        Am2EditField_2Label            matlab.ui.control.Label
        EPaEditField_3                 matlab.ui.control.NumericEditField
        EPaEditField_3Label            matlab.ui.control.Label
        BuildingtheStructurePanel_2    matlab.ui.container.Panel
        GridLayout5_2                  matlab.ui.container.GridLayout
        Image11                        matlab.ui.control.Image
        Image10                        matlab.ui.control.Image
        LoadTypeDropDown               matlab.ui.control.DropDown
        LoadTypeDropDownLabel          matlab.ui.control.Label
        DeformationMagnitudeEditField_2  matlab.ui.control.NumericEditField
        DeformationMagnitudeLabel_2    matlab.ui.control.Label
        mEditField                     matlab.ui.control.NumericEditField
        mEditFieldLabel                matlab.ui.control.Label
        pEditField                     matlab.ui.control.NumericEditField
        pEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel_2              matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel_2              matlab.ui.control.Label
        Image9                         matlab.ui.control.Image
        APPLYButton_2                  matlab.ui.control.Button
        DFEANALYSISLabel_4             matlab.ui.control.Label
        DFEANALYSISLabel_3             matlab.ui.control.Label
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes5_2                      matlab.ui.control.UIAxes
        UIAxes_displacement_3          matlab.ui.control.UIAxes
        UIAxes8_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        initialfigure = "Circle"
        d1 = 1
        d2 = 1
        p = 3
        m = 3
        R = 0.2
        EL
        NL
        NoN
        NoE
        NPE
        PD
        ENL
        DOFs
        DOCs
        NodeSwitchLabel = "Off"
        ElementSwitchLabel = "Off"
        inclusion = 1 %default inclusion
        EMatrix = 1
        EInclusion = 10
        nuMatrix = 0.3
        nuInclusion = 0.3
        E = 1
        nu = 0.3
        Magnitude = 0.1
        BC_Type = "Extension"
        plottype = "stress_xx"
        magnifier = 100


        % 1d parameters %
        A = 0.01
        E_1d = 1          
        NL_2
        EL_2
        NoE_2
        NoN_2
        BCs_2
        ENL_2
        DOFs_2
        DOCs_2
        colorMap = jet
        mag_2 = 2
        disp_direction_2 = "U_x"
        plottype_2 ="disp_x"

        %%%% 2.3 parametreleri %%%

        d1_z = 10
        d2_z = 2
        p_z = 5
        m_z = 4
        E_z2 = 1
        E_z1 = 1
        A_z = 0.01
        nu_z = 0.3
        BC_Type_z = "x_loading"
        Magnitude_z = 0.5
        disp_direction_z = "U_x"
        plottype_z = "disp_x"

        %%% 2d parameters %%%    
        NL_3
        EL_3
        NoE_3
        NoN_3
        BCs_3
        ENL_3
        DOFs_3
        DOCs_3
        NPE_3

        %%% 1d parameters %%%
        NL_4
        EL_4
        NoE_4
        NoN_4
        BCs_4
        ENL_4
        DOFs_4
        DOCs_4
        NPE_4

     
    end
    
    methods (Access = private)
        
        function results = updateAxes(app)

            cla(app.UIAxes2);
            hold(app.UIAxes2,"on");
            grid(app.UIAxes2,"on");
            axis(app.UIAxes2, "equal");

            app.NoN = size(app.NL,1);
            app.NoE = size(app.EL,1);
            app.NPE = size(app.EL,2); 
                
            if app.ElementTypeDropDown.Value == "D2TR3N"
                app.NL;
                app.EL;
                    for i = 1:app.NoE
                        hold(app.UIAxes2,"on");
                        plot(app.UIAxes2,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'m');
                        plot(app.UIAxes2,[app.NL(app.EL(i,2),1), app.NL(app.EL(i,3),1)], [app.NL(app.EL(i,2),2), app.NL(app.EL(i,3),2)],'m');
                        plot(app.UIAxes2,[app.NL(app.EL(i,3),1), app.NL(app.EL(i,1),1)], [app.NL(app.EL(i,3),2), app.NL(app.EL(i,1),2)],'m');
                        x = (app.NL(app.EL(i,1),1) + app.NL(app.EL(i,2),1) + app.NL(app.EL(i,3),1))/3;
                        y = (app.NL(app.EL(i,1),2) + app.NL(app.EL(i,2),2) + app.NL(app.EL(i,3),2))/3;
                        switch app.ElementSwitchLabel
                            case "On"
                                text(app.UIAxes2,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                    end

                    for i = 1:app.NoN
                        hold(app.UIAxes2,"on");
                        switch app.NodeSwitchLabel
                            case "On"
                                plot(app.UIAxes2,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                                text(app.UIAxes2,app.NL(i,1),app.NL(i,2), num2str(i), 'Color','w','FontSize',9,'HorizontalAlignment','center');
                        end
                    end
                                        
            elseif app.ElementTypeDropDown.Value == "D2QU4N"

                    for i = 1:app.NoE
                        hold(app.UIAxes2,"on");
                        plot(app.UIAxes2,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'m');
                        plot(app.UIAxes2,[app.NL(app.EL(i,2),1), app.NL(app.EL(i,3),1)], [app.NL(app.EL(i,2),2), app.NL(app.EL(i,3),2)],'m');
                        plot(app.UIAxes2,[app.NL(app.EL(i,3),1), app.NL(app.EL(i,4),1)], [app.NL(app.EL(i,3),2), app.NL(app.EL(i,4),2)],'m');
                        plot(app.UIAxes2,[app.NL(app.EL(i,4),1), app.NL(app.EL(i,1),1)], [app.NL(app.EL(i,4),2), app.NL(app.EL(i,1),2)],'m');
                        x = (app.NL(app.EL(i,1),1) + app.NL(app.EL(i,2),1) + app.NL(app.EL(i,3),1) + app.NL(app.EL(i,4),1))/4;
                        y = (app.NL(app.EL(i,1),2) + app.NL(app.EL(i,2),2) + app.NL(app.EL(i,3),2) + app.NL(app.EL(i,4),2))/4;
                         switch app.ElementSwitchLabel
                            case "On"
                            text(app.UIAxes2,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                    end

                    for i = 1:app.NoN
                        hold(app.UIAxes2,"on");
                        switch app.NodeSwitchLabel
                            case "On"
                            plot(app.UIAxes2,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes2,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',9,'HorizontalAlignment','center')
                        end
                    end
            elseif app.ElementTypeDropDown.Value == "D2TR6N"
                app.NL;
                app.EL;
                for i = 1:app.NoE
                    hold(app.UIAxes2,"on");
                    plot(app.UIAxes2,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,2),1), app.NL(app.EL(i,3),1)], [app.NL(app.EL(i,2),2), app.NL(app.EL(i,3),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,3),1), app.NL(app.EL(i,6),1)], [app.NL(app.EL(i,3),2), app.NL(app.EL(i,6),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,6),1), app.NL(app.EL(i,1),1)], [app.NL(app.EL(i,6),2), app.NL(app.EL(i,1),2)],'m');
                    x = (app.NL(app.EL(i,1),1) + app.NL(app.EL(i,2),1) + app.NL(app.EL(i,3),1))/3;
                    y = (app.NL(app.EL(i,1),2) + app.NL(app.EL(i,2),2) + app.NL(app.EL(i,3),2))/3;
                    switch app.ElementSwitchLabel
                        case "On"
                            text(app.UIAxes2,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                    end
                end

                for i = 1:app.NoN
                    hold(app.UIAxes2,"on");
                    switch app.NodeSwitchLabel
                        case "On"
                            plot(app.UIAxes2,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes2,app.NL(i,1),app.NL(i,2), num2str(i), 'Color','w','FontSize',9,'HorizontalAlignment','center');
                    end
                end
            elseif app.ElementTypeDropDown.Value == "D2QU8N"
                app.NL;
                app.EL;
                for i = 1:app.NoE
                    hold(app.UIAxes2,"on");
                    plot(app.UIAxes2,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,2),1), app.NL(app.EL(i,3),1)], [app.NL(app.EL(i,2),2), app.NL(app.EL(i,3),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,3),1), app.NL(app.EL(i,4),1)], [app.NL(app.EL(i,3),2), app.NL(app.EL(i,4),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,4),1), app.NL(app.EL(i,1),1)], [app.NL(app.EL(i,4),2), app.NL(app.EL(i,1),2)],'m');
                    x = (app.NL(app.EL(i,1),1) + app.NL(app.EL(i,2),1) + app.NL(app.EL(i,3),1) + app.NL(app.EL(i,4),1))/4;
                    y = (app.NL(app.EL(i,1),2) + app.NL(app.EL(i,2),2) + app.NL(app.EL(i,3),2) + app.NL(app.EL(i,4),2))/4;
                     switch app.ElementSwitchLabel
                        case "On"
                        text(app.UIAxes2,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                    end
                end

                for i = 1:app.NoN
                    hold(app.UIAxes2,"on");
                    switch app.NodeSwitchLabel
                        case "On"
                        plot(app.UIAxes2,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                        text(app.UIAxes2,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',9,'HorizontalAlignment','center')
                    end
                end

            elseif app.ElementTypeDropDown.Value == "D2QU9N"
                app.NL;
                app.EL;
                for i = 1:app.NoE
                    hold(app.UIAxes2,"on");
                    plot(app.UIAxes2,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,2),1), app.NL(app.EL(i,3),1)], [app.NL(app.EL(i,2),2), app.NL(app.EL(i,3),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,3),1), app.NL(app.EL(i,4),1)], [app.NL(app.EL(i,3),2), app.NL(app.EL(i,4),2)],'m');
                    plot(app.UIAxes2,[app.NL(app.EL(i,4),1), app.NL(app.EL(i,1),1)], [app.NL(app.EL(i,4),2), app.NL(app.EL(i,1),2)],'m');
                    x = (app.NL(app.EL(i,1),1) + app.NL(app.EL(i,3),1) + app.NL(app.EL(i,5),1) + app.NL(app.EL(i,7),1))/4;
                    y = (app.NL(app.EL(i,1),2) + app.NL(app.EL(i,3),2) + app.NL(app.EL(i,5),2) + app.NL(app.EL(i,7),2))/4;
                     switch app.ElementSwitchLabel
                        case "On"
                        text(app.UIAxes2,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                    end
                end

                for i = 1:app.NoN
                    hold(app.UIAxes2,"on");
                    switch app.NodeSwitchLabel
                        case "On"
                        plot(app.UIAxes2,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                        text(app.UIAxes2,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',9,'HorizontalAlignment','center')
                    end
                end
            end

            app.NodesEditField.Value = app.NoN;
            app.ElementsEditField.Value = app.NoE;
 
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function results = process(app)
            [app.ENL, app.DOFs, app.DOCs] = assign_BCs(app.NL, app.BC_Type,app.Magnitude, app.d1, app.d2);            

            K = assemble_stiffness(app.ENL, app.EL, app.NL, app.E, app.nu);

            Fp = assemble_forces(app.ENL, app.NL);

            Up = assemble_displacements(app.ENL, app.NL);

            Kpu = K(1:app.DOFs,1:app.DOFs);
            Kpp = K(1:app.DOFs,app.DOFs+1:app.DOFs+app.DOCs);
            Kuu = K(app.DOFs+1:app.DOCs+app.DOFs,1:app.DOFs);
            Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);

            F = Fp - Kpp * Up;

            Uu = inv(Kpu)*F;

            Fu = Kuu*Uu + Kup*Up;

            app.ENL = update_nodes(app.ENL,Uu,app.NL,Fu); 
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function comparison = updateaxes_2d(app)

                app.NoN = size(app.NL,1);
                app.NoE = size(app.EL,1);
                app.NPE = size(app.EL,2);
                for i = 1:app.NoE
                        hold(app.UIAxes5,"on");
                        plot(app.UIAxes5,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'m');
                        plot(app.UIAxes5,[app.NL(app.EL(i,2),1), app.NL(app.EL(i,3),1)], [app.NL(app.EL(i,2),2), app.NL(app.EL(i,3),2)],'m');
                        plot(app.UIAxes5,[app.NL(app.EL(i,3),1), app.NL(app.EL(i,1),1)], [app.NL(app.EL(i,3),2), app.NL(app.EL(i,1),2)],'m');
                        x = (app.NL(app.EL(i,1),1) + app.NL(app.EL(i,2),1) + app.NL(app.EL(i,3),1))/3;
                        y = (app.NL(app.EL(i,1),2) + app.NL(app.EL(i,2),2) + app.NL(app.EL(i,3),2))/3;
                end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function voids = updateAxes_1d(app)

            cla(app.UIAxes_4);
            hold(app.UIAxes_4,"on");
            grid(app.UIAxes_4,"on");


            for i = 1 : app.NoE_2

                H = plot(app.UIAxes_4, [app.NL_2(app.EL_2(i,1),1), app.NL_2(app.EL_2(i,2),1)], [app.NL_2(app.EL_2(i,1),2), app.NL_2(app.EL_2(i,2),2)],'LineWidth' ,5,'Color', 'k');
                H.Color(4)=0.2;

            end
            for i = 1:app.NoN_2
                
                    plot(app.UIAxes_4, app.NL_2(i,1), app.NL_2(i,2), 'o', 'MarkerSize', 10, 'MarkerEdgeColor','k','MarkerFaceColor','r')
             end
           
            hold(app.UIAxes_4, "off");
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function compare1 = oned_process(app)
            [app.ENL_2 , app.DOFs_2, app.DOCs_2] = assign_BCs(app.NL_2, app.BC_Type, app.Magnitude, 1, 1);
        
            format long;
        
            K_2  = assemble_stiffness_1(app.ENL_2,app.EL_2,app.NL_2,app.E_1d,app.A);
            Fp_2 = assemble_forces_1(app.ENL_2, app.NL_2);
            Up_2 = assemble_displacement_1(app.ENL_2,app.NL_2);
        
            %assemble forces and displacements
            Kpu_2= K_2(1:app.DOFs_2,1:app.DOFs_2); %DOFxDOF
            Kpp_2= K_2(1:app.DOFs_2, app.DOFs_2+1:app.DOFs_2+app.DOCs_2); %DOFxDOC
            Kuu_2= K_2(app.DOFs_2+1:app.DOCs_2+app.DOFs_2,1:app.DOFs_2);% DOCxDOF
            Kup_2= K_2(app.DOFs_2+1:app.DOCs_2+app.DOFs_2,app.DOFs_2+1:app.DOCs_2+app.DOFs_2);%DOCxDOC
        
            F_2 = Fp_2 - Kpp_2 * Up_2;
        
            Uu_2= inv(Kpu_2)*F_2;
        
            Fu_2=Kuu_2*Uu_2+Kup_2*Up_2;
        
            app.ENL_2 = update_nodes_1(app.ENL_2,Uu_2,app.NL_2,Fu_2);
        
        
            %%%%%%%%%%% 1D POST PROCESS %%%%%%%%%%%%
        
        
            for i=1:app.NoE_2
                x = [app.ENL_2(app.EL_2(i,1),1)+app.mag_2*app.ENL_2(app.EL_2(i,1),9) app.ENL_2(app.EL_2(i,2),1)+app.mag_2*app.ENL_2(app.EL_2(i,2),9)];
                %x starting coord+displacement added with magnification factor, x ending coord
                y = [app.ENL_2(app.EL_2(i,1),2)+app.mag_2*app.ENL_2(app.EL_2(i,1),10) app.ENL_2(app.EL_2(i,2),2)+app.mag_2*app.ENL_2(app.EL_2(i,2),10)];
                %y starting coord, y ending coord
                z = zeros(size(x)); %z value is needed to plot surface
                sigma_2 = app.E_1d * (sqrt((app.ENL_2(app.EL_2(i,1),9)-app.ENL_2(app.EL_2(i,2),9))^2 + (app.ENL_2(app.EL_2(i,1),10)-app.ENL_2(app.EL_2(i,2),10))^2)/(sqrt((app.ENL_2(app.EL_2(i,1),1)-app.ENL_2(app.EL_2(i,2),1))^2 + (app.ENL_2(app.EL_2(i,1),2)-app.ENL_2(app.EL_2(i,2),2))^2)));
                % (length change over original length)
                %E* sqrt((xdisp1-xdisp2)^2+(ydisp1-ydisp2)^2) / ((xoriginal1-xoriginal2)^2 + (yoriginal1-yoriginal2)^2)
        
        
                if app.disp_direction_2 == "U_x"
                    dispx1 = abs(app.ENL_2(app.EL_2(i,1),9));
                    dispx2 = abs(app.ENL_2(app.EL_2(i,2),9));
                    col_dispx = [dispx1 dispx2];
                    surface(app.UIAxes_displacement_2, [x;x],[y;y],[z;z], [col_dispx;col_dispx], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction_2 == "U_y"
                    dispy1 = abs(app.ENL_2(app.EL_2(i,1),10));
                    dispy2 = abs(app.ENL_2(app.EL_2(i,2),10));
                    col_dispy = [dispy1 dispy2];
                    surface(app.UIAxes_displacement_2, [x;x],[y;y],[z;z], [col_dispy;col_dispy], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction_2 == "U"
                    alldisp1 = sqrt(app.ENL_2(app.EL_2(i,1),9)^2 + app.ENL_2(app.EL_2(i,1),10)^2);
                    alldisp2 = sqrt(app.ENL_2(app.EL_2(i,2),9)^2 + app.ENL_2(app.EL_2(i,2),10)^2);
                    col_alldisp = [alldisp1 alldisp2];
                    surface(app.UIAxes_displacement_2, [x;x],[y;y],[z;z], [col_alldisp;col_alldisp], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction_2 == "Stress"
                    col_stress_2 = [sigma_2 sigma_2]; %stress does not vary inside the element
                    surface(app.UIAxes_displacement_2, [x;x],[y;y],[z;z], [col_stress_2;col_stress_2], 'facecol','no','edgecol','interp','linew',4);
                end
        
            end
        
        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function compare2 = twod_process(app)
                app.d1 = 1;
                app.d2 = 1;
                app.m = 4;
                

                [app.ENL, app.DOFs, app.DOCs] = assign_BCs(app.NL, app.BC_Type,app.Magnitude, app.d1, app.d2);            

                K = assemble_stiffness(app.ENL, app.EL, app.NL, app.E, app.nu);

                Fp = assemble_forces(app.ENL, app.NL);

                Up = assemble_displacements(app.ENL, app.NL);
    
                Kpu = K(1:app.DOFs,1:app.DOFs);
                Kpp = K(1:app.DOFs,app.DOFs+1:app.DOFs+app.DOCs);
                Kuu = K(app.DOFs+1:app.DOCs+app.DOFs,1:app.DOFs);
                Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);
    
                F = Fp - Kpp * Up;
    
                Uu = inv(Kpu)*F;
    
                Fu = Kuu*Uu + Kup*Up;
    
                app.ENL = update_nodes(app.ENL,Uu,app.NL,Fu); 

        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function comparison = updateaxes_2d_2(app)

                app.NoN_3 = size(app.NL_3,1);
                app.NoE_3 = size(app.EL_3,1);
                app.NPE_3 = size(app.EL_3,2);

                for i = 1:app.NoE_3
                        hold(app.UIAxes5_2,"on");
                        plot(app.UIAxes5_2,[app.NL_3(app.EL_3(i,1),1), app.NL_3(app.EL_3(i,2),1)], [app.NL_3(app.EL_3(i,1),2), app.NL_3(app.EL_3(i,2),2)],'m');
                        plot(app.UIAxes5_2,[app.NL_3(app.EL_3(i,2),1), app.NL_3(app.EL_3(i,3),1)], [app.NL_3(app.EL_3(i,2),2), app.NL_3(app.EL_3(i,3),2)],'m');
                        plot(app.UIAxes5_2,[app.NL_3(app.EL_3(i,3),1), app.NL_3(app.EL_3(i,1),1)], [app.NL_3(app.EL_3(i,3),2), app.NL_3(app.EL_3(i,1),2)],'m');
                       
                end
                        
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
         function voids = updateAxes_1d_2(app)

            cla(app.UIAxes_5);
            hold(app.UIAxes_5,"on");
            grid(app.UIAxes_5,"on");


            for i = 1 : app.NoE_4

                H = plot(app.UIAxes_5, [app.NL_4(app.EL_4(i,1),1), app.NL_4(app.EL_4(i,2),1)], [app.NL_4(app.EL_4(i,1),2), app.NL_4(app.EL_4(i,2),2)],'LineWidth' ,5,'Color', 'k');
                H.Color(4)=0.2;

            end
            for i = 1:app.NoN_4
                
                    plot(app.UIAxes_5, app.NL_4(i,1), app.NL_4(i,2), 'o', 'MarkerSize', 10, 'MarkerEdgeColor','k','MarkerFaceColor','r')
             end
           
            hold(app.UIAxes_5, "off");
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        function compare1 = oned_process_2(app)

            [app.ENL_4 , app.DOFs_4, app.DOCs_4] = assign_BCs_beam(app.NL_4, app.BC_Type_z, app.Magnitude_z, app.d1_z, app.d2_z);
        
            format long;
        
            K_4  = assemble_stiffness_1(app.ENL_4,app.EL_4,app.NL_4,app.E_z1,app.A_z);
            Fp_4 = assemble_forces_1(app.ENL_4, app.NL_4);
            Up_4 = assemble_displacement_1(app.ENL_4,app.NL_4);
        
            %assemble forces and displacements
            Kpu_4= K_4(1:app.DOFs_4,1:app.DOFs_4); %DOFxDOF
            Kpp_4= K_4(1:app.DOFs_4, app.DOFs_4+1:app.DOFs_4+app.DOCs_4); %DOFxDOC
            Kuu_4= K_4(app.DOFs_4+1:app.DOCs_4+app.DOFs_4,1:app.DOFs_4);% DOCxDOF
            Kup_4= K_4(app.DOFs_4+1:app.DOCs_4+app.DOFs_4,app.DOFs_4+1:app.DOCs_4+app.DOFs_4);%DOCxDOC
        
            F_4 = Fp_4 - Kpp_4 * Up_4;
        
            Uu_4= inv(Kpu_4)*F_4;
        
            Fu_4=Kuu_4*Uu_4+Kup_4*Up_4;
        
            app.ENL_4 = update_nodes_1(app.ENL_4,Uu_4,app.NL_4,Fu_4);
        
        
            %%%%%%%%%%% 1D POST PROCESS %%%%%%%%%%%%
        
        
            for i=1:app.NoE_4
                x = [app.ENL_4(app.EL_4(i,1),1)+app.mag_2*app.ENL_4(app.EL_4(i,1),9) app.ENL_4(app.EL_4(i,2),1)+app.mag_2*app.ENL_4(app.EL_4(i,2),9)];
                %x starting coord+displacement added with magnification factor, x ending coord
                y = [app.ENL_4(app.EL_4(i,1),2)+app.mag_2*app.ENL_4(app.EL_4(i,1),10) app.ENL_4(app.EL_4(i,2),2)+app.mag_2*app.ENL_4(app.EL_4(i,2),10)];
                %y starting coord, y ending coord
                z = zeros(size(x)); %z value is needed to plot surface
                sigma_4 = app.E_z1 * (sqrt((app.ENL_4(app.EL_4(i,1),9)-app.ENL_4(app.EL_4(i,2),9))^2 + (app.ENL_4(app.EL_4(i,1),10)-app.ENL_4(app.EL_4(i,2),10))^2)/(sqrt((app.ENL_4(app.EL_4(i,1),1)-app.ENL_4(app.EL_4(i,2),1))^2 + (app.ENL_4(app.EL_4(i,1),2)-app.ENL_4(app.EL_4(i,2),2))^2)));
                % (length change over original length)
                %E* sqrt((xdisp1-xdisp2)^2+(ydisp1-ydisp2)^2) / ((xoriginal1-xoriginal2)^2 + (yoriginal1-yoriginal2)^2)
        
        
                if app.disp_direction_z == "U_x"
                    dispx1 = abs(app.ENL_4(app.EL_4(i,1),9));
                    dispx2 = abs(app.ENL_4(app.EL_4(i,2),9));
                    col_dispx = [dispx1 dispx2];
                    surface(app.UIAxes_displacement_3, [x;x],[y;y],[z;z], [col_dispx;col_dispx], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction_z == "U_y"
                    dispy1 = abs(app.ENL_4(app.EL_4(i,1),10));
                    dispy2 = abs(app.ENL_4(app.EL_4(i,2),10));
                    col_dispy = [dispy1 dispy2];
                    surface(app.UIAxes_displacement_3, [x;x],[y;y],[z;z], [col_dispy;col_dispy], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction_z == "U"
                    alldisp1 = sqrt(app.ENL_4(app.EL_4(i,1),9)^2 + app.ENL_4(app.EL_4(i,1),10)^2);
                    alldisp2 = sqrt(app.ENL_4(app.EL_4(i,2),9)^2 + app.ENL_4(app.EL_4(i,2),10)^2);
                    col_alldisp = [alldisp1 alldisp2];
                    surface(app.UIAxes_displacement_3, [x;x],[y;y],[z;z], [col_alldisp;col_alldisp], 'facecol','no','edgecol','interp','linew',4);
                elseif app.disp_direction_z == "Stress"
                    col_stress_2 = [sigma_4 sigma_4]; %stress does not vary inside the element
                    surface(app.UIAxes_displacement_3, [x;x],[y;y],[z;z], [col_stress_2;col_stress_2], 'facecol','no','edgecol','interp','linew',4);
                end
        
            end
        
        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   

        function compare2 = twod_process_2(app)


                [app.ENL_3, app.DOFs_3, app.DOCs_3] = assign_BCs_beam(app.NL_3, app.BC_Type_z,app.Magnitude_z, app.d1_z, app.d2_z);            

                K_3 = assemble_stiffness(app.ENL_3, app.EL_3, app.NL_3, app.E_z2, app.nu_z);

                Fp_3 = assemble_forces(app.ENL_3, app.NL_3);

                Up_3 = assemble_displacements(app.ENL_3, app.NL_3);
    
                Kpu_3 = K_3(1:app.DOFs_3,1:app.DOFs_3);
                Kpp_3 = K_3(1:app.DOFs_3,app.DOFs_3+1:app.DOFs_3+app.DOCs_3);
                Kuu_3 = K_3(app.DOFs_3+1:app.DOCs_3+app.DOFs_3,1:app.DOFs_3);
                Kup_3 = K_3(app.DOFs_3+1:app.DOCs_3+app.DOFs_3,app.DOFs_3+1:app.DOCs_3+app.DOFs_3);
    
                F_3 = Fp_3 - Kpp_3 * Up_3;
    
                Uu_3 = inv(Kpu_3)*F_3;
    
                Fu_3 = Kuu_3*Uu_3 + Kup_3*Up_3;
    
                app.ENL_3 = update_nodes(app.ENL_3,Uu_3,app.NL_3,Fu_3); 

        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InclusionTypeDropDown
        function InclusionTypeDropDownValueChanged(app, event)
            value = app.InclusionTypeDropDown.Value;
            if value == "Circle"
                app.Image4.ImageSource = imread("Circle.png");
            elseif value == "Square"
                app.Image4.ImageSource = imread("Square.png");
            else 
                app.Image4.ImageSource = imread("Rhombus.png");
            end
        end

        % Value changed function: aValue
        function aValueValueChanged(app, event)
            value = app.aValue.Value;
            app.d1 = value;
        end

        % Value changed function: bValue
        function bValueValueChanged(app, event)
            value = app.bValue.Value;
            app.d2 = value;
        end

        % Value changed function: cValue
        function cValueValueChanged(app, event)
            value = app.cValue.Value;
            app.p = value;
        end

        % Value changed function: dValue
        function dValueValueChanged(app, event)
            value = app.dValue.Value;
            app.m = value;
        end

        % Value changed function: eValue
        function eValueValueChanged(app, event)
            value = app.eValue.Value;
            app.R = value;
        end

        % Button pushed function: GenerateMeshButton
        function GenerateMeshButtonPushed(app, event)
           if app.InclusionTypeDropDown.Value == "Circle"

               if app.ElementTypeDropDown.Value == "D2TR3N"
                   [app.NL, app.EL] = circle_D2TR3N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU4N"
                   [app.NL, app.EL] = circle_D2QU4N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2TR6N"
                   [app.NL, app.EL] = circle_D2TR6N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU8N"
                   [app.NL, app.EL] = circle_D2QU8N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU9N"
                   [app.NL, app.EL] = circle_D2QU9N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               end

           elseif app.InclusionTypeDropDown.Value == "Square"

               if app.ElementTypeDropDown.Value == "D2TR3N"
                   [app.NL, app.EL] = square_D2TR3N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU4N"
                   [app.NL, app.EL] = square_D2QU4N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2TR6N"
                   [app.NL, app.EL] = square_D2TR6N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU8N"
                   [app.NL, app.EL] = square_D2QU8N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU9N"
                   [app.NL, app.EL] = square_D2QU9N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               end

           elseif app.InclusionTypeDropDown.Value == "Rhombus"

               if app.ElementTypeDropDown.Value == "D2TR3N"
                   [app.NL, app.EL] = rhombus_D2TR3N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU4N"
                   [app.NL, app.EL] = rhombus_D2QU4N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2TR6N"
                   [app.NL, app.EL] = rhombus_D2TR6N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU8N"
                   [app.NL, app.EL] = rhombus_D2QU8N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               elseif app.ElementTypeDropDown.Value == "D2QU9N"
                   [app.NL, app.EL] = rhombus_D2QU9N(app.d1, app.d2, app.p, app.m, app.R, app.inclusion);
               end

           end

           app.NL;
           app.EL;
           updateAxes(app)
        end

        % Value changed function: NodesEditField
        function NodesEditFieldValueChanged(app, event)
            value = app.NodesEditField.Value;
            updateAxes(app)
        end

        % Value changed function: ElementsEditField
        function ElementsEditFieldValueChanged(app, event)
            value = app.ElementsEditField.Value;
            updateAxes(app)
        end

        % Button pushed function: REMOVEMESHButton
        function REMOVEMESHButtonPushed(app, event)
             app.NL = []; 
             app.EL = [];          
             app.NoN = 0;
             app.NoE = 0;          
             updateAxes(app); 
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            %
        end

        % Value changed function: VoidSwitch
        function VoidSwitchValueChanged(app, event)
            value = app.VoidSwitch.Value;
            if (app.VoidSwitch.Value == "Void")
                app.inclusion = 0;
                %app.HiddenL.Value = 0;
                app.EInclusionEditField.Editable = false;
                app.EInclusionEditField.Enable = 'off';
                app.NuInclusionEditField.Editable = false;
                app.NuInclusionEditField.Enable = 'off';
            elseif (app.VoidSwitch.Value == "Inclusion")
                %app.HiddenL.Value = 1;
                app.inclusion = 1;
                app.EInclusionEditField.Editable = true;
                app.EInclusionEditField.Enable = 'on';
                app.NuInclusionEditField.Editable = true;
                app.NuInclusionEditField.Enable = 'on';
            end            
        end

        % Value changed function: ElementLabelSwitch
        function ElementLabelSwitchValueChanged(app, event)
            value = app.ElementLabelSwitch.Value;
            app.ElementSwitchLabel = value;
            updateAxes(app);
        end

        % Value changed function: NodeLabelSwitch
        function NodeLabelSwitchValueChanged(app, event)
            value = app.NodeLabelSwitch.Value;
            app.NodeSwitchLabel = value;
            updateAxes(app);
        end

        % Value changed function: EMatrixEditField
        function EMatrixEditFieldValueChanged(app, event)
            value = app.EMatrixEditField.Value;
            app.EMatrix = value;
        end

        % Value changed function: EInclusionEditField
        function EInclusionEditFieldValueChanged(app, event)
            value = app.EInclusionEditField.Value;
            app.EInclusion = value;
        end

        % Value changed function: NuMatrixEditField
        function NuMatrixEditFieldValueChanged(app, event)
            value = app.NuMatrixEditField.Value;
            app.nuMatrix=value;
        end

        % Value changed function: NuInclusionEditField
        function NuInclusionEditFieldValueChanged(app, event)
            value = app.NuInclusionEditField.Value;
            app.nuInclusion = value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

                app.E = app.EMatrix;
                app.nu= app.nuMatrix;
                process(app)
    
                app.PD = size(app.NL,2);
                app.NPE = size(app.EL,2);
    
                app.NoN = size(app.ENL,1);
                app.NoE = size(app.EL,1);
    
                scale = app.magnifier/100; % Magnifies deformation
                for i = 1: app.NoE
                    if i > 4*app.p*app.m
                       app.E = app.EInclusion;
                       app.nu= app.nuInclusion;
                       [disp, stress, strain] = element_post_process(app.NL,app.EL,app.ENL, app.E, app.nu);
                    else
                       app.E = app.EMatrix;
                       app.nu= app.nuMatrix;
                       [disp, stress, strain] = element_post_process(app.NL,app.EL,app.ENL, app.E, app.nu);
                    end
                end
            switch app.NPE
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 3
                    for i = 1:app.NoE
                        nl = app.EL(i,1:app.NPE); % node numbers connected to element
                        for j = 1:app.NPE
                            X(j,i) = app.ENL(nl(j),1) + scale*app.ENL(nl(j),4*app.PD+1);
                            Y(j,i) = app.ENL(nl(j),2) + scale*app.ENL(nl(j),4*app.PD+2);
                        end
                        for j = 1:app.NPE
                            stress_xx(j,i) = stress(i,:,1,1);
                        end
                        for j = 1:app.NPE
                            stress_xy(j,i) = stress(i,:,1,2);
                        end
                        for j = 1:app.NPE
                            stress_yx(j,i) = stress(i,:,2,1);
                        end
                        for j = 1:app.NPE
                            stress_yy(j,i) = stress(i,:,2,2);
                        end
                        for j = 1:app.NPE
                            strain_xx(j,i) = stress(i,:,1,1);
                        end
                        for j = 1:app.NPE
                            strain_xy(j,i) = stress(i,:,1,2);
                        end
                        for j = 1:app.NPE
                            strain_yx(j,i) = stress(i,:,2,1);
                        end
                        for j = 1:app.NPE
                            strain_yy(j,i) = stress(i,:,2,2);
                        end
                        for j = 1:app.NPE
                            disp_x(j,i) = disp(i,j,1,1);
                        end
                        for j = 1:app.NPE
                            disp_y(j,i) = disp(i,j,2,1);
                        end
                    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 4
                    for i = 1:app.NoE
                        nl = app.EL(i,1:app.NPE); % node numbers connected to element
                        for j = 1:app.NPE
                            X(j,i) = app.ENL(nl(j),1) + scale*app.ENL(nl(j),4*app.PD+1);
                            Y(j,i) = app.ENL(nl(j),2) + scale*app.ENL(nl(j),4*app.PD+2);
                        end
                        for j = 1:app.NPE
                            val = stress(i,:,1,1);
                            stress_xx(j,i) = val(1,j);
                        end
                        for j = 1:app.NPE
                            val = stress(i,:,1,2);
                            stress_xy(j,i) = val(1,j);
                        end
                        for j = 1:app.NPE
                            val = stress(i,:,2,1);
                            stress_yx(j,i) = val(1,j);
                        end
                        for j = 1:app.NPE
                            val = stress(i,:,2,2);
                            stress_yy(j,i) = val(1,j);
                        end
                        for j = 1:app.NPE
                            val = strain(i,:,1,1);
                            strain_xx(j,i) = val(1,j);
                        end
                        for j = 1:app.NPE
                            val = strain(i,:,1,2);
                            strain_xy(j,i) = val(1,j);
                        end
                        for j = 1:app.NPE
                            val = strain(i,:,2,1);
                            strain_yx(j,i) = val(1,j);
                        end
                        for j = 1:app.NPE
                            val = strain(i,:,2,2);
                            strain_yy(j,i) = val(1,j);
                        end
                        for j = 1:app.NPE
                            disp_x(j,i) = disp(i,j,1,1);
                        end
                        for j = 1:app.NPE
                            disp_y(j,i) = disp(i,j,2,1);
                        end
                    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 6
                    order = [1,4,2,5,3,6]; 
                    for i=1:app.NoE
                        nl = app.EL(i,1:app.NPE);
                        % coordinates
                        for j=1:app.NPE
                            X(j,i) = app.ENL(nl(order(j)),1)+ scale*app.ENL(nl(order(j)),4*app.PD+1);    
                            Y(j,i) = app.ENL(nl(order(j)),2)+ scale*app.ENL(nl(order(j)),4*app.PD+2); 
                        end
                       % Stress
                        for j=1:app.NPE/2
                            val = stress(i,:,1,1);   
                            if (j==app.NPE/2)
                                stress_xx(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                stress_xx(order(2*j-1),i) = val(1,j);
                            else
                                stress_xx(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                stress_xx(order(2*j-1),i) = val(1,j);
                            end
                        end
                        for j=1:app.NPE/2
                           val = stress(i,:,1,2);   
                           if (j==app.NPE/2)
                                stress_xy(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                stress_xy(order(2*j-1),i) = val(1,j);
                           else
                                stress_xy(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                stress_xy(order(2*j-1),i) = val(1,j);                           
                           end
                        end
                        for j=1:app.NPE/2
                            val = stress(i,:,2,1);   
                            if (j==app.NPE/2)
                                stress_yx(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                stress_yx(order(2*j-1),i) = val(1,j);
                           else
                                stress_yx(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                stress_yx(order(2*j-1),i) = val(1,j);
                           end
                        end
                        for j=1:app.NPE/2
                           val = stress(i,:,2,2);   
                           if (j==app.NPE/2)
                                stress_yy(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                stress_yy(order(2*j-1),i) = val(1,j);
                           else
                                stress_yy(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                stress_yy(order(2*j-1),i) = val(1,j);
                           end
                        end
                    % Strain
                        for j=1:app.NPE/2
                          val = strain(i,:,1,1);   
                          if (j==app.NPE/2)
                               strain_xx(order(2*j),i) = (val(1,j)+val(1,1))/2;
                               strain_xx(order(2*j-1),i) = val(1,j);
                          else
                               strain_xx(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                               strain_xx(order(2*j-1),i) = val(1,j);
                          end
                       end
                       for j=1:app.NPE/2
                          val = strain(i,:,1,2);   
                          if (j==app.NPE/2)
                               strain_xy(order(2*j),i) = (val(1,j)+val(1,1))/2;
                               strain_xy(order(2*j-1),i) = val(1,j);
                          else
                               strain_xy(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                               strain_xy(order(2*j-1),i) = val(1,j);
                          end
                       end
                       for j=1:app.NPE/2
                          val = strain(i,:,2,1);   
                          if (j==app.NPE/2)
                               strain_yx(order(2*j),i) = (val(1,j)+val(1,1))/2;
                               strain_yx(order(2*j-1),i) = val(1,j);
                          else
                               strain_yx(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                               strain_yx(order(2*j-1),i) = val(1,j);
                          end
                       end
                       for j=1:app.NPE/2
                          val = strain(i,:,2,2);   
                          if (j==app.NPE/2)
                               strain_yy(order(2*j),i) = (val(1,j)+val(1,1))/2;
                               strain_yy(order(2*j-1),i) = val(1,j);
                          else
                               strain_yy(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                               strain_yy(order(2*j-1),i) = val(1,j);
                          end
                       end
                        % Displacement
                       for j=1:app.NPE
                          val = disp(i,:,1);
                          disp_x(order(j),i) = disp(i,order(j));
                       end
                       for j=1:app.NPE
                          val = disp(i,:,2);
                          disp_y(order(j),i) = disp(i,order(j));
                       end
                     end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 8
                    order = [1,5,2,6,3,7,4,8];
                    for i=1:app.NoE
                        nl = app.EL(i,1:app.NPE); 
                        % Coordinates
                        for j=1:app.NPE
                            X(j,i) = app.ENL(nl(order(j)),1)+ scale*app.ENL(nl(order(j)),4*app.PD+1);  
                            Y(j,i) = app.ENL(nl(order(j)),2)+ scale*app.ENL(nl(order(j)),4*app.PD+2);
                        end
                        % Stress
                            for j=1:app.NPE/2
                               val = stress(i,:,1,1);   
                               if (j==app.NPE/2)
                                    stress_xx(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                    stress_xx(order(2*j-1),i) = val(1,j);
                               else
                                    stress_xx(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                    stress_xx(order(2*j-1),i) = val(1,j);
                               end
                            end
                            for j=1:app.NPE/2
                               val = stress(i,:,1,2);   
                               if (j==app.NPE/2)
                                    stress_xy(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                    stress_xy(order(2*j-1),i) = val(1,j);
                               else
                                    stress_xy(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                    stress_xy(order(2*j-1),i) = val(1,j);
                               end
                            end
                            for j=1:app.NPE/2
                               val = stress(i,:,2,1);   
                               if (j==app.NPE/2)
                                    stress_yx(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                    stress_yx(order(2*j-1),i) = val(1,j);
                               else
                                    stress_yx(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                    stress_yx(order(2*j-1),i) = val(1,j);
                               end
                            end
                            for j=1:app.NPE/2
                               val = stress(i,:,2,2);   
                               if (j==app.NPE/2)
                                    stress_yy(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                    stress_yy(order(2*j-1),i) = val(1,j);
                               else
                                    stress_yy(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                    stress_yy(order(2*j-1),i) = val(1,j);
                               end
                            end
                        % Strain
                             for j=1:app.NPE/2
                                val = strain(i,:,1,1);   
                                if (j==app.NPE/2)
                                    strain_xx(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                    strain_xx(order(2*j-1),i) = val(1,j);    
                                else
                                    strain_xx(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                    strain_xx(order(2*j-1),i) = val(1,j);
                                end
                            end
                            for j=1:app.NPE/2
                                val = strain(i,:,1,2);   
                                if (j==app.NPE/2)
                                    strain_xy(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                    strain_xy(order(2*j-1),i) = val(1,j);
                                else
                                    strain_xy(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                    strain_xy(order(2*j-1),i) = val(1,j);
                                end
                            end
                            for j=1:app.NPE/2
                               val = strain(i,:,2,1);   
                               if (j==app.NPE/2)
                                    strain_yx(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                    strain_yx(order(2*j-1),i) = val(1,j);
                               else
                                    strain_yx(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                    strain_yx(order(2*j-1),i) = val(1,j);
                               end
                            end
                            for j=1:app.NPE/2
                               val = strain(i,:,2,2);   
                               if (j==app.NPE/2)
                                    strain_yy(order(2*j),i) = (val(1,j)+val(1,1))/2;
                                    strain_yy(order(2*j-1),i) = val(1,j);
                               else
                                    strain_yy(order(2*j),i) = (val(1,j)+val(1,j+1))/2;
                                    strain_yy(order(2*j-1),i) = val(1,j);
                               end
                            end
                            % Displacement
                            for j=1:app.NPE
                               val = disp(i,:,1);
                               disp_x(order(j),i) = disp(i,order(j));
                            end
                            for j=1:app.NPE
                               val = disp(i,:,2);
                               disp_y(order(j),i) = disp(i,order(j));
                            end
                    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 9
                    order11 = [1,5,9,8];
                    order12 = [5,2,6,9];
                    order13 = [9,6,3,7];
                    order14 = [8,9,7,4];
                    for i=1:app.NoE
                        nl = app.EL(i,1:app.NPE); 
                        % coordinates
                        for j=1:4
                            X(j,i,1) = app.ENL(nl(order11(j)),1)+ scale*app.ENL(nl(order11(j)),4*app.PD+1);
                            Y(j,i,1) = app.ENL(nl(order11(j)),2)+ scale*app.ENL(nl(order11(j)),4*app.PD+2);
                            X(j,i,2) = app.ENL(nl(order12(j)),1)+ scale*app.ENL(nl(order12(j)),4*app.PD+1);    
                            Y(j,i,2) = app.ENL(nl(order12(j)),2)+ scale*app.ENL(nl(order12(j)),4*app.PD+2);
                            X(j,i,3) = app.ENL(nl(order13(j)),1)+ scale*app.ENL(nl(order13(j)),4*app.PD+1);
                            Y(j,i,3) = app.ENL(nl(order13(j)),2)+ scale*app.ENL(nl(order13(j)),4*app.PD+2);
                            X(j,i,4) = app.ENL(nl(order14(j)),1)+ scale*app.ENL(nl(order14(j)),4*app.PD+1); 
                            Y(j,i,4) = app.ENL(nl(order14(j)),2)+ scale*app.ENL(nl(order14(j)),4*app.PD+2);
                        end
                        % stress
                        for j=1:4
                           val = stress(i,:,1,1);
                           stress_xx(j,i,1) = val(1,order11(j)); 
                           stress_xx(j,i,2) = val(1,order12(j));
                           stress_xx(j,i,3) = val(1,order13(j));
                           stress_xx(j,i,4) = val(1,order14(j));
                        end
                        for j=1:4
                           val = stress(i,:,1,2);
                           stress_xy(j,i,1) = val(1,order11(j)); 
                           stress_xy(j,i,2) = val(1,order12(j));
                           stress_xy(j,i,3) = val(1,order13(j));
                           stress_xy(j,i,4) = val(1,order14(j));
                        end
                        for j=1:4
                           val = stress(i,:,2,1);
                           stress_yx(j,i,1) = val(1,order11(j)); 
                           stress_yx(j,i,2) = val(1,order12(j));
                           stress_yx(j,i,3) = val(1,order13(j));
                           stress_yx(j,i,4) = val(1,order14(j));
                        end
                        for j=1:4
                           val = stress(i,:,2,2);
                           stress_yy(j,i,1) = val(1,order11(j)); 
                           stress_yy(j,i,2) = val(1,order12(j));
                           stress_yy(j,i,3) = val(1,order13(j));
                           stress_yy(j,i,4) = val(1,order14(j));
                        end
                        % strain
                        for j=1:4
                           val = strain(i,:,1,1);
                           strain_xx(j,i,1) = val(1,order11(j)); 
                           strain_xx(j,i,2) = val(1,order12(j));
                           strain_xx(j,i,3) = val(1,order13(j));
                           strain_xx(j,i,4) = val(1,order14(j));
                        end
                        for j=1:4
                           val = strain(i,:,1,2);
                           strain_xy(j,i,1) = val(1,order11(j)); 
                           strain_xy(j,i,2) = val(1,order12(j));
                           strain_xy(j,i,3) = val(1,order13(j));
                           strain_xy(j,i,4) = val(1,order14(j));
                        end
                        for j=1:4
                           val = strain(i,:,2,1);
                           strain_yx(j,i,1) = val(1,order11(j)); 
                           strain_yx(j,i,2) = val(1,order12(j));
                           strain_yx(j,i,3) = val(1,order13(j));
                           strain_yx(j,i,4) = val(1,order14(j));
                        end
                        for j=1:4
                           val = strain(i,:,2,2);
                           strain_yy(j,i,1) = val(1,order11(j)); 
                           strain_yy(j,i,2) = val(1,order12(j));
                           strain_yy(j,i,3) = val(1,order13(j));
                           strain_yy(j,i,4) = val(1,order14(j));
                        end
                        % displacement 
                        for j=1:4
                           disp_x(j,i,1) = disp(i,order11(j),1);
                           disp_x(j,i,2) = disp(i,order12(j),1);
                           disp_x(j,i,3) = disp(i,order13(j),1);
                           disp_x(j,i,4) = disp(i,order14(j),1);
                        end
                        for j=1:4
                           disp_y(j,i,1) = disp(i,order11(j),2);
                           disp_y(j,i,2) = disp(i,order12(j),2);
                           disp_y(j,i,3) = disp(i,order13(j),2);
                           disp_y(j,i,4) = disp(i,order14(j),2);
                        end
                    end
            end
%%%%%%%%%%%% PRECISE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            switch app.plottype
                case "stress_xx"
                    a = stress_xx;
                    b = "Distribution of Stress XX";
                case "stress_xy"
                    a = stress_xy;
                    b = "Distribution of Stress XY";
                case "stress_yx"
                    a = stress_yx;
                    b = "Distribution of Stress YX";
                case "stress_yy"
                    a = stress_yy;
                    b = "Distribution of Stress YY";
                case "strain_xx"
                    a = stress_xx;
                    b = "Distribution of Strain XX";
                case "strain_xy"
                    a = strain_xy;
                    b = "Distribution of Strain XY";
                case "strain_yx"
                    a = strain_yx;
                    b = "Distribution of Strain YX";
                case "strain_yy"
                    a = strain_yy;
                    b = "Distribution of Strain YY";
                case "disp_x"
                    a = disp_x;
                    b = "Distribution of Displacement X";
                case "disp_y"
                    a = disp_y;
                    b = "Distribution of Displacement Y";
            end
%%%%%%%%%%%% SMOOTH %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [smooth] = smoothFunction(app.EL,app.ENL,a);
                    cla(app.UIAxesPrecise);
                    hold(app.UIAxesPrecise, "on")
                    colormap(app.UIAxesPrecise, app.colorMap);
                    grid(app.UIAxesPrecise,"on");
                    cla(app.UIAxesSmooth);
                    hold(app.UIAxesSmooth, "on")
                    colormap(app.UIAxesSmooth, app.colorMap);
                    grid(app.UIAxesSmooth,"on");
                    if app.NPE == 9
                        for i=1:4
                            if app.ShowMeshSwitch.Value == "On"
                                patch(app.UIAxesPrecise,X(:,:,i),Y(:,:,i),a(:,:,i),'FaceColor','interp');
                                patch(app.UIAxesSmooth,X(:,:,i),Y(:,:,i),smooth(:,:,i),'FaceColor','interp');
                            else
                                patch(app.UIAxesPrecise,X(:,:,i),Y(:,:,i),a(:,:,i),'FaceColor','interp','EdgeColor','none');
                                patch(app.UIAxesSmooth,X(:,:,i),Y(:,:,i),smooth(:,:,i),'FaceColor','interp','EdgeColor','none');
                            end
                        end
                    else
                        if app.ShowMeshSwitch.Value == "On"
                            patch(app.UIAxesPrecise,X,Y,a,'FaceColor','interp');
                            patch(app.UIAxesSmooth,X,Y,smooth,'FaceColor','interp');
                        else
                            patch(app.UIAxesPrecise,X,Y,a,'FaceColor','interp','EdgeColor','none');
                            patch(app.UIAxesSmooth,X,Y,smooth,'FaceColor','interp','EdgeColor','none');
                        end
                    end
                    title(app.UIAxesPrecise,b, 'FontWeight', 'bold');
                    axis(app.UIAxesPrecise, "equal");
                    colorbar(app.UIAxesPrecise);
                    title(app.UIAxesSmooth,b, 'FontWeight', 'bold');
                    axis(app.UIAxesSmooth, "equal");
                    colorbar(app.UIAxesSmooth);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    

            function [disp, stress, strain] = element_post_process(NL,EL,ENL, E, nu)
    
                app.PD = size(NL,2);
                app.NPE = size(EL,2);
    
                app.NoN = size(ENL,1);
                app.NoE = size(EL,1);

                if app.NPE == 3
                    GPE = 1;
                elseif app.NPE == 4
                    GPE = 4; 
                elseif app.NPE == 6
                    GPE = 3;
                elseif app.NPE == 8
                    GPE = 4;
                elseif app.NPE ==9
                    GPE = 9;  
                end

                disp = zeros(app.NoE, app.NPE, app.PD, 1);
                stress = zeros(app.NoE, GPE, app.PD, app.PD);
                strain = zeros(app.NoE, GPE, app.PD, app.PD);
    
                for e = 1:app.NoE
                    nl = EL(e,1:app.NPE); % node numbers connected to each element
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            disp(e,i,j,1) = ENL(nl(i),4*app.PD+j);
                        end
                    end
    
                    x = zeros(app.NPE,app.PD);
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            x(i,j) = NL(nl(i),j);
                        end
                    end
    
                    coor = x';
    
                    u = zeros(app.PD,app.NPE);
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            u(j,i) = ENL(nl(i),4*app.PD+j);
                        end
                    end
    
                    for gp = 1:GPE
    
                        epsilon = zeros(app.PD,app.PD);
    
                        for i = 1:app.NPE
    
                            J = zeros(app.PD,app.PD);
                            grad = zeros(app.PD,app.NPE);
    
                            [xi, eta, alpha] = GaussPoint(app.NPE, GPE, gp);
    
                            grad_nat = grad_N_nat(app.NPE, xi, eta);
    
                            J = coor * grad_nat';
    
                            grad = inv(J)' * grad_nat;
                            epsilon = epsilon + (1/2) * (dyad(grad(:,i),u(:,i)) + dyad(u(:,i), grad(:,i)));  
                        end
    
                        sigma = zeros(app.PD,app.PD);
    
                        for a = 1:app.PD
                            for b = 1:app.PD
                                for c = 1:app.PD
                                    for d = 1:app.PD
                                        sigma(a,b) = sigma(a,b) + constitutive(a,b,c,d, E,nu) * epsilon(c,d);
                                    end
                                end
                            end
                        end
    
                        for a = 1:app.PD
                            for b = 1:app.PD
                                strain(e,gp,a,b) = epsilon(a,b);
                                stress(e,gp,a,b) = sigma(a,b);
                            end
                        end
                    end
                end
            end
        

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        

            function result = grad_N_nat(NPE, xi, eta)
                app.PD = 2;
                result = zeros(app.PD,NPE);
                if (NPE == 3)
                    result(1,1) = 1;
                    result(1,2) = 0;
                    result(1,3) = -1;
    
                    result(2,1) = 0;
                    result(2,2) = 1;
                    result(2,3) = -1;
    
                elseif (NPE == 4)
                    result(1,1) = -1/4*(1-eta);
                    result(1,2) =  1/4*(1-eta);
                    result(1,3) =  1/4*(1+eta);
                    result(1,4) = -1/4*(1+eta);
    
                    result(2,1) = -1/4*(1-xi);
                    result(2,2) = -1/4*(1+xi);
                    result(2,3) =  1/4*(1+xi);
                    result(2,4) =  1/4*(1-xi);
                elseif (NPE == 6)
                    result(1,1) = 4*xi-1;
                    result(1,2) = 0;
                    result(1,3) = 4*xi+4*eta-3;
                    result(1,4) = 4*eta;
                    result(1,5) = -4*eta;
                    result(1,6) = 4-8*xi-4*eta;%
                    
                    result(2,1) = 0;
                    result(2,2) = 4*eta-1;
                    result(2,3) = 4*eta+4*xi-3;
                    result(2,4) = 4*xi;
                    result(2,5) = 4-4*xi-8*eta;
                    result(2,6) = -4*xi;
                elseif (NPE == 8)
                    result(1,1) = -1/4*(eta^2+2*xi*eta-2*xi-eta);
                    result(1,2) = -1/4*(-eta^2+2*xi*eta-2*xi+eta);
                    result(1,3) = 1/4*(eta^2+2*xi*eta+2*xi+eta);
                    result(1,4) = 1/4*(-eta^2+2*xi*eta+2*xi-eta);
                    result(1,5) = 1/2*(2*xi*eta-2*xi);
                    result(1,6) = 1/2*(-eta^2+1);
                    result(1,7) = 1/2*(-2*xi*eta-2*xi);
                    result(1,8) = 1/2*(eta^2-1);
                    
                    result(2,1) = -1/4*(xi^2+2*xi*eta-2*eta-xi);
                    result(2,2) = -1/4*(-2*xi*eta+xi^2-2*eta+xi);
                    result(2,3) = 1/4*(2*xi*eta+xi^2+2*eta+xi);
                    result(2,4) = 1/4*(-2*xi*eta+xi^2+2*eta-xi);
                    result(2,5) = 1/2*(xi^2-1);
                    result(2,6) = 1/2*(-2*xi*eta-2*eta);
                    result(2,7) = 1/2*(-xi^2+1);
                    result(2,8) = 1/2*(2*xi*eta-2*eta);

                elseif (NPE == 9)
                    result(1,1) = 1/4*(eta-1)*(eta)*(2*xi-1);
                    result(1,2) = 1/4*(eta-1)*(eta)*(2*xi+1);
                    result(1,3) = 1/4*(eta+1)*(eta)*(2*xi+1);
                    result(1,4) = 1/4*(eta+1)*(eta)*(2*xi-1);
                    result(1,5) = -1/2*(eta-1)*(eta)*(2*xi);
                    result(1,6) = -1/2*(eta-1)*(eta+1)*(2*xi+1);
                    result(1,7) = -1/2*(eta+1)*(eta)*(2*xi);
                    result(1,8) = -1/2*(eta-1)*(eta+1)*(2*xi-1);
                    result(1,9) = (eta-1)*(eta+1)*(2*xi);
                    
                    result(2,1) = 1/4*(xi-1)*(xi)*(2*eta-1);
                    result(2,2) = 1/4*(xi+1)*(xi)*(2*eta-1);
                    result(2,3) = 1/4*(xi+1)*(xi)*(2*eta+1);
                    result(2,4) = 1/4*(xi-1)*(xi)*(2*eta+1);
                    result(2,5) = -1/2*(xi-1)*(xi+1)*(2*eta-1);
                    result(2,6) = -1/2*(xi+1)*(xi)*(2*eta);
                    result(2,7) = -1/2*(xi-1)*(xi+1)*(2*eta+1);
                    result(2,8) = -1/2*(xi-1)*(xi)*(2*eta);
                    result(2,9) = (xi-1)*(xi+1)*(2*eta);
                end
            end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function [xi, eta, alpha] = GaussPoint(NPE, GPE, gp)
                if (NPE == 4)
                    if (GPE == 1)
                        xi = 0; eta = 0; alpha = 4;
                    elseif (GPE == 4)
                        if (gp == 1)
                            xi = -1/sqrt(3); eta = -1/sqrt(3); alpha = 1;
                        elseif(gp == 2)
                            xi = 1/sqrt(3); eta = -1/sqrt(3); alpha = 1;
                        elseif(gp == 3)
                            xi = 1/sqrt(3); eta = 1/sqrt(3); alpha = 1;
                        elseif(gp == 4)
                            xi = -1/sqrt(3); eta = 1/sqrt(3); alpha = 1;
                        end
                    end
                end
                
                if (NPE == 3)
                    xi = 1/3; eta = 1/3; alpha = 1;
                end
            
                if (NPE == 6)
                    if (GPE == 3)
                        if gp==1
                            xi = 1/6; eta = 1/6; alpha = 1/6;
                        elseif gp==2
                            xi = 4/6; eta = 1/6; alpha = 1/6;
                        elseif gp==3
                            xi = 1/6; eta = 4/6; alpha = 1/6;
                        end
                    end 
                elseif (NPE == 8)
                    if GPE==4
                        if gp==1
                            xi = -1/sqrt(3); eta = -1/sqrt(3); alpha = 1;
                        elseif gp==2
                            xi = 1/sqrt(3); eta = -1/sqrt(3); alpha = 1;
                        elseif gp==3
                            xi = 1/sqrt(3); eta = 1/sqrt(3); alpha = 1;
                        elseif gp==4
                            xi = -1/sqrt(3); eta = 1/sqrt(3); alpha = 1;
                        end
                    end
                elseif (NPE == 9)
                    if (GPE == 9)
                        if gp==1
                            xi = -sqrt(0.6); eta = -sqrt(0.6); alpha = 25/81;
                        elseif gp==2
                            xi = sqrt(0.6); eta = -sqrt(0.6); alpha = 25/81;
                        elseif gp==3
                            xi = sqrt(0.6); eta = sqrt(0.6); alpha = 25/81;
                        elseif gp==4
                            xi = -sqrt(0.6); eta = sqrt(0.6); alpha = 25/81;
                        elseif gp==5
                            xi = 0; eta = -sqrt(0.6); alpha = 40/81;
                        elseif gp==7
                            xi = 0; eta = sqrt(0.6); alpha = 40/81;
                        elseif gp==6
                            xi = sqrt(0.6); eta = 0; alpha = 40/81;
                        elseif gp==8
                            xi = -sqrt(0.6); eta = 0; alpha = 40/81;
                        elseif gp==9
                            xi = 0; eta = 0; alpha = 64/81;
                        end
                    end
                end     
            end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function C = constitutive(i,j,k,l,E, nu)
                C = (E/(2*(1+nu))) * (delta(i,l)*delta(j,k) + delta(i,k)*delta(j,l)) + ((E*nu)/(1-nu^2)) * (delta(i,j)*delta(k,l));
            end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function delta = delta(i,j)
                if (i == j)
                    delta = 1;
                else
                    delta = 0;
                end
            end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function A = dyad(a,b)
                app.PD = 2;
                A = zeros(app.PD,app.PD);
                for k = 1:app.PD
                    for t = 1:app.PD
                        A(k,t) = a(k)*b(t);
                    end
                end
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function [smooth_results] = smoothFunction(EL,ENL,precise_results)
                app.NPE = size(EL,2);
                app.NoN = size(ENL,1);
                app.NoE = size(EL,1);
                if (app.NPE ~= 9)
                    if(app.NPE == 8)
                        order = [1,5,2,6,3,7,4,8]; 
                    elseif(app.NPE == 6)
                        order = [1,4,2,5,3,6];
                    elseif (app.NPE == 3)
                        order = [1,2,3];
                    elseif (app.NPE == 4)    
                        order = [1,2,3,4];
                    end
                    avgerage_x = zeros(app.NoN,1);
                    for i=1:app.NoN
                        cntx = 0;
                        for j=1:app.NoE
                            for k=1:app.NPE
                               if(EL(j,order(k))==i)
                                   avgerage_x(i,1)=avgerage_x(i,1)+precise_results(k,j);
                                   cntx = cntx +1;
                               end
                            end  
                        end
                        avgerage_x(i,1)=avgerage_x(i,1)/cntx;
                    end
                    smooth_results = zeros(app.NPE,app.NoE);
                    for i=1:app.NoN
                        for j=1:app.NoE
                            for k=1:app.NPE
                                if(EL(j,order(k))==i)
                                    smooth_results(k,j)=avgerage_x(i,1);
                                end
                            end
                        end
                    end
                else
                    smooth_results = precise_results;
                end
            end
            app.EMatrix;
            app.nuMatrix;
        end

        % Value changed function: DeformationMagnitudeEditField
        function DeformationMagnitudeEditFieldValueChanged(app, event)
            value = app.DeformationMagnitudeEditField.Value;
            app.Magnitude = value;
        end

        % Drop down opening function: ResultsDropDown
        function ResultsDropDownOpening(app, event)
%
        end

        % Selection changed function: DeformationTypeButtonGroup
        function DeformationTypeButtonGroupSelectionChanged(app, event)
    
            app.NoN = size(app.NL,1); % Number of Nodes
                app.PD = size(app.NL,2); %Problem Dimension  
                app.ENL = zeros(app.NoN,app.PD*6);
                app.ENL(:,1:app.PD) = app.NL;
                
                if app.ShearButton.Value == 1 
                    app.BC_Type = "Shear";
                elseif app.ExpansionButton.Value == 1
                    app.BC_Type = "Expansion";
                else 
                    app.BC_Type = "Extension";
                end
        end

        % Selection changed function: ColorMapButtonGroup
        function ColorMapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorMapButtonGroup.SelectedObject;

            switch selectedButton
                case app.JETButton
                    app.colorMap = jet;
                    colormap(app.UIAxesPrecise, app.colorMap)
                    colormap(app.UIAxesSmooth, app.colorMap)

                case app.RAINBOWButton
                    app.colorMap = parula ;
                    colormap(app.UIAxesPrecise, app.colorMap)
                    colormap(app.UIAxesSmooth, app.colorMap)

                case app.BONEButton
                    app.colorMap = bone ;
                    colormap(app.UIAxesPrecise, app.colorMap)
                    colormap(app.UIAxesSmooth, app.colorMap)
            end
        end

        % Value changed function: ResultsDropDown
        function ResultsDropDownValueChanged(app, event)
            value = app.ResultsDropDown.Value;
            if value == "stress_xx"
                app.plottype = "stress_xx";
            elseif value == "stress_xy"
                app.plottype = "stress_xy";
            elseif value == "stress_yx"
                app.plottype = "stress_yx";
            elseif value == "stress_yy"
                app.plottype = "stress_yy";
            elseif value == "strain_xx"
                app.plottype = "strain_xx";
            elseif value == "strain_xy"
                app.plottype = "strain_xy";
            elseif value == "strain_yx"
                app.plottype = "strain_yx";
            elseif value == "strain_yy"
                app.plottype = "strain_yy";
            elseif value == "displacement_x"
                app.plottype = "disp_x";
            else 
                app.plottype = "disp_y";
            end
            RUNButtonPushed(app)
        end

        % Value changed function: MagnifierEditField
        function MagnifierEditFieldValueChanged(app, event)
            value = app.MagnifierEditField.Value;
            app.magnifier = value;
        end

        % Value changed function: ShowMeshSwitch
        function ShowMeshSwitchValueChanged(app, event)
            value = app.ShowMeshSwitch.Value;
            RUNButtonPushed(app)
        end

        % Value changed function: EPaEditField
        function EPaEditFieldValueChanged(app, event)
            value = app.EPaEditField.Value;
            app.E_1d = value;
        end

        % Value changed function: Am2EditField
        function Am2EditFieldValueChanged(app, event)
            value = app.Am2EditField.Value;
            app.A = value;
        end

        % Value changed function: EPaEditField_2
        function EPaEditField_2ValueChanged(app, event)
            value = app.EPaEditField_2.Value;
            app.E = value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.nu = value;
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            value = app.cEditField.Value;
            app.p = value;
        end

        % Value changed function: eEditField
        function eEditFieldValueChanged(app, event)
            value = app.eEditField.Value;
            app.R = value;
        end

        % Value changed function: DeformaitonMagnitudeEditField
        function DeformaitonMagnitudeEditFieldValueChanged(app, event)
            value = app.DeformaitonMagnitudeEditField.Value;
            app.Magnitude = value;
        end

        % Value changed function: DeformationTypeDropDown
        function DeformationTypeDropDownValueChanged(app, event)
            value = app.DeformationTypeDropDown.Value;
            value = app.DeformationTypeDropDown.Value;
            
            app.NoN = size(app.NL,1); % Number of Nodes
            app.PD = size(app.NL,2); %Problem Dimension
            app.ENL = zeros(app.NoN,app.PD*6);
            app.ENL(:,1:app.PD) = app.NL;


            if value == "Extension"
                app.BC_Type = "Extension";
            elseif value == "Expansion"
                app.BC_Type = "Expansion";
            else 
                app.BC_Type = "Shear";
            end             
        end

        % Value changed function: ShowMeshSwitch_2
        function ShowMeshSwitch_2ValueChanged(app, event)
            value = app.ShowMeshSwitch_2.Value;
            PLOTButtonPushed(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            switch app.DropDown.Value
                case "U_x"
                    app.disp_direction_2 = "U_x";
                case "U_y"
                    app.disp_direction_2 = "U_y";
                case "U"
                    app.disp_direction_2 = "U";
                case "Stress"
                    app.disp_direction_2 = "Stress";
            end
            PLOTButtonPushed(app)
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value == "stress_xx"
                app.plottype_2 = "stress_xx";
            elseif value == "stress_xy"
                app.plottype_2 = "stress_xy";
            elseif value == "stress_yx"
                app.plottype_2 = "stress_yx";
            elseif value == "stress_yy"
                app.plottype_2 = "stress_yy";           
            elseif value == "U_x"
                app.plottype_2 = "disp_x";
            elseif value =="U_y" 
                app.plottype_2 = "disp_y";
            else
                app.plottype_2 ="U_total";
            end
            PLOTButtonPushed(app)
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            cla(app.UIAxes_4);
            hold(app.UIAxes_4,"on");
            grid(app.UIAxes_4,"on");
            axis(app.UIAxes_4,"equal");

            cla(app.UIAxes5);
            hold(app.UIAxes5,"on");
            grid(app.UIAxes5,"on");
            axis(app.UIAxes5,"equal");

                app.d1 = 1;
                app.d2 = 1;
                app.m = 4;
                element_type ="D2TR3N";

                %%%%%%%%%%%% 2D PREPROCESS %%%%%%%%%%%%%%%%%%
                    if app.VoidType_2.Value == "Circle"
                        [app.NL, app.EL] = circle_void_mesh(app.d1, app.d2, app.p, app.m, app.R, element_type);
                    elseif app.VoidType_2.Value == "Square"
                        [app.NL, app.EL] = square_D2TR3N(app.d1, app.d2, app.p, app.m, 0.25, element_type);
                    elseif app.VoidType_2.Value == "Rhombus"
                        [app.NL, app.EL] = rhombus_D2TR3N(app.d1, app.d2, app.p, app.m, app.R, element_type);
                    elseif app.VoidType_2.Value == "Uniform"
                        [app.NL,app.EL] = uniform_mesh(app.d1,app.d2,app.p,app.p,element_type);
                    end
                    updateaxes_2d(app)

                %%%%%%%%%%%%%%%%%%%%%%%%%% 1D PREPROCESS %%%%%%%%%%%%%%
                    if app.VoidType_2.Value == "Circle"
                        [app.NL_2, app.EL_2, app.NoE_2, app.NoN_2] = circlehole_mesh(app.p,app.R);
                    elseif app.VoidType_2.Value == "Square"
                        [app.NL_2, app.EL_2, app.NoE_2, app.NoN_2] = squarehole_mesh(app.p);
                    elseif app.VoidType_2.Value == "Rhombus"
                        [app.NL_2, app.EL_2, app.NoE_2, app.NoN_2] = squarehole_mesh(app.p);
                    elseif app.VoidType_2.Value == "Uniform"
                        [app.NL_2, app.EL_2, app.NoE_2, app.NoN_2] = nohole_mesh(app.p);
                    end
                    updateAxes_1d(app)
       
                    
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            if app.EL == 0
                uialert(app.UIFigure, "There is no meshed structure!", "Input Error");
            else
                cla(app.UIAxes_displacement_2)
                cla(app.UIAxes8)
    
                colormap(app.UIAxes_displacement_2,"jet")
                colorbar(app.UIAxes_displacement_2)
                axis(app.UIAxes_displacement_2, "equal")
    
                oned_process(app)
                twod_process(app)
    
                %%% 2d POSTPROCESS %%%
                app.PD = size(app.NL,2);
                app.NPE = size(app.EL,2);
    
                app.NoN = size(app.ENL,1);
                app.NoE = size(app.EL,1);
    
                scale = 2; % Magnifies deformation
                app.NPE =3;
    
                [disp, stress, strain] = element_post_process(app.NL,app.EL,app.ENL, app.E, app.nu);
    
    
                for i = 1:app.NoE
                    nl = app.EL(i,1:app.NPE); % node numbers connected to element
    
                    for j = 1:app.NPE
                        X(j,i) = app.ENL(nl(j),1) + scale*app.ENL(nl(j),4*app.PD+1);
                        Y(j,i) = app.ENL(nl(j),2) + scale*app.ENL(nl(j),4*app.PD+2);
                    end
    
                    for j = 1:app.NPE
                        stress_xx(j,i) = stress(i,:,1,1);
                    end
    
                    for j = 1:app.NPE
                        stress_xy(j,i) = stress(i,:,1,2);
                    end
    
                    for j = 1:app.NPE
                        stress_yx(j,i) = stress(i,:,2,1);
                    end
    
                    for j = 1:app.NPE
                        stress_yy(j,i) = stress(i,:,2,2);
                    end      
    
                    for j = 1:app.NPE
                        disp_x(j,i) = disp(i,j,1,1);
                    end
    
                    for j = 1:app.NPE
                        disp_y(j,i) = disp(i,j,2,1);
                    end
                end
    
                  
                switch app.plottype_2
                    case "stress_xx"
                        cla(app.UIAxes8);
                        hold(app.UIAxes8, "on")
                        colormap(app.UIAxes8, app.colorMap);
                        if app.ShowMeshSwitch_2.Value == "On"
                            patch(app.UIAxes8,X,Y,stress_xx,'FaceColor','interp');
                        else
                            patch(app.UIAxes8,X,Y,stress_xx,'FaceColor','interp','EdgeColor','none');
                        end
                        title(app.UIAxes8,'Distribution of Stress XX', 'FontWeight', 'bold');
                        axis(app.UIAxes8, "equal");
                        colorbar(app.UIAxes8);
                    case "stress_xy"
                        cla(app.UIAxes8);
                        hold(app.UIAxes8, "on")
                        colormap(app.UIAxes8, app.colorMap);
                        if app.ShowMeshSwitch_2.Value == "On"
                            patch(app.UIAxes8,X,Y,stress_xy,'FaceColor','interp');
                        else
                            patch(app.UIAxes8,X,Y,stress_xy,'FaceColor','interp','EdgeColor','none');
                        end
                        title(app.UIAxes8,'Distribution of Stress XY', 'FontWeight', 'bold');
                        axis(app.UIAxes8, "equal");
                        colorbar(app.UIAxes8);
                    case "stress_yx"
                        cla(app.UIAxes8);
                        hold(app.UIAxes8, "on")
                        colormap(app.UIAxes8, app.colorMap);
                        if app.ShowMeshSwitch_2.Value == "On"
                            patch(app.UIAxes8,X,Y,stress_yx,'FaceColor','interp');
                        else
                            patch(app.UIAxes8,X,Y,stress_yx,'FaceColor','interp','EdgeColor','none');
                        end                    
                        title(app.UIAxes8,'Distribution of Stress YX', 'FontWeight', 'bold');
                        axis(app.UIAxes8, "equal");
                        colorbar(app.UIAxes8);
                    case "stress_yy"
                        cla(app.UIAxes8);
                        hold(app.UIAxes8, "on")
                        colormap(app.UIAxes8, app.colorMap);
                        if app.ShowMeshSwitch_2.Value == "On"
                            patch(app.UIAxes8,X,Y,stress_yy,'FaceColor','interp');
                        else
                            patch(app.UIAxes8,X,Y,stress_yy,'FaceColor','interp','EdgeColor','none');
                        end                    
                        title(app.UIAxes8,'Distribution of Stress YY', 'FontWeight', 'bold');
                        axis(app.UIAxes8, "equal");
                        colorbar(app.UIAxes8);
                    
                    case "disp_x"
                        cla(app.UIAxes8);
                        hold(app.UIAxes8, "on")
                        colormap(app.UIAxes8, app.colorMap);
                        if app.ShowMeshSwitch_2.Value == "On"
                            patch(app.UIAxes8,X,Y,disp_x,'FaceColor','interp');
                        else
                            patch(app.UIAxes8,X,Y,disp_x,'FaceColor','interp','EdgeColor','none');
                        end   
                        title(app.UIAxes8,'Distribution of Displacement X', 'FontWeight', 'bold');
                        axis(app.UIAxes8, "equal");
                        colorbar(app.UIAxes8);
                    case "disp_y"
                        cla(app.UIAxes8);
                        hold(app.UIAxes8, "on")
                        colormap(app.UIAxes8, app.colorMap);
                        if app.ShowMeshSwitch_2.Value == "On"
                            patch(app.UIAxes8,X,Y,disp_y,'FaceColor','interp');
                        else
                            patch(app.UIAxes8,X,Y,disp_y,'FaceColor','interp','EdgeColor','none');
                        end   
                        title(app.UIAxes8,'Distribution of Displacement Y', 'FontWeight', 'bold');
                        axis(app.UIAxes8, "equal");
                        colorbar(app.UIAxes8);
                end
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function [disp, stress, strain] = element_post_process(NL,EL,ENL, E, nu)
    
                app.PD = size(NL,2);
                app.NPE = size(EL,2);
    
                app.NoN = size(ENL,1);
                app.NoE = size(EL,1);
    
                if app.NPE == 3
                    GPE = 1;
                elseif app.NPE == 4
                    GPE = 4; % Can be 1 too!!!
                end
    
                disp = zeros(app.NoE, app.NPE, app.PD, 1);
                stress = zeros(app.NoE, GPE, app.PD, app.PD);
                strain = zeros(app.NoE, GPE, app.PD, app.PD);
    
                for e = 1:app.NoE
                    nl = EL(e,1:app.NPE); % node numbers connected to each element
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            disp(e,i,j,1) = ENL(nl(i),4*app.PD+j);
                        end
                    end
    
                    x = zeros(app.NPE,app.PD);
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            x(i,j) = NL(nl(i),j);
                        end
                    end
    
                    coor = x';
    
                    u = zeros(app.PD,app.NPE);
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            u(j,i) = ENL(nl(i),4*app.PD+j);
                        end
                    end
    
                    for gp = 1:GPE
    
                        epsilon = zeros(app.PD,app.PD);
    
                        for i = 1:app.NPE
    
                            J = zeros(app.PD,app.PD);
                            grad = zeros(app.PD,app.NPE);


                            xi = 1/3; eta = 1/3; alpha = 1;


                            grad_nat = grad_N_nat(app.NPE, xi, eta);
    
                            J = coor * grad_nat';
    
                            grad = inv(J)' * grad_nat;
                            epsilon = epsilon + (1/2) * (dyad(grad(:,i),u(:,i)) + dyad(u(:,i), grad(:,i)));  
                        end
    
                        sigma = zeros(app.PD,app.PD);
    
                        for a = 1:app.PD
                            for b = 1:app.PD
                                for c = 1:app.PD
                                    for d = 1:app.PD
                                        sigma(a,b) = sigma(a,b) + constitutive(a,b,c,d, E,nu) * epsilon(c,d);
                                    end
                                end
                            end
                        end
    
                        for a = 1:app.PD
                            for b = 1:app.PD
                                strain(e,gp,a,b) = epsilon(a,b);
                                stress(e,gp,a,b) = sigma(a,b);
                            end
                        end
                    end
                end
            end
        

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        

            function result = grad_N_nat(NPE, xi, eta)
                app.PD = 2;
                result = zeros(app.PD,NPE);
                
                result(1,1) = 1;
                result(1,2) = 0;
                result(1,3) = -1;
    
                result(2,1) = 0;
                result(2,2) = 1;
                result(2,3) = -1;
    
            end
    
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function C = constitutive(i,j,k,l,E, nu)
                C = (E/(2*(1+nu))) * (delta(i,l)*delta(j,k) + delta(i,k)*delta(j,l)) + ((E*nu)/(1-nu^2)) * (delta(i,j)*delta(k,l));
            end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function delta = delta(i,j)
                if (i == j)
                    delta = 1;
                else
                    delta = 0;
                end
            end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function A = dyad(a,b)
                app.PD = 2;
                A = zeros(app.PD,app.PD);
                for k = 1:app.PD
                    for t = 1:app.PD
                        A(k,t) = a(k)*b(t);
                    end
                end
            end
        end

        % Value changed function: VoidType_2
        function VoidType_2ValueChanged(app, event)
            value = app.VoidType_2.Value;
            if value == "Circle"
                app.Image5.ImageSource = imread("Circle.png");
            elseif value == "Square"
                app.Image5.ImageSource = imread("Square.png");
            elseif value == "Rhombus"
                app.Image5.ImageSource = imread("Rhombus.png");
            else 
                app.Image5.ImageSource = imread("uniform.png");
            end
            
            if (app.VoidType_2.Value == "Circle")
                app.eEditField.Editable = true;
                app.eEditField.Enable = "on";
            else
                app.eEditField.Editable = false;
                app.eEditField.Enable = "off";
            end

        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.d1_z = value;
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.d2_z = value;
        end

        % Value changed function: pEditField
        function pEditFieldValueChanged(app, event)
            value = app.pEditField.Value;
            app.p_z = value;
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.m_z = value;
        end

        % Value changed function: EPaEditField_4
        function EPaEditField_4ValueChanged(app, event)
            value = app.EPaEditField_4.Value;
            app.E_z2 = value;
        end

        % Value changed function: EPaEditField_3
        function EPaEditField_3ValueChanged(app, event)
            value = app.EPaEditField_3.Value;
            app.E_z1 = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.nu_z = value;
        end

        % Value changed function: Am2EditField_2
        function Am2EditField_2ValueChanged(app, event)
            value = app.Am2EditField_2.Value;
            app.A_z = value;
        end

        % Value changed function: LoadTypeDropDown
        function LoadTypeDropDownValueChanged(app, event)
            value = app.LoadTypeDropDown.Value;
            if value == "x_loading"
                app.BC_Type_z = "x_loading";
            else 
                app.BC_Type_z = "y_loading";
            end

        end

        % Value changed function: DeformationMagnitudeEditField_2
        function DeformationMagnitudeEditField_2ValueChanged(app, event)
            value = app.DeformationMagnitudeEditField_2.Value;
            app.Magnitude_z = value ; 
        end

        % Button pushed function: APPLYButton_2
        function APPLYButton_2Pushed(app, event)
                cla(app.UIAxes_5);
                hold(app.UIAxes_5,"on");
                grid(app.UIAxes_5,"on");
    
                cla(app.UIAxes5_2);
                hold(app.UIAxes5_2,"on");
                grid(app.UIAxes5_2,"on");

                
                element_type ="D2TR3N";

                %%%%%%%%%%%% 2D PREPROCESS %%%%%%%%%%%%%%%%%%
                
                [app.NL_3,app.EL_3]=uniform_mesh(app.d1_z,app.d2_z,app.p_z,app.m_z,element_type);
                updateaxes_2d_2(app)               

                %%%%%%%%%%%%%%%%%%%%%%%%%% 1D PREPROCESS %%%%%%%%%%%%%%                                   
                
                [app.NL_4, app.EL_4, app.NoE_4, app.NoN_4] = beam_mesh_1(app.d1_z,app.d2_z,app.p_z,app.m_z);
                

                updateAxes_1d_2(app)
        end

        % Button pushed function: PLOTButton_2
        function PLOTButton_2Pushed(app, event)
            cla(app.UIAxes_displacement_3)
            cla(app.UIAxes8_2)

            colormap(app.UIAxes_displacement_3,"jet")
            colorbar(app.UIAxes_displacement_3)

            oned_process_2(app)
            twod_process_2(app)


            %%% 2d POSTPROCESS %%%

            PD_3 = 2;            

            app.NoN_3 = size(app.ENL_3,1);
            app.NoE_3 = size(app.EL_3,1);

            scale = 2; % Magnifies deformation
            app.NPE =3;

            [disp, stress, strain] = element_post_process(app.NL_3,app.EL_3,app.ENL_3, app.E_z2, app.nu_z);


            for i = 1:app.NoE_3
                nl = app.EL_3(i,1:app.NPE_3); % node numbers connected to element

                for j = 1:app.NPE_3
                    X(j,i) = app.ENL_3(nl(j),1) + scale*app.ENL_3(nl(j),4*PD_3+1);
                    Y(j,i) = app.ENL_3(nl(j),2) + scale*app.ENL_3(nl(j),4*PD_3+2);
                end

                for j = 1:app.NPE_3
                    stress_xx(j,i) = stress(i,:,1,1);
                end

                for j = 1:app.NPE_3
                    stress_xy(j,i) = stress(i,:,1,2);
                end

                for j = 1:app.NPE_3
                    stress_yx(j,i) = stress(i,:,2,1);
                end

                for j = 1:app.NPE_3
                    stress_yy(j,i) = stress(i,:,2,2);
                end      

                for j = 1:app.NPE_3
                    disp_x(j,i) = disp(i,j,1,1);
                end

                for j = 1:app.NPE_3
                    disp_y(j,i) = disp(i,j,2,1);
                end
            end

              
            switch app.plottype_z
                case "stress_xx"
                    cla(app.UIAxes8_2);
                    hold(app.UIAxes8_2, "on")
                    colormap(app.UIAxes8_2, app.colorMap);
                    if app.ShowMeshSwitch_3.Value == "On"
                        patch(app.UIAxes8_2,X,Y,stress_xx,'FaceColor','interp');
                    else
                        patch(app.UIAxes8_2,X,Y,stress_xx,'FaceColor','interp','EdgeColor','none');
                    end
                    title(app.UIAxes8_2,'Distribution of Stress XX', 'FontWeight', 'bold');
                    axis(app.UIAxes8_2, "equal");
                    colorbar(app.UIAxes8_2);
                case "stress_xy"
                    cla(app.UIAxes8_2);
                    hold(app.UIAxes8_2, "on")
                    colormap(app.UIAxes8_2, app.colorMap);
                    if app.ShowMeshSwitch_3.Value == "On"
                        patch(app.UIAxes8_2,X,Y,stress_xy,'FaceColor','interp');
                    else
                        patch(app.UIAxes8_2,X,Y,stress_xy,'FaceColor','interp','EdgeColor','none');
                    end
                    title(app.UIAxes8_2,'Distribution of Stress XY', 'FontWeight', 'bold');
                    axis(app.UIAxes8_2, "equal");
                    colorbar(app.UIAxes8_2);
                case "stress_yx"
                    cla(app.UIAxes8_2);
                    hold(app.UIAxes8_2, "on")
                    colormap(app.UIAxes8_2, app.colorMap);
                    if app.ShowMeshSwitch_3.Value == "On"
                        patch(app.UIAxes8_2,X,Y,stress_yx,'FaceColor','interp');
                    else
                        patch(app.UIAxes8_2,X,Y,stress_yx,'FaceColor','interp','EdgeColor','none');
                    end                    
                    title(app.UIAxes8_2,'Distribution of Stress YX', 'FontWeight', 'bold');
                    axis(app.UIAxes8_2, "equal");
                    colorbar(app.UIAxes8_2);
                case "stress_yy"
                    cla(app.UIAxes8_2);
                    hold(app.UIAxes8_2, "on")
                    colormap(app.UIAxes8_2, app.colorMap);
                    if app.ShowMeshSwitch_3.Value == "On"
                        patch(app.UIAxes8_2,X,Y,stress_yy,'FaceColor','interp');
                    else
                        patch(app.UIAxes8_2,X,Y,stress_yy,'FaceColor','interp','EdgeColor','none');
                    end                    
                    title(app.UIAxes8_2,'Distribution of Stress YY', 'FontWeight', 'bold');
                    axis(app.UIAxes8_2, "equal");
                    colorbar(app.UIAxes8_2);
                
                case "disp_x"
                    cla(app.UIAxes8_2);
                    hold(app.UIAxes8_2, "on")
                    colormap(app.UIAxes8_2, app.colorMap);
                    if app.ShowMeshSwitch_3.Value == "On"
                        patch(app.UIAxes8_2,X,Y,disp_x,'FaceColor','interp');
                    else
                        patch(app.UIAxes8_2,X,Y,disp_x,'FaceColor','interp','EdgeColor','none');
                    end   
                    title(app.UIAxes8_2,'Distribution of Displacement X', 'FontWeight', 'bold');
                    axis(app.UIAxes8_2, "equal");
                    colorbar(app.UIAxes8_2);
                case "disp_y"
                    cla(app.UIAxes8_2);
                    hold(app.UIAxes8_2, "on")
                    colormap(app.UIAxes8_2, app.colorMap);
                    if app.ShowMeshSwitch_3.Value == "On"
                        patch(app.UIAxes8_2,X,Y,disp_y,'FaceColor','interp');
                    else
                        patch(app.UIAxes8_2,X,Y,disp_y,'FaceColor','interp','EdgeColor','none');
                    end   
                    title(app.UIAxes8_2,'Distribution of Displacement Y', 'FontWeight', 'bold');
                    axis(app.UIAxes8_2, "equal");
                    colorbar(app.UIAxes8_2);
            end


            function [disp, stress, strain] = element_post_process(NL,EL,ENL, E, nu)
    
                app.PD = size(NL,2);
                app.NPE = size(EL,2);
    
                app.NoN = size(ENL,1);
                app.NoE = size(EL,1);
    
                if app.NPE == 3
                    GPE = 1;
                elseif app.NPE == 4
                    GPE = 4; % Can be 1 too!!!
                end
    
                disp = zeros(app.NoE, app.NPE, app.PD, 1);
                stress = zeros(app.NoE, GPE, app.PD, app.PD);
                strain = zeros(app.NoE, GPE, app.PD, app.PD);
    
                for e = 1:app.NoE
                    nl = EL(e,1:app.NPE); % node numbers connected to each element
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            disp(e,i,j,1) = ENL(nl(i),4*app.PD+j);
                        end
                    end
    
                    x = zeros(app.NPE,app.PD);
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            x(i,j) = NL(nl(i),j);
                        end
                    end
    
                    coor = x';
    
                    u = zeros(app.PD,app.NPE);
                    for i = 1:app.NPE
                        for j = 1:app.PD
                            u(j,i) = ENL(nl(i),4*app.PD+j);
                        end
                    end
    
                    for gp = 1:GPE
    
                        epsilon = zeros(app.PD,app.PD);
    
                        for i = 1:app.NPE
    
                            J = zeros(app.PD,app.PD);
                            grad = zeros(app.PD,app.NPE);


                            xi = 1/3; eta = 1/3; alpha = 1;


                            grad_nat = grad_N_nat(app.NPE, xi, eta);
    
                            J = coor * grad_nat';
    
                            grad = inv(J)' * grad_nat;
                            epsilon = epsilon + (1/2) * (dyad(grad(:,i),u(:,i)) + dyad(u(:,i), grad(:,i)));  
                        end
    
                        sigma = zeros(app.PD,app.PD);
    
                        for a = 1:app.PD
                            for b = 1:app.PD
                                for c = 1:app.PD
                                    for d = 1:app.PD
                                        sigma(a,b) = sigma(a,b) + constitutive(a,b,c,d, E,nu) * epsilon(c,d);
                                    end
                                end
                            end
                        end
    
                        for a = 1:app.PD
                            for b = 1:app.PD
                                strain(e,gp,a,b) = epsilon(a,b);
                                stress(e,gp,a,b) = sigma(a,b);
                            end
                        end
                    end
                end
            end
        

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        

            function result = grad_N_nat(NPE, xi, eta)
                app.PD = 2;
                result = zeros(app.PD,NPE);
                
                result(1,1) = 1;
                result(1,2) = 0;
                result(1,3) = -1;
    
                result(2,1) = 0;
                result(2,2) = 1;
                result(2,3) = -1;
    
            end
    
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function C = constitutive(i,j,k,l,E, nu)
                C = (E/(2*(1+nu))) * (delta(i,l)*delta(j,k) + delta(i,k)*delta(j,l)) + ((E*nu)/(1-nu^2)) * (delta(i,j)*delta(k,l));
            end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function delta = delta(i,j)
                if (i == j)
                    delta = 1;
                else
                    delta = 0;
                end
            end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            function A = dyad(a,b)
                app.PD = 2;
                A = zeros(app.PD,app.PD);
                for k = 1:app.PD
                    for t = 1:app.PD
                        A(k,t) = a(k)*b(t);
                    end
                end
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch value
                case "U_x"
                    app.disp_direction_z = "U_x";
                case "U_y"
                    app.disp_direction_z = "U_y";
                case "U"
                    app.disp_direction_z = "U";
                case "Stress"
                    app.disp_direction_z = "Stress";
            end
            PLOTButton_2Pushed(app)
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
           
            value = app.DropDown_4.Value;

            if value == "stress_xx"
                app.plottype_z = "stress_xx";
            elseif value == "stress_xy"
                app.plottype_z = "stress_xy";
            elseif value == "stress_yx"
                app.plottype_z = "stress_yx";
            elseif value == "stress_yy"
                app.plottype_z = "stress_yy";           
            elseif value == "U_x"
                app.plottype_z = "disp_x";
            elseif value =="U_y" 
                app.plottype_z = "disp_y";
            else
                app.plottype_z = "U_total";
            end
            PLOTButton_2Pushed(app)
        end

        % Value changed function: ShowMeshSwitch_3
        function ShowMeshSwitch_3ValueChanged(app, event)
            value = app.ShowMeshSwitch_3.Value;
            PLOTButton_2Pushed(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DFEMforVoidedIncludedSquaresTab = uitab(app.TabGroup);
            app.DFEMforVoidedIncludedSquaresTab.Title = '2D FEM for Voided & Included Squares ';
            app.GridLayout2 = uigridlayout(app.DFEMforVoidedIncludedSquaresTab);
            app.UIAxesPrecise = uiaxes(app.GridLayout2);
            title(app.UIAxesPrecise, 'Precise')
            xlabel(app.UIAxesPrecise, 'X')
            ylabel(app.UIAxesPrecise, 'Y')
            zlabel(app.UIAxesPrecise, 'Z')
            app.UIAxesSmooth = uiaxes(app.GridLayout2);
            title(app.UIAxesSmooth, 'Smooth')
            xlabel(app.UIAxesSmooth, 'X')
            ylabel(app.UIAxesSmooth, 'Y')
            zlabel(app.UIAxesSmooth, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'MESH')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.InclusionTypeDropDownLabel = uilabel(app.GridLayout2);
            app.InclusionTypeDropDownLabel.Text = 'Inclusion Type';
            app.InclusionTypeDropDown = uidropdown(app.GridLayout2);
            app.InclusionTypeDropDown.Items = {'Circle', 'Square', 'Rhombus'};
            app.InclusionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InclusionTypeDropDownValueChanged, true);
            app.InclusionTypeDropDown.Value = 'Circle';
            app.aValue = uieditfield(app.GridLayout2, 'numeric');
            app.aValue.ValueChangedFcn = createCallbackFcn(app, @aValueValueChanged, true);
            app.aValue.Value = 1;
            app.aEditFieldLabel = uilabel(app.GridLayout2);
            app.aEditFieldLabel.Text = 'a';
            app.bEditFieldLabel = uilabel(app.GridLayout2);
            app.bEditFieldLabel.Text = 'b';
            app.bValue = uieditfield(app.GridLayout2, 'numeric');
            app.bValue.ValueChangedFcn = createCallbackFcn(app, @bValueValueChanged, true);
            app.bValue.Value = 1;
            app.cEditFieldLabel = uilabel(app.GridLayout2);
            app.cEditFieldLabel.Text = 'c';
            app.cValue = uieditfield(app.GridLayout2, 'numeric');
            app.cValue.ValueChangedFcn = createCallbackFcn(app, @cValueValueChanged, true);
            app.cValue.Value = 3;
            app.dEditFieldLabel = uilabel(app.GridLayout2);
            app.dEditFieldLabel.Text = 'd';
            app.dValue = uieditfield(app.GridLayout2, 'numeric');
            app.dValue.ValueChangedFcn = createCallbackFcn(app, @dValueValueChanged, true);
            app.dValue.Value = 3;
            app.eEditFieldLabel = uilabel(app.GridLayout2);
            app.eEditFieldLabel.Text = 'e';
            app.eValue = uieditfield(app.GridLayout2, 'numeric');
            app.eValue.ValueChangedFcn = createCallbackFcn(app, @eValueValueChanged, true);
            app.eValue.Value = 0.2;
            app.REMOVEMESHButton = uibutton(app.GridLayout2, 'push');
            app.REMOVEMESHButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEMESHButtonPushed, true);
            app.REMOVEMESHButton.Text = 'REMOVE MESH';
            app.GenerateMeshButton = uibutton(app.GridLayout2, 'push');
            app.GenerateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshButtonPushed, true);
            app.GenerateMeshButton.Text = 'Generate Mesh';
            app.ElementTypeDropDownLabel = uilabel(app.GridLayout2);
            app.ElementTypeDropDownLabel.Text = 'Element Type';
            app.ElementTypeDropDown = uidropdown(app.GridLayout2);
            app.ElementTypeDropDown.Items = {'D2TR3N', 'D2QU4N', 'D2TR6N', 'D2QU8N', 'D2QU9N'};
            app.ElementTypeDropDown.Value = 'D2TR3N';
            app.NodesEditFieldLabel = uilabel(app.GridLayout2);
            app.NodesEditFieldLabel.Text = '#Nodes';
            app.NodesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NodesEditField.ValueChangedFcn = createCallbackFcn(app, @NodesEditFieldValueChanged, true);
            app.ElementsEditFieldLabel = uilabel(app.GridLayout2);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.ElementsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ElementsEditField.ValueChangedFcn = createCallbackFcn(app, @ElementsEditFieldValueChanged, true);
            app.DeformationTypeButtonGroup = uibuttongroup(app.GridLayout2);
            app.DeformationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DeformationTypeButtonGroupSelectionChanged, true);
            app.DeformationTypeButtonGroup.Title = 'Deformation Type';
            app.ExtensionButton = uiradiobutton(app.DeformationTypeButtonGroup);
            app.ExtensionButton.Text = 'Extension';
            app.ExtensionButton.Value = true;
            app.ExpansionButton = uiradiobutton(app.DeformationTypeButtonGroup);
            app.ExpansionButton.Text = 'Expansion';
            app.Image = uiimage(app.DeformationTypeButtonGroup);
            app.Image.ImageSource = 'extension.PNG';
            app.Image2 = uiimage(app.DeformationTypeButtonGroup);
            app.Image2.ImageSource = 'expansion.PNG';
            app.ShearButton = uiradiobutton(app.DeformationTypeButtonGroup);
            app.ShearButton.Text = 'Shear';
            app.Image2_2 = uiimage(app.DeformationTypeButtonGroup);
            app.Image2_2.ImageSource = 'shear.PNG';
            app.POSTPROCESSLabel = uilabel(app.GridLayout2);
            app.POSTPROCESSLabel.Text = 'POST-PROCESS';
            app.DeformationMagnitudeLabel = uilabel(app.GridLayout2);
            app.DeformationMagnitudeLabel.Text = 'Deformation Magnitude';
            app.DeformationMagnitudeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DeformationMagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @DeformationMagnitudeEditFieldValueChanged, true);
            app.DeformationMagnitudeEditField.Value = 0.1;
            app.PROCESSLabel = uilabel(app.GridLayout2);
            app.PROCESSLabel.Text = 'PROCESS';
            app.PREPROCESSLabel = uilabel(app.GridLayout2);
            app.PREPROCESSLabel.Text = 'PRE-PROCESS';
            app.NodeLabelSwitchLabel = uilabel(app.GridLayout2);
            app.NodeLabelSwitchLabel.Text = 'Node Label';
            app.NodeLabelSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.NodeLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitchValueChanged, true);
            app.RUNLamp = uilamp(app.GridLayout2);
            app.ElementLabelSwitchLabel = uilabel(app.GridLayout2);
            app.ElementLabelSwitchLabel.Text = 'Element Label';
            app.ElementLabelSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.ElementLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementLabelSwitchValueChanged, true);
            app.MaterialPropertiesPanel = uipanel(app.GridLayout2);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.GridLayout3 = uigridlayout(app.MaterialPropertiesPanel);
            app.MatrixLabel = uilabel(app.GridLayout3);
            app.MatrixLabel.Text = 'Matrix';
            app.EEditFieldLabel = uilabel(app.GridLayout3);
            app.EEditFieldLabel.Text = 'E';
            app.EMatrixEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EMatrixEditField.ValueChangedFcn = createCallbackFcn(app, @EMatrixEditFieldValueChanged, true);
            app.EMatrixEditField.Value = 1;
            app.EEditField_2Label = uilabel(app.GridLayout3);
            app.EEditField_2Label.Text = 'E';
            app.EInclusionEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EInclusionEditField.ValueChangedFcn = createCallbackFcn(app, @EInclusionEditFieldValueChanged, true);
            app.EInclusionEditField.Value = 10;
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = 'ѵ';
            app.NuMatrixEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NuMatrixEditField.ValueChangedFcn = createCallbackFcn(app, @NuMatrixEditFieldValueChanged, true);
            app.NuMatrixEditField.Value = 0.3;
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = 'ѵ';
            app.NuInclusionEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NuInclusionEditField.ValueChangedFcn = createCallbackFcn(app, @NuInclusionEditFieldValueChanged, true);
            app.NuInclusionEditField.Value = 0.3;
            app.InclusionLabel = uilabel(app.GridLayout3);
            app.InclusionLabel.Text = 'Inclusion';
            app.ColorMapButtonGroup = uibuttongroup(app.GridLayout2);
            app.ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroupSelectionChanged, true);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.JETButton = uitogglebutton(app.ColorMapButtonGroup);
            app.JETButton.Icon = 'jet.png';
            app.JETButton.Text = 'JET';
            app.JETButton.Value = true;
            app.RAINBOWButton = uitogglebutton(app.ColorMapButtonGroup);
            app.RAINBOWButton.Icon = 'rainbow.png';
            app.RAINBOWButton.Text = 'RAINBOW';
            app.BONEButton = uitogglebutton(app.ColorMapButtonGroup);
            app.BONEButton.Icon = 'bone.png';
            app.BONEButton.Text = 'BONE';
            app.MagnifierEditFieldLabel = uilabel(app.GridLayout2);
            app.MagnifierEditFieldLabel.Text = 'Magnifier (%)';
            app.MagnifierEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MagnifierEditField.Limits = [1e-05 10000000000];
            app.MagnifierEditField.ValueChangedFcn = createCallbackFcn(app, @MagnifierEditFieldValueChanged, true);
            app.MagnifierEditField.Value = 100;
            app.ShowMeshSwitchLabel = uilabel(app.GridLayout2);
            app.ShowMeshSwitchLabel.Text = 'Show Mesh';
            app.ShowMeshSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ShowMeshSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowMeshSwitchValueChanged, true);
            app.ShowMeshSwitch.Value = 'On';
            app.ResultsDropDownLabel = uilabel(app.GridLayout2);
            app.ResultsDropDownLabel.Text = 'Results';
            app.ResultsDropDown = uidropdown(app.GridLayout2);
            app.ResultsDropDown.Items = {'stress_xx', 'stress_xy', 'stress_yx', 'stress_yy', 'strain_xx', 'strain_xy', 'strain_yx', 'strain_yy', 'displacement_x', 'displacement_y'};
            app.ResultsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ResultsDropDownOpening, true);
            app.ResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultsDropDownValueChanged, true);
            app.ResultsDropDown.Value = 'stress_xx';
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'circle.PNG';
            app.VoidSwitch = uiswitch(app.GridLayout2, 'slider');
            app.VoidSwitch.Items = {'Void', 'Inclusion'};
            app.VoidSwitch.ValueChangedFcn = createCallbackFcn(app, @VoidSwitchValueChanged, true);
            app.VoidSwitch.Value = 'Inclusion';
            app.RUNButton = uibutton(app.GridLayout2, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.D2DFEMforSquaresTab = uitab(app.TabGroup);
            app.D2DFEMforSquaresTab.Title = '1D & 2D FEM for Squares';
            app.GridLayout4 = uigridlayout(app.D2DFEMforSquaresTab);
            app.UIAxes8 = uiaxes(app.GridLayout4);
            title(app.UIAxes8, 'Results')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes_displacement_2 = uiaxes(app.GridLayout4);
            title(app.UIAxes_displacement_2, 'Results')
            xlabel(app.UIAxes_displacement_2, 'X')
            ylabel(app.UIAxes_displacement_2, 'Y')
            zlabel(app.UIAxes_displacement_2, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout4);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.DFEANALYSISLabel = uilabel(app.GridLayout4);
            app.DFEANALYSISLabel.Text = '1D FE ANALYSIS';
            app.DFEANALYSISLabel_2 = uilabel(app.GridLayout4);
            app.DFEANALYSISLabel_2.Text = '2D FE ANALYSIS';
            app.BuildingtheStructurePanel = uipanel(app.GridLayout4);
            app.BuildingtheStructurePanel.Title = 'Building the Structure';
            app.GridLayout5 = uigridlayout(app.BuildingtheStructurePanel);
            app.Image5 = uiimage(app.GridLayout5);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'circle.PNG');
            app.ab1Label = uilabel(app.GridLayout5);
            app.ab1Label.Text = 'a = b = 1';
            app.d4Label = uilabel(app.GridLayout5);
            app.d4Label.Text = 'd = 4';
            app.Image6 = uiimage(app.GridLayout5);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'expansion.PNG');
            app.Image7 = uiimage(app.GridLayout5);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'extension.PNG');
            app.Image8 = uiimage(app.GridLayout5);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'shear.PNG');
            app.ExpansionLabel = uilabel(app.GridLayout5);
            app.ExpansionLabel.Text = 'Expansion';
            app.ExtensionLabel = uilabel(app.GridLayout5);
            app.ExtensionLabel.Text = 'Extension';
            app.ShearLabel = uilabel(app.GridLayout5);
            app.ShearLabel.Text = 'Shear';
            app.DeformationTypeDropDown = uidropdown(app.GridLayout5);
            app.DeformationTypeDropDown.Items = {'Expansion', 'Extension', 'Shear'};
            app.DeformationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DeformationTypeDropDownValueChanged, true);
            app.DeformationTypeDropDown.Value = 'Expansion';
            app.DeformaitonMagnitudeEditFieldLabel = uilabel(app.GridLayout5);
            app.DeformaitonMagnitudeEditFieldLabel.Text = 'Deformaiton Magnitude';
            app.DeformaitonMagnitudeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.DeformaitonMagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @DeformaitonMagnitudeEditFieldValueChanged, true);
            app.DeformaitonMagnitudeEditField.Value = 0.1;
            app.APPLYButton = uibutton(app.GridLayout5, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.cEditFieldLabel_2 = uilabel(app.GridLayout5);
            app.cEditFieldLabel_2.Text = 'c';
            app.cEditField = uieditfield(app.GridLayout5, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.cEditField.Value = 3;
            app.eEditFieldLabel_2 = uilabel(app.GridLayout5);
            app.eEditFieldLabel_2.Text = 'e';
            app.eEditField = uieditfield(app.GridLayout5, 'numeric');
            app.eEditField.ValueChangedFcn = createCallbackFcn(app, @eEditFieldValueChanged, true);
            app.eEditField.Value = 0.25;
            app.VoidType_2 = uidropdown(app.GridLayout5);
            app.VoidType_2.Items = {'Uniform', 'Circle', 'Square', 'Rhombus'};
            app.VoidType_2.ValueChangedFcn = createCallbackFcn(app, @VoidType_2ValueChanged, true);
            app.VoidType_2.Value = 'Circle';
            app.EPaEditFieldLabel = uilabel(app.GridLayout4);
            app.EPaEditFieldLabel.Text = 'E (Pa)';
            app.EPaEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EPaEditField.ValueChangedFcn = createCallbackFcn(app, @EPaEditFieldValueChanged, true);
            app.EPaEditField.Value = 1;
            app.Am2EditFieldLabel = uilabel(app.GridLayout4);
            app.Am2EditFieldLabel.Text = 'A(m^2)';
            app.Am2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Am2EditField.ValueChangedFcn = createCallbackFcn(app, @Am2EditFieldValueChanged, true);
            app.Am2EditField.Value = 0.01;
            app.EPaEditField_2Label = uilabel(app.GridLayout4);
            app.EPaEditField_2Label.Text = 'E (Pa)';
            app.EPaEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.EPaEditField_2.ValueChangedFcn = createCallbackFcn(app, @EPaEditField_2ValueChanged, true);
            app.EPaEditField_2.Value = 1;
            app.EditFieldLabel = uilabel(app.GridLayout4);
            app.EditFieldLabel.Text = 'ѵ';
            app.EditField = uieditfield(app.GridLayout4, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.3;
            app.PLOTButton = uibutton(app.GridLayout4, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.DropDown = uidropdown(app.GridLayout4);
            app.DropDown.Items = {'U_x', 'U_y', 'U', 'Stress'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'U_x';
            app.DropDown_2 = uidropdown(app.GridLayout4);
            app.DropDown_2.Items = {'stress_xx', 'stress_xy', 'stress_yx', 'stress_yy', 'U_x', 'U_y', 'U'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'U_x';
            app.ShowMeshSwitch_2 = uiswitch(app.GridLayout4, 'slider');
            app.ShowMeshSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ShowMeshSwitch_2ValueChanged, true);
            app.ShowMeshSwitch_2.Value = 'On';
            app.ShowMeshSwitch_2Label = uilabel(app.GridLayout4);
            app.ShowMeshSwitch_2Label.Text = 'Show Mesh';
            app.D2DFEMforBeamTab = uitab(app.TabGroup);
            app.D2DFEMforBeamTab.Title = '1D & 2D FEM for Beam';
            app.GridLayout4_2 = uigridlayout(app.D2DFEMforBeamTab);
            app.UIAxes8_2 = uiaxes(app.GridLayout4_2);
            title(app.UIAxes8_2, 'Results')
            xlabel(app.UIAxes8_2, 'X')
            ylabel(app.UIAxes8_2, 'Y')
            zlabel(app.UIAxes8_2, 'Z')
            app.UIAxes_displacement_3 = uiaxes(app.GridLayout4_2);
            title(app.UIAxes_displacement_3, 'Results')
            xlabel(app.UIAxes_displacement_3, 'X')
            ylabel(app.UIAxes_displacement_3, 'Y')
            zlabel(app.UIAxes_displacement_3, 'Z')
            app.UIAxes5_2 = uiaxes(app.GridLayout4_2);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout4_2);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.DFEANALYSISLabel_3 = uilabel(app.GridLayout4_2);
            app.DFEANALYSISLabel_3.Text = '1D FE ANALYSIS';
            app.DFEANALYSISLabel_4 = uilabel(app.GridLayout4_2);
            app.DFEANALYSISLabel_4.Text = '2D FE ANALYSIS';
            app.BuildingtheStructurePanel_2 = uipanel(app.GridLayout4_2);
            app.BuildingtheStructurePanel_2.Title = 'Building the Structure';
            app.GridLayout5_2 = uigridlayout(app.BuildingtheStructurePanel_2);
            app.APPLYButton_2 = uibutton(app.GridLayout5_2, 'push');
            app.APPLYButton_2.ButtonPushedFcn = createCallbackFcn(app, @APPLYButton_2Pushed, true);
            app.APPLYButton_2.Text = 'APPLY';
            app.Image9 = uiimage(app.GridLayout5_2);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'beam.jpeg');
            app.aEditFieldLabel_2 = uilabel(app.GridLayout5_2);
            app.aEditFieldLabel_2.Text = 'a';
            app.aEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = 10;
            app.bEditFieldLabel_2 = uilabel(app.GridLayout5_2);
            app.bEditFieldLabel_2.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 2;
            app.pEditFieldLabel = uilabel(app.GridLayout5_2);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.pEditField.ValueChangedFcn = createCallbackFcn(app, @pEditFieldValueChanged, true);
            app.pEditField.Value = 5;
            app.mEditFieldLabel = uilabel(app.GridLayout5_2);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 4;
            app.DeformationMagnitudeLabel_2 = uilabel(app.GridLayout5_2);
            app.DeformationMagnitudeLabel_2.Text = {'Deformation'; 'Magnitude'};
            app.DeformationMagnitudeEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.DeformationMagnitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @DeformationMagnitudeEditField_2ValueChanged, true);
            app.LoadTypeDropDownLabel = uilabel(app.GridLayout5_2);
            app.LoadTypeDropDownLabel.Text = 'Load Type';
            app.LoadTypeDropDown = uidropdown(app.GridLayout5_2);
            app.LoadTypeDropDown.Items = {'x_loading', 'y_loading'};
            app.LoadTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadTypeDropDownValueChanged, true);
            app.LoadTypeDropDown.Value = 'x_loading';
            app.Image10 = uiimage(app.GridLayout5_2);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2022-05-13 at 21.13.13.jpeg');
            app.Image11 = uiimage(app.GridLayout5_2);
            app.Image11.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2022-05-13 at 21.13.28.jpeg');
            app.EPaEditField_3Label = uilabel(app.GridLayout4_2);
            app.EPaEditField_3Label.Text = 'E (Pa)';
            app.EPaEditField_3 = uieditfield(app.GridLayout4_2, 'numeric');
            app.EPaEditField_3.ValueChangedFcn = createCallbackFcn(app, @EPaEditField_3ValueChanged, true);
            app.EPaEditField_3.Value = 1;
            app.Am2EditField_2Label = uilabel(app.GridLayout4_2);
            app.Am2EditField_2Label.Text = 'A(m^2)';
            app.Am2EditField_2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.Am2EditField_2.ValueChangedFcn = createCallbackFcn(app, @Am2EditField_2ValueChanged, true);
            app.Am2EditField_2.Value = 0.01;
            app.EPaEditField_4Label = uilabel(app.GridLayout4_2);
            app.EPaEditField_4Label.Text = 'E (Pa)';
            app.EPaEditField_4 = uieditfield(app.GridLayout4_2, 'numeric');
            app.EPaEditField_4.ValueChangedFcn = createCallbackFcn(app, @EPaEditField_4ValueChanged, true);
            app.EPaEditField_4.Value = 1;
            app.EditField_2Label = uilabel(app.GridLayout4_2);
            app.EditField_2Label.Text = 'ѵ';
            app.EditField_2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 0.3;
            app.PLOTButton_2 = uibutton(app.GridLayout4_2, 'push');
            app.PLOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLOTButton_2Pushed, true);
            app.PLOTButton_2.Text = 'PLOT';
            app.DropDown_3 = uidropdown(app.GridLayout4_2);
            app.DropDown_3.Items = {'U_x', 'U_y', 'U', 'Stress'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'U_x';
            app.DropDown_4 = uidropdown(app.GridLayout4_2);
            app.DropDown_4.Items = {'stress_xx', 'stress_xy', 'stress_yx', 'stress_yy', 'U_x', 'U_y', 'U'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'U_x';
            app.ShowMeshSwitch_3 = uiswitch(app.GridLayout4_2, 'slider');
            app.ShowMeshSwitch_3.ValueChangedFcn = createCallbackFcn(app, @ShowMeshSwitch_3ValueChanged, true);
            app.ShowMeshSwitch_3Label = uilabel(app.GridLayout4_2);
            app.ShowMeshSwitch_3Label.Text = 'Show Mesh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_2d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end